classdef HueDashboard < matlab.apps.AppBase
    %HUEDASHBOARD (Developer and support notes)
    %   Developed by:
    %   Stephen Folmer
    %   spfolmer@niceway.com
    %   (c) 2026 Stephen Folmer
    %   
    %   Provided 'as is' without support or warranty.
    %   
    %   ----------------------------------------------------------------
    %   Developed on Matlab r2025 but, save for dark mode presentation,
    %   will be backwards compatible for several releases.
    %   ----------------------------------------------------------------
    %   
    %   Update log:
    %   20251015 - (ra4) v1.4 - corrected handling for change in Bridge IP
    %   - (ra5) v1.5 - tidied up 'no internet' handling.
    %   20251017 - (ra6) v1.6 - added detailed help text for control of
    %   lights.  
    %   20251019 - v2.0 - complete re-design of the Bridge pairing
    %   (adding to whitelist) and IP address update processes.
    %   20251022 - v2.1 cosmetic changes to informative messages.
    %   20251101 - v2.2 slightly larger display area, with new colouration,
    %   some larger fonts and real-time lights' status count after
    %   toggling.
    %   20251104 - v2.3 included dimmer switches in the sensor data table.
    %   dynamic text for low battery and unreachable lights diagnostics.
    %   20251105 - v2.4 added Last Event column to Sensors and Switches
    %   grid.  Moved things around to accommodate the expanded grid.  Also
    %   various display and performance enhancements..
    %   20251109 - v2.5 improvements to Rules details - cater for 'zero'
    %   groups and dimmers quirks.  Added 'last motion detected' section.
    %   20251112 - v2.6 added max/min temperatures in Sensor Temperatures
    %   panel title.
    %   20251121 - v2.7 added dynamic text indicating change in maximum and
    %   minimum temperatures.
    %   20251126 - v2.8 improved directory control for Hue Data.  Added
    %   HTML/CSS utilisation for enhanced presentation.
    %   20251203 - v2.9 minor bug fixes and enhancements to HTML.
    %   20251209 - v2.10 presentation improvements.
    %   20251212 - v2.11 bug fixes (including: Correct 'Lights On' count
    %   after toggling; Update base arrays after re-sorting the UI Lights
    %   grid to ensure correct lights/groups are processed); 
    %   Update the 'Lights Groups On' count in line with Toggled lights.
    %   20251224 - v3.0 added auto-restart when refresh response exceeds
    %   1.25 x app startup time.  Also incorporated hueAppDiagnostics.mat
    %   file.
    %   20251231 - v3.1 - Pulsing Hue Logo and minor code tidying.
    %   20260101 - v3.2 - removed extraneous Command Window diagnostics.
    %   20260102 - v3.3 - added count for number of Rooms with Lights ON.
    %   20260103 - v3.4 - introduced checkbox to restrict lights shown in
    %   the grid to only those that are lit and in a Room group.
    %   20260107 - v3.5 - added Location to Rules grid, which is derived by
    %   pattern-matching rules, sensors and resource links data.
    %   20260108 - v3.6 - Bri-Col column in the lights grid now shows the
    %   colour of the lights.
    %   20260110 - v3.7 - optimised hueXYtoRGB function.  Replaced arrayfun
    %   with vectorised solution.
    %   20260111 - v3.8 - remember last position of UIFigure
    %   20260113 - v3.9 - improved colour rendering for Bri-col cells.
    %   20260116 - v3.10 - convert RGB to nearest colour name match.
    %   20260117 - v4.0 - major changes.  Added 'Colour' column to Lights
    %   table.  Brightness and colour are now controlled from separate
    %   columns/cells.  Displayed lights colour names are derived directly
    %   from the Hue xy values based on nearest match to CSS colour
    %   schemes.
    %   20260119 - v4.1 - only store extracted bridge data on startup.
    %   20260122 - v4.2 - added auto-refresh option upon motion detection.
    %   20260201 - v5.0 - prepare for Bridge Pro integration.  API v2.

    % Properties that correspond to app components
    properties (Access = public)
        HueDashboardv57UIFigure        matlab.ui.Figure
        Sunset                         matlab.ui.control.Label
        inTimesTriggeredOrderLabel     matlab.ui.control.Label
        OfflineMode                    matlab.ui.control.CheckBox
        BridgeDropDown                 matlab.ui.control.DropDown
        BridgeDropDownLabel            matlab.ui.control.Label
        AutoRefreshButton              matlab.ui.control.Button
        lightsOnRoomsOnlyCheckbox      matlab.ui.control.CheckBox
        noRoomsLightsOn                matlab.ui.control.Label
        RoomsLabel                     matlab.ui.control.Label
        inLabel                        matlab.ui.control.Label
        HueLogo                        matlab.ui.container.Panel
        ResponseLimitSwitch            matlab.ui.control.RockerSwitch
        HTMLLastMotion                 matlab.ui.control.HTML
        HTMLLightsOn                   matlab.ui.control.HTML
        HTMLTempTrend                  matlab.ui.control.HTML
        HTMLLowBatMsg                  matlab.ui.control.HTML
        WarningsPanel                  matlab.ui.container.Panel
        HTMLUnreachableLights          matlab.ui.control.HTML
        HTMLLowBatDevs                 matlab.ui.control.HTML
        LowBatteryDevicesHdr           matlab.ui.control.Label
        UnreachableLightsHdr           matlab.ui.control.Label
        onLabel                        matlab.ui.control.Label
        BridgeConfigurationandUsagePanel  matlab.ui.container.Panel
        InLabel                        matlab.ui.control.Label
        TotalDevices                   matlab.ui.control.Label
        devicesLabel                   matlab.ui.control.Label
        SwitchesLabel                  matlab.ui.control.Label
        DimmerSwitches                 matlab.ui.control.Label
        MotionSensorsLabel             matlab.ui.control.Label
        MotionSensors                  matlab.ui.control.Label
        PCSensors                      matlab.ui.control.NumericEditField
        BRSensors                      matlab.ui.control.NumericEditField
        AVSensors                      matlab.ui.control.NumericEditField
        SensorsLabel                   matlab.ui.control.Label
        PCResourceLinks                matlab.ui.control.NumericEditField
        BRResourceLinks                matlab.ui.control.NumericEditField
        AVResourceLinks                matlab.ui.control.NumericEditField
        ResourceLinksLabel             matlab.ui.control.Label
        ONSchedules                    matlab.ui.control.NumericEditField
        PCSchedules                    matlab.ui.control.NumericEditField
        BRSchedules                    matlab.ui.control.NumericEditField
        AVSchedules                    matlab.ui.control.NumericEditField
        SchedulesLabel                 matlab.ui.control.Label
        PCScenes                       matlab.ui.control.NumericEditField
        BRScenes                       matlab.ui.control.NumericEditField
        AVScenes                       matlab.ui.control.NumericEditField
        ScenesLabel                    matlab.ui.control.Label
        OnGroups                       matlab.ui.control.NumericEditField
        PCGroups                       matlab.ui.control.NumericEditField
        BRGroups                       matlab.ui.control.NumericEditField
        AVGroups                       matlab.ui.control.NumericEditField
        GroupsLabel                    matlab.ui.control.Label
        ONRules                        matlab.ui.control.NumericEditField
        PCRules                        matlab.ui.control.NumericEditField
        BRRules                        matlab.ui.control.NumericEditField
        AVRules                        matlab.ui.control.NumericEditField
        RulesLabel                     matlab.ui.control.Label
        ONLights                       matlab.ui.control.NumericEditField
        PCLights                       matlab.ui.control.NumericEditField
        BRLights                       matlab.ui.control.NumericEditField
        AVLights                       matlab.ui.control.NumericEditField
        LightsLabel                    matlab.ui.control.Label
        OnActiveLabel                  matlab.ui.control.Label
        UsedLabel                      matlab.ui.control.Label
        BridgeLimitLabel               matlab.ui.control.Label
        InstalledLabel                 matlab.ui.control.Label
        DiagnosticsTimeTextArea        matlab.ui.control.TextArea
        DiagnosticsMsgTextArea         matlab.ui.control.TextArea
        DiagnosticsTextAreaLabel       matlab.ui.control.Label
        TableSensors                   matlab.ui.control.Table
        TableLights                    matlab.ui.control.Table
        UnreachableLightsLabel         matlab.ui.control.Label
        RulesTable                     matlab.ui.control.Table
        NeverTriggered                 matlab.ui.control.NumericEditField
        NumberofRulesNOTtriggeredsincethelastBridgefirmwareupdateLabel  matlab.ui.control.Label
        RulesLabel_2                   matlab.ui.control.Label
        LightsTable                    matlab.ui.control.Table
        AllLightsOn                    matlab.ui.control.Button
        AllLightsOff                   matlab.ui.control.Button
        ToggleALLLightsButton          matlab.ui.control.Button
        LightsOnLabel                  matlab.ui.control.Label
        TempsTable                     matlab.ui.control.Table
        SensorsandSwitchesLabel        matlab.ui.control.Label
        SensorTemperaturesPanel        matlab.ui.container.Panel
        FRSensors                      matlab.ui.control.NumericEditField
        FreezingLable                  matlab.ui.control.Label
        ColdLable                      matlab.ui.control.Label
        CoolLabel                      matlab.ui.control.Label
        WarmLabel                      matlab.ui.control.Label
        HotLabel                       matlab.ui.control.Label
        HOSensors                      matlab.ui.control.NumericEditField
        WASensors                      matlab.ui.control.NumericEditField
        COSensors                      matlab.ui.control.NumericEditField
        CLSensors                      matlab.ui.control.NumericEditField
        CMSensors                      matlab.ui.control.NumericEditField
        CMSensorsLabel                 matlab.ui.control.Label
        SWUpDateAvail                  matlab.ui.control.TextArea
        Label                          matlab.ui.control.Label
        SWversion                      matlab.ui.control.TextArea
        BridgeID                       matlab.ui.control.TextArea
        IPAddressLabel_2               matlab.ui.control.Label
        Response                       matlab.ui.control.NumericEditField
        LastBridgeDataRefreshTextArea  matlab.ui.control.TextArea
        AutoSWUpdate                   matlab.ui.control.TextArea
        LastFirmwareUpdate             matlab.ui.control.TextArea
        ZigbeeChannel                  matlab.ui.control.NumericEditField
        IPAddress                      matlab.ui.control.TextArea
        APIversion                     matlab.ui.control.TextArea
        ZigbeeChannelLabel_2           matlab.ui.control.Label
        PreviousStatusRefreshLabel     matlab.ui.control.Label
        IPAddressLabel_4               matlab.ui.control.Label
        LastFirmwareUpdateSoftwareVersionTextAreaLabel  matlab.ui.control.Label
        ZigbeeChannelLabel             matlab.ui.control.Label
        IPAddressLabel                 matlab.ui.control.Label
        IPAddressLabel_3               matlab.ui.control.Label
        RefreshButton                  matlab.ui.control.Button
        PhilipsHueDashboardLabel       matlab.ui.control.Label
        ExitButton                     matlab.ui.control.Button
        ContextMenu                    matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
    end


    properties (Access = private)
        fName
        lastHueIP char % last obtained IP
        hueIPAddressV2 char % Bridge v2 IP
        hueIPAddressPro char % Bridge Pro IP
        appID char % Bridge v2 Registration Key (whitelist)
        appKey % Bridge Pro Registration Key (whitelist)
        dataRepository
        newBri
        cellSelIndices
        prevCellSel
        prevEventName
        editGroup
        lastKey
        xyColour
        newColr
        successfulStart
        prevMaxTemp
        prevMinTemp
        lightsOn
        tGroups
        responseLimit double = 0
        restartMsg string = ""
        diagFileName
        appFolder
        Pulse
        HTMLPulse
        roomLightsOn double = 0
        lightsOnRoomsOnly logical = false
        colBri
        startup logical = true
        UpdateTimer   timer
        lastRefresh datetime = datetime("now")
        autoRefresh logical = true
        autoRefreshCount double = 0
        sensorNameLastMovement
        vAPI double = 1 % v1 for Bridge v2 - v2 for Bridge Pro
        restoreWindowPos logical = true

        Bridge   % HueBridgeV2 instance
        idMap

        % API v2 - data start
        geolocationv2
        roomsv2
        zonesv2
        lightsv2
        bridgev2
        devicesv2
        sensorsv2
        sensorsTempv2
        sensorsMotionv2
        groupsv2
        scenesv2
        scriptsv2
        schedulesv2
        capabilitiesv2
        resourcelinksv2
        softwareUpdatesv2
        powerStatesv2
        switchesv2
        devicePowerv2
        connectivityv2
        %API v2 - data end

        allResources
        resourceMap
        bridgeSwitch logical = false
        offlineMode logical = false
        rawResources % raw data for all resources from API v2
        bridgeProMode logical = false % Real Bridge Pro processing
        tLights = []
        sunset
        currentX
        currentY
    end


    methods (Access = private)

        function errCode = getStats(app, request)

            tic

            addpath(genpath(app.dataRepository));

            errCode = 0;

            switch request
                case 'refresh'

                    if ~ app.offlineMode
                        [errCode, app.lastHueIP, app.appID, allDevices, allLights,   ...
                            allRules, allScripts, allSensors, allSensorTemps, allSensorMotions, ...
                            allSwitches, allPowerStates, ...
                            allGroups, allSchedules, allCapabilities, allConnectivity, ...
                            allResourceLinks, allScenes, config] = ...
                            connectToBridge(app);
                        app.OfflineMode.Tooltip = 'In On-line Mode.';
                    else
                        dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
                        strDt = string(dt);
                        app.DiagnosticsTimeTextArea.Value = strDt;
                        app.DiagnosticsMsgTextArea.Value = '** Restarted in Offline Mode **';
                        lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                        save (app.diagFileName,"lastDiagMsg");
                        [allDevices, allLights, allRules, allScripts, allSensors, ...
                            allSensorTemps, allSensorMotions, allSwitches, allGroups, ...
                            allSchedules, allCapabilities, allConnectivity, allResourceLinks, allScenes, ...
                            allPowerStates, config] = loadAllData(app);
                        app.startup = false; % Prevent re-saving data
                        app.offlineMode = true;
                        app.successfulStart = true;
                        app.autoRefresh = false;
                        app.OfflineMode.Tooltip = 'In Off-line Mode.';
                    end

                    switch errCode
                        case 777
                            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
                            strDt = string(dt);
                            app.DiagnosticsTimeTextArea.Value = strDt;
                            app.DiagnosticsMsgTextArea.Value = '** Unable to look up the curent Hue IP address using https://discovery.meethue.com... (If using a VPN turn it off for the duration of this process **';
                            lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                            save (app.diagFileName,"lastDiagMsg");
                            [allDevices, allLights, allRules, allScripts, allSensors, ...
                                allSensorTemps, allSensorMotions, allSwitches, allGroups, ...
                                allSchedules, allCapabilities, allConnectivity, allResourceLinks, allScenes, ...
                                allPowerStates, config] = loadAllData(app);
                            app.startup = false; % Prevent re-saving data
                            app.offlineMode = true;
                            app.successfulStart = true;
                            app.OfflineMode.Value = 1;
                            app.autoRefresh = false;
                            app.OfflineMode.Tooltip = 'In Off-line Mode.';
                        case 888
                            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
                            strDt = string(dt);
                            app.DiagnosticsTimeTextArea.Value = strDt;
                            app.DiagnosticsMsgTextArea.Value = '** Not connected to Bridge **';
                            lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                            save (app.diagFileName,"lastDiagMsg");
                            return;
                        case 999
                            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
                            strDt = string(dt);
                            app.DiagnosticsTimeTextArea.Value = strDt;
                            app.DiagnosticsMsgTextArea.Value = '** No Internet **';
                            lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                            save (app.diagFileName,"lastDiagMsg");
                            return;
                        otherwise
                    end

                case 'startup'
                    app.responseLimit = 0;
                    app.prevMaxTemp = -999;
                    app.prevMinTemp = 999;
                    if ispc
                        app.fName = [app.dataRepository '\userNameHue.mat'];
                        app.diagFileName = [app.dataRepository '\hueAppDiagnostics.mat'];
                    else
                        app.fName = [app.dataRepository '/userNameHue.mat'];
                        app.diagFileName = [app.dataRepository '/hueAppDiagnostics.mat'];
                    end
                    try
                        load(app.diagFileName);
                        if ~contains(restartDetails, 'success','IgnoreCase',true)
                            app.restartMsg = restartDetails;
                        else
                            app.restartMsg = "";
                        end
                        restartDetails = "Successful Startup.";
                        if ispc
                            save([app.dataRepository '\hueAppDiagnostics'],"restartDetails");
                        else
                            save([app.dataRepository '/hueAppDiagnostics'],"restartDetails");
                        end
                    catch
                        % app.restartMsg = 'No restart details found';
                    end
                    try
                        fprintf('Checking that %s is readable...', app.fName);
                        load(app.fName, "hueUser");  % userNameHue
                        app.appID = hueUser;
                        % disp(app.appID)
                        fprintf('OK. \n');
                    catch EM
                        disp(EM.message)
                        disp(EM.cause);
                        fprintf('PROBLEM...Creating temporary dummy ID...will need to pair with Bridge.\n');
                        hueUser = 'dummy-need-to-pair'; % app.appID
                        save([app.fName],"hueUser");
                    end
                    return;
                otherwise
            end

            app.cellSelIndices = [0 0];
            app.newBri = 0;
            app.prevEventName = 'none';
            app.editGroup = '';
            cd (app.dataRepository)
            app.IPAddress.Value = app.lastHueIP;

            %% >>> Amend or remove
            %%% Check for Bridge version
            %%% May need to cater for Bridge v2 and Pro working in tandem..
            %%%
            % app.vAPI = detectHueApiVersion(app); controlled by
            % app.BridgeDropDown callback

            if app.vAPI == 1 % Bridge v2
                app.hueIPAddressV2 = app.lastHueIP;
                app.BridgeID.Value = config.bridgeid;
                app.ZigbeeChannel.Value = config.zigbeechannel;
                app.APIversion.Value = config.apiversion;
                if config.swupdate2.autoinstall.on
                    app.AutoSWUpdate.Value = 'On';
                else
                    app.AutoSWUpdate.Value = 'Off';
                end
                % Convert to datetime using input format
                dt = datetime(config.swupdate2.bridge.lastinstall, 'InputFormat', 'yyyy-MM-dd''T''HH:mm:ss');
                dt.Format = 'dd-MMM-yyyy HH:mm:ss';
                app.LastFirmwareUpdate.Value = string(dt);
                app.SWversion.Value = config.swversion;
                app.SWUpDateAvail.Value = config.swupdate2.bridge.state;
                if config.swupdate2.bridge.state == "noupdates"
                    app.SWUpDateAvail.Value = 'No updates available';
                else
                    if contains(config.swupdate2.bridge.state,'readytoinstall')
                        app.SWUpDateAvail.Value = 'Updates available' ;
                    else
                        app.SWUpDateAvail.Value = 'Unknown';
                    end
                end
                %%% Reset in case previously hidden by Bridge Pro view
                %%%
                app.BRSensors.Visible = "on";
                app.PCSensors.Visible = "on";
                app.BRGroups.Visible = "on";
                app.PCGroups.Visible = "on";
                app.BRRules.Visible = "on";
                app.PCRules.Visible = "on";
                app.BRScenes.Visible = "on";
                app.PCScenes.Visible = "off";
                app.BRSchedules.Visible = "on";
                app.PCSchedules.Visible = "on";
                app.BRResourceLinks.Visible = "on";
                app.PCResourceLinks.Visible = "on";
                %%%
                app.AVLights.Value = allCapabilities.lights.total - allCapabilities.lights.available;
                app.BRLights.Value = allCapabilities.lights.total;
                app.PCLights.Value = (allCapabilities.lights.total - allCapabilities.lights.available) ...
                    * 100 / allCapabilities.lights.total;
                app.AVSensors.Value = allCapabilities.sensors.total - allCapabilities.sensors.available;
                app.BRSensors.Value = allCapabilities.sensors.total;
                app.PCSensors.Value = (allCapabilities.sensors.total - allCapabilities.sensors.available) ...
                    * 100 / allCapabilities.sensors.total;
                app.AVScenes.Value = allCapabilities.scenes.total - allCapabilities.scenes.available;
                app.BRScenes.Value = allCapabilities.scenes.total;
                app.PCScenes.Value = (allCapabilities.scenes.total - allCapabilities.scenes.available) ...
                    * 100 / allCapabilities.scenes.total;
                app.AVSchedules.Value = allCapabilities.schedules.total - allCapabilities.schedules.available;
                app.BRSchedules.Value = allCapabilities.schedules.total;
                app.PCSchedules.Value = (allCapabilities.schedules.total - allCapabilities.schedules.available) ...
                    * 100 / allCapabilities.schedules.total;
                app.AVRules.Value = allCapabilities.rules.total - allCapabilities.rules.available;
                app.BRRules.Value = allCapabilities.rules.total;
                app.PCRules.Value = (allCapabilities.rules.total - allCapabilities.rules.available) ...
                    * 100 / allCapabilities.rules.total;
                app.AVResourceLinks.Value = allCapabilities.resourcelinks.total - allCapabilities.resourcelinks.available;
                app.BRResourceLinks.Value = allCapabilities.resourcelinks.total;
                app.PCResourceLinks.Value = (allCapabilities.resourcelinks.total - allCapabilities.resourcelinks.available) ...
                    * 100 / allCapabilities.resourcelinks.total;

                %%%%
                %%%%  Sensors
                %%%%

                tableSensors = struct2table(allSensors);
                noSensors = size(tableSensors);
                fieldX = fieldnames(allSensors(1,1));
                % Create a standard format table of Sensors
                sz = [noSensors(1,2) 9];
                varTypes = ["double", "string", "string", "string", "string", "double", "double","datetime","string"];
                varNames = ["sensorno", "sensorname", "modelID", "productname", "type", "battery", "temp","lastevent","uniqueid"];
                tSensors = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                tSensors.sensorno = str2double(extractAfter(fieldX, 'x'));
                lowBattery = 0;
                maxTemp = -99;
                maxRoom = [];
                minTemp = 99;
                minRoom = [];
                warm = 0;
                veryWarm = 0;
                cool = 0;
                cold = 0;
                freezing = 0;
                tgt = 0;
                noTemps = 0;
                noMotion = 0;
                noDimmers = 0;
                for i = 1:noSensors(1,2)
                    try
                        battery = tableSensors.(fieldX{i,1}).config.battery;
                        tSensors.battery(i) = tableSensors.(fieldX{i,1}).config.battery;
                        tSensors.productname(i) = tableSensors.(fieldX{i,1}).productname;
                        tSensors.modelID(i) = tableSensors.(fieldX{i,1}).modelid;
                        tSensors.uniqueid(i) = tableSensors.(fieldX{i,1}).uniqueid;
                        try
                            tSensors.lastevent(i) = tableSensors.(fieldX{i,1}).state.lastupdated;
                        catch
                            tSensors.lastevent(i) = tableSensors.(fieldX{i,1}).swupdate.lastinstall;
                        end
                        if contains(tSensors.productname(i),'motion')
                            noMotion = noMotion + 1;
                            if battery < 30
                                lowBattery = lowBattery + 1;
                            end
                        end
                        if contains(tSensors.productname(i),'dimmer')
                            noDimmers = noDimmers + 1;
                            if battery < 30
                                lowBattery = lowBattery + 1;
                            end
                        end
                    catch
                    end
                    tSensors.sensorname(i) = tableSensors.(fieldX{i,1}).name;
                    tSensors.type(i) = tableSensors.(fieldX{i,1}).type;
                    if  contains(tSensors.type(i) ,"Temperature")
                        tSensors.temp(i) = tableSensors.(fieldX{i,1}).state.temperature/100;
                        noTemps = noTemps + 1;
                        if tSensors.temp(i) > maxTemp
                            maxTemp = tSensors.temp(i);
                            maxRoom = extractBetween(tSensors.sensorname(i),'Sensor','Temp');
                        end
                        if tSensors.temp(i) < minTemp
                            minTemp = tSensors.temp(i);
                            minRoom = extractBetween(tSensors.sensorname(i),'Sensor','Temp');
                        end
                        if tSensors.temp(i) > 30
                            veryWarm = veryWarm + 1;
                        elseif tSensors.temp(i) > 21
                            warm = warm + 1;
                        elseif tSensors.temp(i) < 0
                            freezing = freezing + 1;
                        elseif tSensors.temp(i) < 10
                            cold = cold + 1;
                        elseif tSensors.temp(i) < 18
                            cool = cool + 1;
                        else
                            tgt = tgt + 1;
                        end
                    end
                end

                app.WASensors.Value = warm;
                app.HOSensors.Value = veryWarm;
                app.CLSensors.Value = cool;
                app.COSensors.Value = cold;
                app.FRSensors.Value = freezing;
                app.CMSensors.Value = tgt;
                if app.prevMaxTemp == -999
                    app.prevMaxTemp = maxTemp;
                end
                if app.prevMinTemp == 999
                    app.prevMinTemp = minTemp;
                end
                if maxTemp > app.prevMaxTemp
                    maxTempText = append('<font color="yellow">Max: </font>', maxRoom,...
                        '<font color="yellow">',' RISING',' </font>', ' to ', num2str(maxTemp,'%+2.2f'));
                    app.prevMaxTemp = maxTemp;
                elseif maxTemp < app.prevMaxTemp
                    maxTempText = append('<font color="yellow">Max: </font>', maxRoom, ...
                        '<font color="cyan">',' DROPPING',' </font>', ' to ', num2str(maxTemp,'%+2.2f'));
                    app.prevMaxTemp = maxTemp;
                else
                    maxTempText = append('<font color="yellow">Max: </font>', maxRoom, ...
                        '<font color="grey">',' STABLE',' </font>', ' at ', num2str(maxTemp,'%+2.2f'));
                end

                if minTemp > app.prevMinTemp
                    minTempText = append('<font color="yellow">Min: </font>', minRoom, ...
                        '<font color="yellow">',' RISING',' </font>', ' to ', num2str(minTemp,'%+2.2f'));
                    app.prevMinTemp = minTemp;
                elseif minTemp < app.prevMinTemp
                    minTempText = append('<font color="yellow">Min: </font>', minRoom, ...
                        '<font color="cyan">',' DROPPING',' </font>', ' to ', num2str(minTemp,'%+2.2f'));
                    app.prevMinTemp = minTemp;
                else
                    minTempText = append('<font color="yellow">Min: </font>', minRoom, ...
                        '<font color="grey">',' STABLE',' </font>', ' at ', num2str(minTemp,'%+2.2f'));
                end

                app.SensorTemperaturesPanel.Title = append('Sensor Temperatures           Max ',...
                    num2str(maxTemp,'%+2.2f'), ' <-> Min ', num2str(minTemp,'%+2.2f'));
                app.MotionSensors.Text = num2str(noMotion);
                app.DimmerSwitches.Text = num2str(noDimmers);
                app.TotalDevices.Text =num2str(noMotion + noDimmers);
                noTempsnDimmers = noTemps + noDimmers;
                if noMotion == 1
                    app.MotionSensorsLabel.Text = 'Motion Sensor';
                else
                    app.MotionSensorsLabel.Text = 'Motion Sensors';
                end
                if noDimmers == 1
                    app.SwitchesLabel.Text = 'Switch';
                else
                    app.SwitchesLabel.Text = 'Switches';
                end
                sz = [noTempsnDimmers 4];
                varTypes = ["string", "double", "string","datetime"];
                varNames = ["temp", "battery", "sensorname","lastevent"];
                tTemps = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                j = 0;
                lastMotionIdx = 0;
                for i = 1:noSensors(1,2)
                    if  contains(tSensors.type(i) ,"Temperature")
                        j = j + 1;
                        tTemps.temp(j) = num2str(tSensors.temp(i),'%+2.2f');
                        tTemps.battery(j) = tSensors.battery(i);
                        tTemps.sensorname(j) = strcat(tSensors.sensorname(i), ' (', tSensors.modelID(i), ')');
                        tTemps.lastevent(j) = tSensors.lastevent(i);
                    elseif contains(tSensors.type(i) ,"Switch")
                        j = j+ 1;
                        tTemps.temp(j) = ' ';
                        tTemps.battery(j) = tSensors.battery(i);
                        tTemps.sensorname(j) = strcat(tSensors.sensorname(i), ' (', tSensors.modelID(i), ')');
                        tTemps.lastevent(j) = tSensors.lastevent(i);
                    elseif contains(tSensors.type(i) ,"Presence")
                        if lastMotionIdx == 0 || tSensors.lastevent(i) > lastMotion
                            lastMotion = tSensors.lastevent(i);
                            lastMotionIdx = i;
                        end
                    end
                end

                if lastMotionIdx > 0
                    app.sensorNameLastMovement = tSensors.sensorname(lastMotionIdx);
                    motionDate = extractBefore(string(lastMotion), " ");
                    motionTime = extractAfter(string(lastMotion), " ");
                    lastMotionText  = append('Last Motion detected by ', tSensors.sensorname(lastMotionIdx),...
                        newline, 'on ', motionDate, ' at ', motionTime);
                    if strlength(tSensors.sensorname(lastMotionIdx)) > 25
                        lastMotionFontsize = '16px';
                    else
                        lastMotionFontsize = '18px';
                    end
                    lastMotionFontcol = 'cyan';
                    lastMotionFontweight = 'bold';
                else
                    lastMotionText = 'No Motions detected.';
                    lastMotionFontsize = 14;
                    lastMotionFontcol = 'yellow';
                    lastMotionFontweight= 'normal';
                end
                app.HTMLLastMotion.Data = struct( ...
                    'message', lastMotionText, ...
                    'color', lastMotionFontcol, ...
                    'fontsize', lastMotionFontsize, ...
                    'fontweight', lastMotionFontweight, ...
                    'lineheight', '0.75', ...
                    'width', '385px', ...
                    'height', '37px', ...
                    'speed', '10s');

                tTemps = sortrows(tTemps,{'temp','sensorname'},{'descend', 'ascend'});
                app.TempsTable.Data = tTemps;

                app.SensorsandSwitchesLabel.Text = '<html><p style="line-height:0.75;">Sensors and<br>Switches</p></html>';
                app.Sunset.Visible ="off";
                if lowBattery > 0
                    sz = [lowBattery 3];
                    varTypes = ["double", "string", "string"];
                    varNames = ["battery", "modelID", "sensorname"];
                    tLowBat = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                    j = 0;
                    for i = 1:noSensors(1,2)
                        try
                            battery = tableSensors.(fieldX{i,1}).config.battery;
                            if (contains(tSensors.productname(i),'motion') ...
                                    || contains(tSensors.productname(i),'dimmer')) ...
                                    && battery < 30
                                j = j + 1;
                                tLowBat.battery(j) = battery;
                                tLowBat.modelID(j) = tableSensors.(fieldX{i,1}).modelid;
                                tLowBat.sensorname(j) = tableSensors.(fieldX{i,1}).name;
                            end
                        catch
                        end
                    end
                    app.TableSensors.Data = sortrows(tLowBat);
                    s1 = uistyle('BackgroundColor','red');
                    s2 = uistyle;
                    s2.FontColor = 'black';
                    addStyle(app.TableSensors,s1,'column',1);
                    addStyle(app.TableSensors,s2,'column',1);
                    app.TableSensors.Visible = "on";
                    app.HTMLLowBatMsg.Visible = "on";
                    % Fast blink (1 second per cycle)
                    app.HTMLLowBatDevs.Data = struct( ...
                        'message', num2str(lowBattery), ...
                        'color', 'red', ...
                        'fontfamily', 'Arial Black', ...
                        'fontsize', '16px', ...
                        'fontweight', 'bold', ...
                        'lineheight', '0', ...
                        'width', '45px', ...
                        'height', '25px', ...
                        'speed', '1s');
                    % pulseText.html..(medium cycle)
                    app.HTMLLowBatMsg.Data = struct( ...
                        'message', '⚠ Devices with a Battery Level below 30% ⚠', ...
                        'speed', '5s');
                else
                    % No blinking
                    app.HTMLLowBatDevs.Data = struct( ...
                        'message', 'none', ...
                        'color', 'white', ...
                        'fontfamily', 'Calibri', ...
                        'fontsize', '14px', ...
                        'fontweight', 'normal', ...
                        'lineheight', '0', ...
                        'width', '45px', ...
                        'height', '25px', ...
                        'speed', '0s');
                    app.TableSensors.Visible = "off";
                    app.HTMLLowBatMsg.Visible = "on";
                    % pulseText.html..(very slow cycle)
                    app.HTMLLowBatMsg.Data = struct( ...
                        'message', '✔ All devices have a Battery Level above 30% ✔', ...
                        'color', 'green', ...
                        'speed', '10s');
                    if contains(string(app.sunset), ":")
                        app.Sunset.Visible ="on";
                        app.Sunset.Text = append("Sunset ", extractBefore(app.sunset,":00"));
                    end
                end

                %%%%
                %%%% Lights
                %%%%

                tableLights = struct2table(allLights);
                noLights = size(tableLights);
                fieldX = fieldnames(allLights(1,1));

                % Create a standard format table of Lights
                sz = [noLights(1,2) 12];
                varTypes = ["double", "string", "string", "string","string","double","double","double","logical","string","logical","string"];
                varNames = ["lightno", "lightname", "productname", "model", "type","bright","x","y","on","uniqueid","reachable","colormode"];
                app.tLights = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                app.tLights.lightno = str2double(extractAfter(fieldX, 'x'));
                lightsReachable = 0;
                app.lightsOn = 0;

                for i = 1:noLights(1,2)
                    app.tLights.model(i) = tableLights.(fieldX{i,1}).modelid;
                    app.tLights.productname(i) = tableLights.(fieldX{i,1}).productname;
                    app.tLights.lightname(i) = tableLights.(fieldX{i,1}).name;
                    app.tLights.type(i) = tableLights.(fieldX{i,1}).type;
                    app.tLights.reachable(i) = tableLights.(fieldX{i,1}).state.reachable;
                    app.tLights.uniqueid(i) = tableLights.(fieldX{i,1}).uniqueid;
                    app.tLights.bright(i) = tableLights.(fieldX{i,1}).state.bri;
                    try
                        app.tLights.colormode(i) = tableLights.(fieldX{i,1}).state.colormode;
                    catch
                        app.tLights.colormode(i) = "white";
                    end
                    % xy colours..
                    if app.tLights.colormode(i) == "xy" || app.tLights.colormode(i) == "hs"
                        app.tLights.x(i) = tableLights.(fieldX{i,1}).state.xy(1,1);
                        app.tLights.y(i) = tableLights.(fieldX{i,1}).state.xy(2,1);
                    else % not a colour bulb so default to cool white
                        app.tLights.colormode(i) = "white";
                        app.tLights.x(i) = 0.3127;
                        app.tLights.y(i) = 0.3290;
                    end
                    if app.tLights.reachable(i)
                        lightsReachable = lightsReachable + 1;
                    end
                    app.tLights.on(i) = tableLights.(fieldX{i,1}).state.on;
                    if app.tLights.on(i)
                        app.lightsOn = app.lightsOn + 1;
                    end
                end

                unreachable = noLights(1,2) - lightsReachable;
                app.ONLights.Value = app.lightsOn;
                if app.lightsOn == 1
                    app.LightsOnLabel.Text = 'Light';
                else
                    app.LightsOnLabel.Text = 'Lights';
                end
                blinkLightsOn(app);

                if unreachable > 0
                    sz = [unreachable 2];
                    varTypes = ["string", "string"];
                    varNames = ["ID", "lightname"];
                    tUnreachable = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                    j = 0;
                    for i = 1:noLights(1,2)
                        if ~ app.tLights.reachable(i)
                            j = j + 1;
                            tUnreachable.ID(j) = app.tLights.model(i);
                            tUnreachable.lightname(j) =  app.tLights.lightname(i);
                        end
                    end

                    app.TableLights.Data = tUnreachable;
                    s1 = uistyle('BackgroundColor','white');
                    s2 = uistyle;
                    s2.FontColor = 'black';
                    s2.FontWeight = 'bold';
                    addStyle(app.TableLights,s1,'column',1);
                    addStyle(app.TableLights,s2,'column',1);
                    app.TableLights.Visible = "on";
                    app.HTMLTempTrend.Visible = "on";
                    app.UnreachableLightsLabel.Interpreter = 'none';
                    app.UnreachableLightsLabel.Tooltip =  'This section is only presented when there ARE Unreachable Lights.';
                    % Fast blink (1 second per cycle)
                    app.HTMLUnreachableLights.Data = struct( ...
                        'message', num2str(unreachable), ...
                        'color', 'red', ...
                        'fontfamily', 'Arial Black', ...
                        'fontsize', '16px', ...
                        'fontweight', 'bold', ...
                        'lineheight', '0', ...
                        'width', '45px', ...
                        'height', '25px', ...
                        'speed', '1s');
                    % Fast blink (1 second per cycle)
                    app.HTMLTempTrend.Data = struct( ...
                        'message', '⚠ Unreachable Lights ⚠', ...
                        'color', 'red', ...
                        'fontsize', '16px', ...
                        'fontweight', 'bold', ...
                        'lineheight', '0', ...
                        'width', '319px', ...
                        'height', '28px', ...
                        'speed', '1s');
                else
                    app.TableLights.Visible = "off";
                    app.HTMLTempTrend.Visible = "on";
                    app.UnreachableLightsLabel.FontColor = "white";
                    app.UnreachableLightsLabel.Tooltip = 'This section is only presented when there are NO Unreachable Lights.';
                    app.UnreachableLightsLabel.BackgroundColor = "none";
                    app.UnreachableLightsLabel.FontWeight = "bold";
                    app.UnreachableLightsLabel.FontSize = 16;
                    app.UnreachableLightsLabel.HorizontalAlignment = 'left';
                    app.UnreachableLightsLabel.Interpreter = 'html';
                    app.UnreachableLightsLabel.Text  = ...
                        append('<html><p style="line-height:1;"><font color="white"><b><br>', ...
                        maxTempText, '<br><br>', ...
                        minTempText, '</b></font></p>');
                    % No blinking
                    app.HTMLUnreachableLights.Data = struct( ...
                        'message', 'none', ...
                        'color', 'white', ...
                        'fontfamily', 'Calibri', ...
                        'fontsize', '14px', ...
                        'fontweight', 'normal', ...
                        'lineheight', '0', ...
                        'width', '45px', ...
                        'height', '25px', ...
                        'speed', '0s');
                    % Very slow blink (10 seconds per cycle)
                    app.HTMLTempTrend.Data = struct( ...
                        'message', '℃ Temperature trends since last Refresh ℃', ...
                        'color', 'white', ...
                        'fontsize', '16px', ...
                        'fontweight', 'bold', ...
                        'lineheight', '0', ...
                        'width', '319px', ...
                        'height', '28px', ...
                        'speed', '10s');
                end

                %%%%
                %%%% Schedules
                %%%%

                tableSchedules = struct2table(allSchedules);
                noSchedules = size(tableSchedules);
                fieldX = fieldnames(allSchedules(1,1));

                % Create a standard format table of Schedules
                sz = [noSchedules(1,2) 6];
                varTypes = ["double", "string", "string","string","datetime","datetime"];
                varNames = ["scheduleno", "schedulename", "desc", "status","created","starttime"];
                tSchedules = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                tSchedules.scheduleno = str2double(extractAfter(fieldX, 'x'));
                enabledSchedules = 0;
                for i = 1:noSchedules(1,2)
                    tSchedules.desc(i) = tableSchedules.(fieldX{i,1}).description;
                    status = tableSchedules.(fieldX{i,1}).status;
                    if status == "enabled"
                        enabledSchedules = enabledSchedules + 1;
                    end
                    tSchedules.status(i) = tableSchedules.(fieldX{i,1}).status;
                    tSchedules.schedulename(i) = tableSchedules.(fieldX{i,1}).name;
                    tSchedules.created(i) = tableSchedules.(fieldX{i,1}).created;
                    try
                        tSchedules.starttime(i) = tableSchedules.(fieldX{i,1}).starttime;
                    catch
                    end
                end
                app.ONSchedules.Value = enabledSchedules;

                %%%%
                %%%% Groups
                %%%%

                tableGroups = struct2table(allGroups);
                noGroups = size(tableGroups);
                fieldX = fieldnames(allGroups(1,1));

                app.AVGroups.Value = noGroups(1,2); % the actual number of groups in use rather than the value from allCapabilities
                app.BRGroups.Value = allCapabilities.groups.total;
                app.PCGroups.Value =  app.AVGroups.Value * 100 / app.BRGroups.Value;

                % Create a standard format table of Groups
                sz = [noGroups(1,2) 8];
                varTypes = ["double", "string", "string", "double", "string","string","logical","logical"];
                varNames = ["groupno", "groupname", "type", "nolights","lights","class","allon","anyon"];
                app.tGroups = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                app.tGroups.groupno = str2double(extractAfter(fieldX, 'x'));
                noGroupLights = 0;
                grplightsOn = 0;
                app.roomLightsOn = 0;
                for i = 1:noGroups(1,2)
                    try
                        app.tGroups.lights(i) = strjoin(tableGroups.(fieldX{i,1}).lights, ', ');
                        x = size(tableGroups.(fieldX{i,1}).lights);
                        app.tGroups.nolights(i) = x(1,1);
                        noGroupLights = noGroupLights + x(1,1);
                    catch
                        app.tGroups.lights(i) = "none";
                    end
                    app.tGroups.type(i) = tableGroups.(fieldX{i,1}).type;
                    app.tGroups.groupname(i) = tableGroups.(fieldX{i,1}).name;
                    app.tGroups.class(i) = tableGroups.(fieldX{i,1}).class;
                    app.tGroups.allon(i) = tableGroups.(fieldX{i,1}).state.all_on;
                    app.tGroups.anyon(i) = tableGroups.(fieldX{i,1}).state.any_on;
                    if app.tGroups.anyon(i)
                        grplightsOn = grplightsOn + 1;
                        if app.tGroups.type(i) == "Room"
                            app.roomLightsOn = app.roomLightsOn + 1;
                        end
                    end
                end

                % Create an intersection table of Groups and Lights
                sz = [noGroupLights 4];
                varTypes = ["double", "string","string","double"];
                varNames = ["groupno", "groupname", "grouptype", "lightno"];
                itGroupslights = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                l = 0;
                for i = 1:noGroups(1,2)
                    if app.tGroups.nolights(i,1) > 0
                        for j = 1:app.tGroups.nolights(i)
                            l = l + 1;
                            itGroupslights.groupno(l) = app.tGroups.groupno(i,1);
                            itGroupslights.groupname(l) = app.tGroups.groupname(i,1);
                            itGroupslights.grouptype(l) = app.tGroups.type(i,1);
                            itGroupslights.lightno(l) =  str2double(tableGroups.(fieldX{i,1}).lights{j,1});
                        end
                    end
                end

                tGroupsLights = join(itGroupslights, app.tLights);

                app.OnGroups.Value = grplightsOn;

                tGroupsLights = sortrows(tGroupsLights,[10 11 2],["descend" "descend" "ascend"]);

                sz = [noGroupLights 12];
                varTypes = ["string", "string", "double","string","string","string","logical","double","string","double","double","string"];
                varNames = ["groupname", "grouptype", "lightno","lightname","productname","model","state","bright","colour","x","y","colormode"];
                allLightsTable = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                sz = [app.roomLightsOn 12];
                roomLightsTable = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                allLightsTable.groupname = tGroupsLights.groupname;
                allLightsTable.grouptype = tGroupsLights.grouptype;
                allLightsTable.lightno = tGroupsLights.lightno;
                allLightsTable.lightname = tGroupsLights.lightname;
                allLightsTable.productname = tGroupsLights.productname;
                allLightsTable.model = tGroupsLights.model;
                allLightsTable.state = tGroupsLights.on;
                allLightsTable.colour(:) = "white";
                allLightsTable.bright = max(round(tGroupsLights.bright/2.54),1);
                allLightsTable.x = tGroupsLights.x;
                allLightsTable.y = tGroupsLights.y;
                allLightsTable.colormode = tGroupsLights.colormode;

                j = 0;
                for i = 1:noGroupLights
                    if allLightsTable.grouptype(i) == "Room" && allLightsTable.state(i) == true
                        j = j + 1;
                        roomLightsTable(j,:) = allLightsTable(i,:);
                    end
                end

                if app.lightsOnRoomsOnly
                    roomLightsTable = sortrows(roomLightsTable);
                    app.LightsTable.Data = roomLightsTable(:,1:9);
                else
                    allLightsTable = sortrows(allLightsTable);
                    app.LightsTable.Data = allLightsTable(:,1:9);
                end

                updateONGroupsRooms(app);

                fmt = '%02d';

                lightsInGrid = size(app.LightsTable.Data);
                if app.lightsOnRoomsOnly
                    for i=1:lightsInGrid(1,1)
                        if roomLightsTable.colormode(i) ~= "white"
                            x = roomLightsTable.x(i);
                            y = roomLightsTable.y(i);
                            bri = max(round(roomLightsTable.bright(i) * 2.54),1);
                            rgb = hueXYtoRGB(app,x,y,bri);
                            app.LightsTable.Data{i,9} = append({nearestCssColourName(app, rgb)}, ' [', num2str(round(x * 100,0),fmt), ',', num2str(round(y * 100,0),fmt),']');
                            s1 = uistyle('BackgroundColor',rgb);
                            s2 = uistyle;
                            addStyle(app.LightsTable,s1,'cell', [i 9]);
                            if sum(rgb) == 0
                                s2.FontColor = 'white';
                            else
                                s2.FontColor = 'black';
                            end
                            addStyle(app.LightsTable,s2,'cell', [i 8]);
                        else
                            s1 = uistyle('BackgroundColor','white');
                            addStyle(app.LightsTable,s1,'cell', [i 9]);
                        end
                    end
                else
                    for i = 1:lightsInGrid(1,1)
                        if allLightsTable.colormode(i) ~= "white"
                            x = allLightsTable.x(i);
                            y = allLightsTable.y(i);
                            bri = max(round(allLightsTable.bright(i) * 2.54),1);
                            rgb = hueXYtoRGB(app,x,y,bri);
                            app.LightsTable.Data{i,9} = append({nearestCssColourName(app, rgb)}, ' [', num2str(round(x * 100,0),fmt), ',', num2str(round(y * 100,0),fmt),']');
                            s1 = uistyle('BackgroundColor',rgb);
                            s2 = uistyle;
                            addStyle(app.LightsTable,s1,'cell', [i 9]);
                            if sum(rgb) == 0
                                s2.FontColor = 'white';
                            else
                                s2.FontColor = 'black';
                            end
                            addStyle(app.LightsTable,s2,'cell',[i 8]);
                        else
                            s1 = uistyle('BackgroundColor','white');
                            addStyle(app.LightsTable,s1,'cell',[i 9]);
                        end
                    end
                end

                highlightOnLights(app);

                %%%%
                %%%% Scenes
                %%%%

                tableScenes = struct2table(allScenes);
                noScenes = size(tableScenes);
                fieldX = fieldnames(allScenes(1,1));

                % Create a standard format table of Scenes
                sz = [noScenes(1,2) 6];
                varTypes = ["string", "string", "string", "datetime", "string","string"];
                varNames = ["sceneID", "scenename", "type", "lastupdate", "group", "lights"];
                tScenes = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                tScenes.sceneID = replace(fieldX, '_', '-');

                for i = 1:noScenes(1,2)
                    tScenes.scenename(i) = tableScenes.(fieldX{i,1}).name;
                    tScenes.type(i) = tableScenes.(fieldX{i,1}).type;
                    tScenes.lastupdate(i) = tableScenes.(fieldX{i,1}).lastupdated;
                    try
                        tScenes.group(i) = tableScenes.(fieldX{i,1}).group;
                    catch
                        tScenes.group(i) = 'n/a';
                    end
                    try
                        tScenes.lights(i) = strjoin(tableScenes.(fieldX{i,1}).lights, ', ');
                    catch
                        tScenes.lights(i) = 'none';
                    end
                end

                %%%%
                %%%% Rules
                %%%%

                tableRules = struct2table(allRules);
                noRules = size(tableRules);

                fieldX = fieldnames(allRules(1,1));

                % Create a standard format table of Rules
                sz = [noRules(1,2) 19];
                varTypes = ["double", "string", "string", "datetime", "datetime", "double", "string","string", ...
                    "string", "double", "string", "string", ...
                    "double", "string", ...
                    "double", "string", "double", "string", "string"];
                varNames = ["ruleno", "location", "rulename", "created", "lasttriggered", "timestriggered", "status", "action", ...
                    "target", "id", "description", "RL", ...
                    "groupno", "groupdesc", ...
                    "sensorno", "sensordesc","scheduleno", "scheduledesc","scenedesc"];
                tRules = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                tRules.ruleno = str2double(extractAfter(fieldX, 'x'));
                rulesEnabled = 0;
                rulesNotTrig = 0;

                for i = 1:noRules(1,2)
                    tRules.created(i) = tableRules.(fieldX{i,1}).created;
                    if tableRules.(fieldX{i,1}).lasttriggered ~= "none"
                        tRules.lasttriggered(i) = tableRules.(fieldX{i,1}).lasttriggered;
                    else
                        tRules.lasttriggered(i) = tableRules.(fieldX{i,1}).created; % default to date created
                    end
                    tRules.timestriggered(i) = tableRules.(fieldX{i,1}).timestriggered;
                    if tRules.timestriggered(i) == 0
                        rulesNotTrig = rulesNotTrig + 1;
                    end
                    status = tableRules.(fieldX{i,1}).status;
                    if status == "enabled"
                        rulesEnabled = rulesEnabled + 1;
                    end
                    tRules.status(i) = tableRules.(fieldX{i,1}).status;
                    tRules.action(i) = tableRules.(fieldX{i,1}).actions.address;
                    tRules.rulename(i) = tableRules.(fieldX{i,1}).name;
                    tRules.groupno(i)  = 0;
                    tRules.sensorno(i)  = 0;
                    tRules.scheduleno(i) = 0;

                    grF = count(tRules.action(i),"groups/");
                    grFx = count(tRules.rulename(i),"Group");
                    scF = count(tRules.action(i),"scenes/");
                    seF = count(tRules.action(i),"sensors/");
                    seFx = count(tRules.rulename(i),"Sensor");
                    diF = count(tRules.rulename(i),"Dimmer");
                    shF = count(tRules.action(i),"schedules/");
                    xfdF = count(tRules.rulename(i),"XFD");

                    %
                    % Default in case no better Description extracted in following section
                    %
                    if seF > 0
                        tRules.sensorno(i)  = extractBetween(tRules.action(i),"sensors/","/");
                    end

                    if grF > 0
                        tRules.groupno(i) = extractBetween(tRules.action(i),"groups/","/action");
                        if tRules.groupno(i) == 0
                            if seFx > 0
                                tRules.sensorno(i)  = extractBetween(tRules.rulename(i),"Sensor ",".");
                            elseif diF > 0
                                tRules.sensorno(i)  = extractBetween(tRules.rulename(i),"Dimmer","Btn");
                            end
                        end
                    elseif grFx > 0
                        tRules.groupno(i) = extractAfter(tRules.rulename(i),"Group");
                    elseif scF > 0
                        if seFx > 0
                            tRules.sensorno(i)  = extractBetween(tRules.rulename(i),"Sensor ",".");
                        end
                    elseif seF > 0
                        if seFx > 0
                            tRules.sensorno(i)  = extractBetween(tRules.rulename(i),"Sensor ",".");
                        elseif diF > 0
                            if contains(tRules.rulename(i),"Switch")
                                tRules.sensorno(i)  = extractBetween(tRules.rulename(i),"Switch "," ");
                            else
                                tRules.sensorno(i)  = extractBetween(tRules.rulename(i),"Dimmer ",".");
                            end
                        elseif xfdF > 0
                            if contains(tRules.rulename(i),"XFDMotion")
                                try
                                    tRules.sensorno(i)  = extractBetween(tRules.rulename(i),"XFDMotion",".");
                                catch
                                    tRules.sensorno(i)  = extractAfter(tRules.rulename(i),"XFDMotion");
                                end
                            end
                        end
                    elseif shF > 0
                        tRules.scheduleno(i)  = extractAfter(tRules.action(i),"schedules/");
                        if contains(tRules.rulename(i),"Dimmer Switch")
                            tRules.sensorno(i)  = extractBetween(tRules.rulename(i),"Switch "," ");
                        end
                    end
                end

                %%%%
                %%%% Resource Links
                %%%%

                tableResourceLinks = struct2table(allResourceLinks);
                noRLs = size(tableResourceLinks);
                fieldX = fieldnames(allResourceLinks(1,1));

                % Create a standard format table of Resource Links
                sz = [noRLs(1,2) 4];
                varTypes = ["string", "string", "string", "string"];
                varNames = ["rlId", "rlname", "desc", "links"];
                tRlinks = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                tRlinks.rlId = fieldX;

                for i = 1:noRLs(1,2)
                    tRlinks.rlname(i) = tableResourceLinks.(fieldX{i,1}).name;
                    tRlinks.desc(i) = tableResourceLinks.(fieldX{i,1}).description;
                    tRlinks.links(i) = strjoin(tableResourceLinks.(fieldX{i,1}).links, ', ');
                    tRlinks.links(i) = strcat(tRlinks.links(i), ','); % add a final delimiter
                end

                %%%
                %%% Expand Rules table with Groups, Sensors, Schedules, Scenes & Resource Links data
                %%%

                for i = 1:noRules(1,2)

                    tRules.RL(i) = '*';
                    rlMatch = ['/rules/' num2str(tRules.ruleno(i)) ','];
                    for n = 1:noRLs(1,2)
                        if contains(tRlinks.links(n), rlMatch)
                            tRules.RL(i) = tRlinks.desc(n);
                            break;
                        end
                    end

                    nxt = false;
                    for j= 1:noGroups(1,2)
                        if tRules.groupno(i) > 0 && tRules.groupno(i) == app.tGroups.groupno(j)
                            tRules.groupdesc(i) = app.tGroups.groupname(j);
                            tRules.target(i) = "Group";
                            tRules.id(i) = app.tGroups.groupno(j);
                            tRules.description(i) = app.tGroups.groupname(j);
                            tRules.location(i) = app.tGroups.groupname(j);
                            tRules.sensordesc(i) = '*';
                            tRules.scheduledesc(i) = '*';
                            tRules.scenedesc(i) = '*';
                            nxt = true;
                            break; % only need one match.. (optimisation)
                        end
                    end
                    if nxt
                        continue; % process next rule.. (optimisation)
                    end
                    for k = 1:noSensors(1,2)
                        if tRules.sensorno(i) == tSensors.sensorno(k)
                            tRules.sensordesc(i) = tSensors.sensorname(k);
                            if contains(tSensors.sensorname(k),"Dimmer")
                                tRules.target(i) = "Dimmer";
                                tRules.location(i) = extractAfter(tSensors.sensorname(k),"Dimmer ");
                            else
                                tRules.target(i) = "Sensor";
                                tRules.location(i) = extractAfter(tSensors.sensorname(k),"Sensor ");
                            end
                            if contains(tRules.location(i), digitsPattern)
                                tRules.location(i) = extractAfter(tRules.RL(i),"Dimmer ");
                            end
                            if ismissing(tRules.location(i))
                                tRules.location(i) = extractAfter(tRules.RL(i),"Dimmer ");
                            end
                            if ismissing(tRules.location(i))
                                tRules.location(i) = extractAfter(tRules.RL(i),"Sensor ");
                            end
                            tRules.id(i) = tSensors.sensorno(k);
                            tRules.description(i) = tSensors.sensorname(k);
                            if ismissing(tRules.location(i))
                                tRules.location(i) = tRules.description(i);
                            end
                            tRules.groupdesc(i) = '*';
                            tRules.scheduledesc(i) = '*';
                            tRules.scenedesc(i) = '*';
                            nxt = true;
                            break;
                        end
                    end
                    if nxt
                        continue;
                    end
                    for l = 1:noSchedules(1,2)
                        if tRules.scheduleno(i) == tSchedules.scheduleno(l)
                            tRules.scheduledesc(i) = tSchedules.schedulename(l);
                            tRules.target(i) = "Schedule";
                            tRules.id(i) = tSchedules.scheduleno(l);
                            tRules.description(i) = tSchedules.schedulename(l);
                            tRules.sensordesc(i) = '*';
                            tRules.groupdesc(i) = '*';
                            tRules.scenedesc(i) = '*';
                            nxt = true;
                            break;
                        end
                    end
                    if nxt
                        continue;
                    end
                    for m = 1:noScenes(1,2)
                        if extractAfter(tRules.action(i), '/scenes/') == tScenes.sceneID(m)
                            tRules.scenedesc(i) = strcat(tScenes.scenename(m)," / ",tScenes.type(m));
                            tRules.target(i) = "Scene";
                            tRules.id(i) = tScenes.sceneID(m);
                            tRules.description(i) = strcat(tScenes.scenename(m)," / ",tScenes.type(m));
                            tRules.sensordesc(i) = '*';
                            tRules.groupdesc(i) = '*';
                            tRules.scheduledesc(i) = '*';
                            nxt = true;
                            break;
                        end
                    end
                    if not(nxt) % no match found for any Rules category..
                        tRules.target(i) = "none";
                        tRules.id(i) = 0;
                        tRules.description(i) = '*';
                        tRules.location(i) = '*';
                        tRules.sensordesc(i) = '*';
                        tRules.groupdesc(i) = '*';
                        tRules.scheduledesc(i) = '*';
                        tRules.scenedesc(i) = '*';
                    end
                end

                tRsorted = sortrows(tRules,{'timestriggered','lasttriggered','created'},{'descend', 'descend','descend'});
                app.RulesTable.Data = tRsorted(:,1:12);
                app.ONRules.Value = rulesEnabled;
                app.NeverTriggered.Value = rulesNotTrig;
                app.RulesLabel_2.Text = "Rules";
                app.RulesLabel_2.FontSize = 36;
                app.inTimesTriggeredOrderLabel.Visible = "on";
                app.NumberofRulesNOTtriggeredsincethelastBridgefirmwareupdateLabel.Visible = "on";
                app.NeverTriggered.Visible = "on";

                % Only save extracted Bridge data at startup
                if app.startup
                    app.startup = false;
                    saveAllData (app, [], allLights, allRules, [], allSensors, [], [], [], allGroups, ...
                        allSchedules, allCapabilities, allConnectivity, allResourceLinks, ...
                        allScenes, [], config)
                else
                    dt = datetime('now', 'Format', 'dd-MMM-yyyy HH:mm:ss');
                    strDt = string(dt);
                    app.LastBridgeDataRefreshTextArea.Value = strDt;
                end
            else

                %%% >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
                %%% >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
                %%% >>> Bridge Pro
                %%% >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
                %%% >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>

                app.hueIPAddressPro = app.lastHueIP;
                app.appKey = app.appID; % keep them separate
                if ~ app.offlineMode
                    config = getBridgeConfigPro(app, app.lastHueIP);
                    if ispc
                        save([app.dataRepository '\config.mat'],"config");
                    else
                        save([app.dataRepository '/config.mat'],"config");
                    end
                end
                app.BridgeID.Value = config.bridge_id;
                app.SWversion.Value = config.sw_version;
                app.ZigbeeChannel.Value = str2double(config.zigbee_channel);
                app.AVLights.Value = size(allLights, 1);
                app.BRLights.Value = 186;
                app.PCLights.Value = app.AVLights.Value * 100 / 186;
                states = cellfun(@(x) x.on.on, allLights);
                app.ONLights.Value = sum(states);
                app.AVSensors.Value = size(allSensors,1);
                app.BRSensors.Value = 0;
                app.PCSensors.Value = 0;
                app.AVGroups.Value = size(allGroups,1);
                app.OnGroups.Value = 0;
                app.BRGroups.Value = 0;
                app.PCGroups.Value = 0;
                app.AVScenes.Value = 0;
                app.BRScenes.Value = 0;
                app.PCScenes.Value = 0;
                app.AVSchedules.Value = 0;
                app.BRSchedules.Value = 0;
                app.ONSchedules.Value = 0;
                app.PCSchedules.Value = 0;
                app.AVRules.Value = 0;
                app.BRRules.Value = 0;
                app.ONRules.Value = 0;
                app.PCRules.Value = 0;
                app.AVResourceLinks.Value = 0;
                app.BRResourceLinks.Value = 0;
                app.PCResourceLinks.Value = 0;

                %%% Hide Bridge Pro stats that are (currently) not
                %%% disclosed via v2 API
                app.BRSensors.Visible = "off";
                app.PCSensors.Visible = "off";
                app.BRGroups.Visible = "off";
                app.PCGroups.Visible = "off";
                app.BRRules.Visible = "off";
                app.PCRules.Visible = "off";
                app.BRScenes.Visible = "off";
                app.PCScenes.Visible = "off";
                app.BRSchedules.Visible = "off";
                app.PCSchedules.Visible = "off";
                app.BRResourceLinks.Visible = "off";
                app.PCResourceLinks.Visible = "off";
                %%%

                noSensorsTemp = size(allSensorTemps,1);
                noSensorsMotion = size(allSensorMotions,1);
                noPowerStates = size(allPowerStates,1);
                noDevices = size(allDevices,1);
                noConnections = size(allConnectivity,1);
                noGroups = size(allGroups,1);


                %%%%
                %%%%  Sensors Bridge Pro
                %%%%

                noSensors = noSensorsTemp + noSensorsMotion;
                noDimmers = 0;

                for i = 1:noDevices
                    if contains(allDevices{i}.product_data.product_name, 'dimmer','IgnoreCase',true)
                        noDimmers = noDimmers + 1;
                    end
                end

                % Create a standard format table of Sensors
                sz = [noSensors 9];
                varTypes = ["double", "string", "string", "string", "string", "double", "double","datetime","string"];
                varNames = ["sensorno", "sensorname", "modelID", "productname", "type", "battery", "temp","lastevent","uniqueid"];
                tSensors = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                % tSensors.sensorno = str2double(extractAfter(fieldX, 'x'));
                lowBattery = 0;
                maxTemp = -99;
                maxRoom = [];
                minTemp = 99;
                minRoom = [];
                warm = 0;
                veryWarm = 0;
                cool = 0;
                cold = 0;
                freezing = 0;
                tgt = 0;
                noTemps = 0;

                app.MotionSensors.Text = num2str(noSensorsMotion);
                if noSensorsMotion == 1
                    app.MotionSensorsLabel.Text = 'Motion Sensor';
                else
                    app.MotionSensorsLabel.Text = 'Motion Sensors';
                end
                app.DimmerSwitches.Text = num2str(noDimmers);
                if noDimmers == 1
                    app.SwitchesLabel.Text = 'Switch';
                else
                    app.SwitchesLabel.Text = 'Switches';
                end
                app.TotalDevices.Text =num2str(noSensorsMotion + noDimmers);
                sz = [noSensors 4];
                varTypes = ["string", "double", "string","datetime"];
                varNames = ["temp", "battery", "sensorname","lastevent"];
                tTemps = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);

                tSensors.lastevent.TimeZone = 'UTC';
                tTemps.lastevent.TimeZone = 'UTC';

                for i = 1:noSensorsTemp
                    for d = 1:noDevices
                        if allDevices{d}.id == allSensorTemps{i}.owner.rid
                            tSensors.productname(i) = allDevices{d}.product_data.product_name;
                            tSensors.modelID(i) = allDevices{d}.product_data.model_id;
                            tSensors.sensorname(i) = allDevices{d}.metadata.name;
                            break;
                        end
                    end
                    tSensors.uniqueid(i) = allSensorTemps{i}.owner.rid;
                    for p = 1:noPowerStates
                        if allPowerStates{p}.owner.rid == allSensorTemps{i}.owner.rid
                            tSensors.battery(i) =  allPowerStates{p}.power_state.battery_level;
                            if tSensors.battery(i) < 30
                                lowBattery = lowBattery + 1;
                            end
                            break;
                        end
                    end
                    tSensors.lastevent(i) = datetime( ...
                        allSensorTemps{i}.temperature.temperature_report.changed, ...
                        'InputFormat','yyyy-MM-dd''T''HH:mm:ss.SSS''Z''', ...
                        'TimeZone','UTC', ...
                        'Format','dd/MM/yy HH:mm:ss');
                    tSensors.type(i) =  allSensorTemps{i}.type;
                    tSensors.temp(i) = allSensorTemps{i}.temperature.temperature;
                    noTemps = noTemps + 1;
                    if tSensors.temp(i) > maxTemp
                        maxTemp = tSensors.temp(i);
                        maxRoom = extractAfter(tSensors.sensorname(i),'Sensor ');
                    end
                    if tSensors.temp(i) < minTemp
                        minTemp = tSensors.temp(i);
                        minRoom = extractAfter(tSensors.sensorname(i),'Sensor ');
                    end
                    if tSensors.temp(i) > 30
                        veryWarm = veryWarm + 1;
                    elseif tSensors.temp(i) > 21
                        warm = warm + 1;
                    elseif tSensors.temp(i) < 0
                        freezing = freezing + 1;
                    elseif tSensors.temp(i) < 10
                        cold = cold + 1;
                    elseif tSensors.temp(i) < 18
                        cool = cool + 1;
                    else
                        tgt = tgt + 1;
                    end
                end

                app.WASensors.Value = warm;
                app.HOSensors.Value = veryWarm;
                app.CLSensors.Value = cool;
                app.COSensors.Value = cold;
                app.FRSensors.Value = freezing;
                app.CMSensors.Value = tgt;
                if app.prevMaxTemp == -999
                    app.prevMaxTemp = maxTemp;
                end
                if app.prevMinTemp == 999
                    app.prevMinTemp = minTemp;
                end
                if maxTemp > app.prevMaxTemp
                    maxTempText = append('<font color="yellow">Max: </font>', maxRoom,...
                        '<font color="yellow">',' RISING',' </font>', ' to ', num2str(maxTemp,'%+2.2f'));
                    app.prevMaxTemp = maxTemp;
                elseif maxTemp < app.prevMaxTemp
                    maxTempText = append('<font color="yellow">Max: </font>', maxRoom, ...
                        '<font color="cyan">',' DROPPING',' </font>', ' to ', num2str(maxTemp,'%+2.2f'));
                    app.prevMaxTemp = maxTemp;
                else
                    maxTempText = append('<font color="yellow">Max: </font>', maxRoom, ...
                        '<font color="grey">',' STABLE',' </font>', ' at ', num2str(maxTemp,'%+2.2f'));
                end

                if minTemp > app.prevMinTemp
                    minTempText = append('<font color="yellow">Min: </font>', minRoom, ...
                        '<font color="yellow">',' RISING',' </font>', ' to ', num2str(minTemp,'%+2.2f'));
                    app.prevMinTemp = minTemp;
                elseif minTemp < app.prevMinTemp
                    minTempText = append('<font color="yellow">Min: </font>', minRoom, ...
                        '<font color="cyan">',' DROPPING',' </font>', ' to ', num2str(minTemp,'%+2.2f'));
                    app.prevMinTemp = minTemp;
                else
                    minTempText = append('<font color="yellow">Min: </font>', minRoom, ...
                        '<font color="grey">',' STABLE',' </font>', ' at ', num2str(minTemp,'%+2.2f'));
                end

                app.SensorTemperaturesPanel.Title = append('Sensor Temperatures           Max ',...
                    num2str(maxTemp,'%+2.2f'), ' <-> Min ', num2str(minTemp,'%+2.2f'));

                j = i;
                for i = 1:noSensorsMotion
                    j = j +1;
                    for d = 1:noDevices
                        if allDevices{d}.id == allSensorMotions{i}.owner.rid
                            tSensors.productname(j) = allDevices{d}.product_data.product_name;
                            tSensors.modelID(j) = allDevices{d}.product_data.model_id;
                            tSensors.sensorname(j) = allDevices{d}.metadata.name;
                            break;
                        end
                    end
                    tSensors.uniqueid(j) = allSensorMotions{i}.owner.rid;
                    tSensors.lastevent(j) = datetime( ...
                        allSensorMotions{i}.motion.motion_report.changed, ...
                        'InputFormat','yyyy-MM-dd''T''HH:mm:ss.SSS''Z''', ...
                        'TimeZone','UTC', ...
                        'Format','dd/MM/yy HH:mm:ss');
                    for p = 1:noPowerStates
                        if allPowerStates{p}.owner.rid == allSensorMotions{i}.owner.rid
                            tSensors.battery(j) =  allPowerStates{p}.power_state.battery_level;
                            if tSensors.battery(j) < 30
                                lowBattery = lowBattery + 1;
                            end
                            break;
                        end
                    end
                    tSensors.type(j) = allSensorMotions{i}.type;
                end
                lastMotionIdx = 0;
                j = 0;
                for i = 1:noSensors
                    if  contains(tSensors.type(i) ,"temperature")
                        j = j + 1;
                        tTemps.temp(j) = num2str(tSensors.temp(i),'%+2.2f');
                        tTemps.battery(j) = tSensors.battery(i);
                        tTemps.sensorname(j) = strcat(tSensors.sensorname(i), '/', tSensors.type(i), ' (', tSensors.modelID(i),')');
                        tTemps.lastevent(j) = tSensors.lastevent(i);
                    elseif contains(tSensors.type(i) ,"motion")
                        j = j+ 1;
                        tTemps.temp(j) = ' ';
                        tTemps.battery(j) = tSensors.battery(i);
                        tTemps.sensorname(j) = strcat(tSensors.sensorname(i), '/', tSensors.type(i), ' (', tSensors.modelID(i),')');
                        tTemps.lastevent(j) = tSensors.lastevent(i);
                        if lastMotionIdx == 0 || tSensors.lastevent(i) > lastMotion
                            lastMotion = tSensors.lastevent(i);
                            lastMotionIdx = i;
                        end
                    end
                end

                if lastMotionIdx > 0
                    app.sensorNameLastMovement = tSensors.sensorname(lastMotionIdx);
                    motionDate = extractBefore(string(lastMotion), " ");
                    motionTime = extractAfter(string(lastMotion), " ");
                    lastMotionText  = append('Last Motion detected by ', tSensors.sensorname(lastMotionIdx),...
                        newline, 'on ', motionDate, ' at ', motionTime);
                    if strlength(tSensors.sensorname(lastMotionIdx)) > 25
                        lastMotionFontsize = '16px';
                    else
                        lastMotionFontsize = '18px';
                    end
                    lastMotionFontcol = 'cyan';
                    lastMotionFontweight = 'bold';
                else
                    lastMotionText = 'No Motions detected.';
                    lastMotionFontsize = 14;
                    lastMotionFontcol = 'yellow';
                    lastMotionFontweight= 'normal';
                end
                app.HTMLLastMotion.Data = struct( ...
                    'message', lastMotionText, ...
                    'color', lastMotionFontcol, ...
                    'fontsize', lastMotionFontsize, ...
                    'fontweight', lastMotionFontweight, ...
                    'lineheight', '0.75', ...
                    'width', '385px', ...
                    'height', '37px', ...
                    'speed', '10s');

                tTemps = sortrows(tTemps,{'temp','sensorname'},{'descend', 'ascend'});
                app.TempsTable.Data = tTemps;

                app.SensorsandSwitchesLabel.Text = '<html><p style="line-height:0.75;">Sensors and<br>Switches</p></html>';


                if lowBattery > 0
                    sz = [lowBattery 3];
                    varTypes = ["double", "string", "string"];
                    varNames = ["battery", "modelID", "sensorname"];
                    tLowBat = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                    j = 0;
                    for i = 1:noSensors
                        if tSensors.battery(j) < 30
                            j = j + 1;
                            tLowBat.battery(j) = tSensors.battery(j);
                            tLowBat.modelID(j) = tSensors.modelID(j);
                            tLowBat.sensorname(j) = tSensors.sensorname(j);
                        end

                    end
                    app.TableSensors.Data = sortrows(tLowBat);
                    s1 = uistyle('BackgroundColor','red');
                    s2 = uistyle;
                    s2.FontColor = 'black';
                    addStyle(app.TableSensors,s1,'column',1);
                    addStyle(app.TableSensors,s2,'column',1);
                    app.TableSensors.Visible = "on";
                    app.HTMLLowBatMsg.Visible = "on";
                    app.Sunset.Visible ="off";
                    % Fast blink (1 second per cycle)
                    app.HTMLLowBatDevs.Data = struct( ...
                        'message', num2str(lowBattery), ...
                        'color', 'red', ...
                        'fontfamily', 'Arial Black', ...
                        'fontsize', '16px', ...
                        'fontweight', 'bold', ...
                        'lineheight', '0', ...
                        'width', '45px', ...
                        'height', '25px', ...
                        'speed', '1s');
                    % pulseText.html..(medium cycle)
                    app.HTMLLowBatMsg.Data = struct( ...
                        'message', '⚠ Devices with a Battery Level below 30% ⚠', ...
                        'speed', '5s');
                else
                    % No blinking
                    app.HTMLLowBatDevs.Data = struct( ...
                        'message', 'none', ...
                        'color', 'white', ...
                        'fontfamily', 'Calibri', ...
                        'fontsize', '14px', ...
                        'fontweight', 'normal', ...
                        'lineheight', '0', ...
                        'width', '45px', ...
                        'height', '25px', ...
                        'speed', '0s');
                    % pulseText.html..(very slow cycle)
                    app.HTMLLowBatMsg.Data = struct( ...
                        'message', '✔ All devices have a Battery Level above 30% ✔', ...
                        'color', 'green', ...
                        'speed', '10s');
                    app.TableSensors.Visible = "off";
                    app.HTMLLowBatMsg.Visible = "on";
                    app.Sunset.Visible ="on";
                    app.Sunset.Text = append("Sunset ", extractBefore(app.sunset,":00"));
                end

                % >>> Assign to UI
                tTemps = sortrows(tTemps,{'temp','sensorname'},{'descend', 'ascend'});
                app.TempsTable.Data = tTemps;

                %%%%
                %%%% Lights Bridge Pro
                %%%%

                noLights = size(allLights,1);

                % Create a standard format table of Lights
                sz = [noLights 16];
                varTypes = ["string", "string", "double","string","string","string","logical","double","string", ...
                    "string","double", "string", "double", "double","logical","string"];
                varNames = ["groupname", "grouptype", "lightno","lightname","productname","model","on","bright","colour", ...
                    "uniqueid","groupno", "type", "x", "y",  "reachable","colormode"];
                app.tLights = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                lightsReachable = 0;
                app.lightsOn = 0;

                for i = 1:noLights
                    app.tLights.lightno(i) = i;
                    for d = 1:noDevices
                        if allDevices{d}.id == allLights{i}.owner.rid
                            app.tLights.model(i) = allDevices{d}.product_data.model_id;
                            app.tLights.productname(i) = allDevices{d}.product_data.product_name;
                            app.tLights.type(i) = allDevices{d}.product_data.product_archetype;
                            break;
                        end
                    end

                    for g = 1:noGroups
                        noChildren = numel(allGroups{g}.children);
                        for gc = 1:noChildren
                            if allGroups{g}.children(gc).rid == allLights{i}.owner.rid
                                app.tLights.groupno(i) = g;
                                app.tLights.groupname(i) = allGroups{g}.metadata.name;
                                app.tLights.grouptype(i) = allGroups{g}.type;
                                break;
                            end
                        end
                    end

                    app.tLights.lightname(i) = allLights{i}.metadata.name;

                    % app.tLights.reachable(i) = get connectivity..
                    app.tLights.uniqueid(i) = allLights{i}.id;
                    app.tLights.bright(i) = round(allLights{i}.dimming.brightness,0);

                    try % xy colours..
                        app.tLights.x(i) = allLights{i}.color.xy.x;
                        app.tLights.y(i) = allLights{i}.color.xy.y;
                        app.tLights.colormode(i) = "xy";
                    catch % not a colour bulb so default to cool white
                        app.tLights.colormode(i) = "white";
                        app.tLights.x(i) = 0.3127;
                        app.tLights.y(i) = 0.3290;
                    end

                    for c = 1:noConnections
                        if  allConnectivity{c}.owner.rid == allLights{i}.owner.rid
                            if allConnectivity{c}.status == "connected"
                                lightsReachable = lightsReachable + 1;
                                app.tLights.reachable(i) = true;
                            else
                                app.tLights.reachable(i) = false;
                            end
                            break;
                        end
                    end
                    app.tLights.on(i) = allLights{i}.on.on;
                    if app.tLights.on(i)
                        app.lightsOn = app.lightsOn + 1;
                    end
                end

                unreachable = noLights - lightsReachable;

                app.ONLights.Value = app.lightsOn;
                if app.lightsOn == 1
                    app.LightsOnLabel.Text = 'Light';
                else
                    app.LightsOnLabel.Text = 'Lights';
                end
                blinkLightsOn(app);

                if unreachable > 0
                    sz = [unreachable 2];
                    varTypes = ["string", "string"];
                    varNames = ["ID", "lightname"];
                    tUnreachable = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                    j = 0;
                    for i = 1:noLights
                        if ~ app.tLights.reachable(i)
                            j = j + 1;
                            tUnreachable.ID(j) = app.tLights.model(i);
                            tUnreachable.lightname(j) =  app.tLights.lightname(i);
                        end
                    end

                    app.TableLights.Data = tUnreachable;
                    s1 = uistyle('BackgroundColor','white');
                    s2 = uistyle;
                    s2.FontColor = 'black';
                    s2.FontWeight = 'bold';
                    addStyle(app.TableLights,s1,'column',1);
                    addStyle(app.TableLights,s2,'column',1);
                    app.TableLights.Visible = "on";
                    app.HTMLTempTrend.Visible = "on";
                    app.UnreachableLightsLabel.Interpreter = 'none';
                    app.UnreachableLightsLabel.Tooltip =  'This section is only presented when there ARE Unreachable Lights.';
                    % Fast blink (1 second per cycle)
                    app.HTMLUnreachableLights.Data = struct( ...
                        'message', num2str(unreachable), ...
                        'color', 'red', ...
                        'fontfamily', 'Arial Black', ...
                        'fontsize', '16px', ...
                        'fontweight', 'bold', ...
                        'lineheight', '0', ...
                        'width', '45px', ...
                        'height', '25px', ...
                        'speed', '1s');
                    % Fast blink (1 second per cycle)
                    app.HTMLTempTrend.Data = struct( ...
                        'message', '⚠ Unreachable Lights ⚠', ...
                        'color', 'red', ...
                        'fontsize', '16px', ...
                        'fontweight', 'bold', ...
                        'lineheight', '0', ...
                        'width', '319px', ...
                        'height', '28px', ...
                        'speed', '1s');
                else
                    app.TableLights.Visible = "off";
                    app.HTMLTempTrend.Visible = "on";
                    app.UnreachableLightsLabel.FontColor = "white";
                    app.UnreachableLightsLabel.Tooltip = 'This section is only presented when there are NO Unreachable Lights.';
                    app.UnreachableLightsLabel.BackgroundColor = "none";
                    app.UnreachableLightsLabel.FontWeight = "bold";
                    app.UnreachableLightsLabel.FontSize = 16;
                    app.UnreachableLightsLabel.HorizontalAlignment = 'left';
                    app.UnreachableLightsLabel.Interpreter = 'html';
                    app.UnreachableLightsLabel.Text  = ...
                        append('<html><p style="line-height:1;"><font color="white"><b><br>', ...
                        maxTempText, '<br><br>', ...
                        minTempText, '</b></font></p>');
                    % No blinking
                    app.HTMLUnreachableLights.Data = struct( ...
                        'message', 'none', ...
                        'color', 'white', ...
                        'fontfamily', 'Calibri', ...
                        'fontsize', '14px', ...
                        'fontweight', 'normal', ...
                        'lineheight', '0', ...
                        'width', '45px', ...
                        'height', '25px', ...
                        'speed', '0s');
                    % Very slow blink (10 seconds per cycle)
                    app.HTMLTempTrend.Data = struct( ...
                        'message', '℃ Temperature trends since last Refresh ℃', ...
                        'color', 'white', ...
                        'fontsize', '16px', ...
                        'fontweight', 'bold', ...
                        'lineheight', '0', ...
                        'width', '319px', ...
                        'height', '28px', ...
                        'speed', '10s');
                end

                app.tLights = sortrows(app.tLights,[2 1],["descend" "descend"]);

                grplightsOn = 0;
                app.roomLightsOn = 0;
                prevGrpNo = 0;

                app.LightsTable.Data = app.LightsTable.Data([],:); % clear the table
                fmt = '%02d';

                for l = 1:noLights
                    if app.tLights.on(l)
                        if app.tLights.groupno(l) ~= prevGrpNo
                            grplightsOn = grplightsOn + 1;
                            prevGrpNo = app.tLights.groupno(l);
                        end
                    end
                    if app.tLights.colormode(l) ~= "white"
                        x = app.tLights.x(l);
                        y = app.tLights.y(l);
                        rgb = hueXYtoRGB(app,x,y,app.tLights.bright(l));
                        app.tLights.colour(l) = append({nearestCssColourName(app, rgb)}, ' [', num2str(round(x * 100,0),fmt), ',', num2str(round(y * 100,0),fmt),']');
                        s1 = uistyle('BackgroundColor',rgb);
                        s2 = uistyle;
                        addStyle(app.LightsTable,s1,'cell', [l 9]);
                        if sum(rgb) == 0
                            s2.FontColor = 'white';
                        else
                            s2.FontColor = 'black';
                        end
                        addStyle(app.LightsTable,s2,'cell',[l 8]);
                    else
                        app.tLights.colour(l) = 'white';
                        s1 = uistyle('BackgroundColor','white');
                        addStyle(app.LightsTable,s1,'cell',[l 9]);
                    end
                end

                if app.lightsOnRoomsOnly
                    j = 0;
                    for l = 1:noLights
                        if app.tLights.on(l) && contains(app.tLights.grouptype(l), "room","IgnoreCase",true);
                            j = j + 1;
                            app.LightsTable.Data(j,1:10) = app.tLights(l,1:10);
                        end
                    end
                else
                    app.LightsTable.Data = app.tLights(:,1:10);
                end

                app.OnGroups.Value = grplightsOn;
                updateONGroupsRooms(app);
                highlightOnLights(app);

                %%%
                %%% Schedules - Scripts Bridge Pro
                %%%

                noSchedules = size(allSchedules,1);
                app.AVSchedules.Value = noSchedules;
                noScenes = size(allScenes,1);
                app.AVScenes.Value = noScenes;
                noSchedsScenes = noSchedules + noScenes;
                noActiveSchedules = 0;

                sz = [noSchedsScenes 19];
                varTypes = ["double", "string", "string", "datetime", "datetime", "double", "string","string", ...
                    "string", "double", "string", "string", ...
                    "double", "string", ...
                    "double", "string", "double", "string", "string"];
                varNames = ["scheduleseqno", "location", "schedulename", "created", "lasttriggered", "timestriggered", "status", "action", ...
                    "target", "id", "description", "RL", ...
                    "groupno", "groupdesc", ...
                    "sensorno", "sensordesc","scheduleno", "scheduledesc","scenedesc"];
                tSchedules = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);

                for i = 1:noSchedules
                    tSchedules.scheduleseqno(i) = i;
                    tSchedules.action(i) = "Automation";  % default
                    try
                        tSchedules.location(i) = allSchedules{i}.configuration.where.group.rtype;
                    catch
                        tSchedules.location(i) = allSchedules{i}.state.model_id;
                    end
                    tSchedules.schedulename(i) = allSchedules{i}.metadata.name;
                    tSchedules.target(i) = '*';
                    tSchedules.description(i) = '*';
                    tSchedules.RL(i) = '*';
                    tSchedules.status(i) = allSchedules{i}.status;
                    if allSchedules{i}.status == "running"
                        noActiveSchedules = noActiveSchedules + 1;
                    end

                    try
                        room = allSchedules{i}.configuration.motion.where.group.rid;
                        for r = 1:noGroups
                            if allGroups{r}.id == room
                                tSchedules.location(i) = allGroups{r}.metadata.name;
                                break;
                            end
                        end
                    catch
                    end
                    try
                        device = allSchedules{i}.configuration.device.rid;
                        for d = 1:noDevices
                            if allDevices{d}.id == device
                                tSchedules.action(i) = append(allDevices{d}.product_data.model_id, ...
                                    '/', allDevices{d}.product_data.product_name);
                                break;
                            end
                        end
                    catch
                        try
                            match = false;
                            device = allSchedules{i}.configuration.motion.motion_service.rid;
                            for d = 1:noDevices
                                for s = 1:size(allDevices{d}.services,1)
                                    if allDevices{d}.services(s).rid == device
                                        tSchedules.action(i) = append(allDevices{d}.product_data.model_id, ...
                                            '/', allDevices{d}.product_data.product_name);
                                        match = true;
                                        break;
                                    end
                                end
                                if match
                                    break;
                                end
                            end
                        catch
                        end
                    end
                    try
                        match = false;
                        for d = 1:size(allSchedules{i}.dependees,1)
                            room = allSchedules{i}.dependees(d).target.rid;
                            for r = 1:noGroups
                                if allGroups{r}.id == room
                                    tSchedules.location(i) = allGroups{r}.metadata.name;
                                    match = true;
                                else
                                    for c = 1:size(allGroups{r}.children,1)
                                        if allGroups{r}.children(c).rid == room
                                            tSchedules.location(i) = allGroups{r}.metadata.name;
                                            match = true;
                                            break;
                                        end
                                    end
                                end
                                if match
                                    break;
                                end
                            end
                        end
                    catch
                    end
                    try % Start of schedule
                        noSlots = size(allSchedules{i}.configuration.motion.when.timeslots,1);
                        tSchedules.RL(i) = '';
                        for t = 1:noSlots
                            startHour = compose("%02d",allSchedules{i}.configuration.motion.when.timeslots(t).start_time.time.hour);
                            startMinute = compose("%02d",allSchedules{i}.configuration.motion.when.timeslots(t).start_time.time.minute);
                            tSchedules.RL(i) = append(tSchedules.RL(i),'Start(', num2str(t), ') @ ', startHour, ':', startMinute, ' ');
                        end
                        tSchedules.description(i) = allSchedules{i}.configuration.motion.when.timeslots(1).on_motion.recall_single.action;
                        tSchedules.id(i) = allSchedules{i}.configuration.motion.when.timeslots(1).on_no_motion.after.minutes;
                        tSchedules.target(i) = allSchedules{i}.configuration.motion.when.timeslots(1).on_no_motion.recall_single.action;
                    catch
                    end
                    try
                        startTime = allSchedules{i}.configuration.when_extended.start_at.time_point.type;
                        endTime = allSchedules{i}.configuration.when_extended.end_at.time_point.type;
                        tSchedules.RL(i) = append(startTime,'-', endTime);
                    catch
                    end

                end

                %%%
                %%% Scenes
                %%%

                for i = 1:noScenes
                    seqNo = noSchedules + i;
                    tSchedules.scheduleseqno(seqNo) = seqNo;
                    tSchedules.target(seqNo) = '*';
                    tSchedules.RL(seqNo) = '*';
                    tSchedules.schedulename(seqNo) = append(allScenes{i}.type , ': ', allScenes{i}.metadata.name);
                    tSchedules.status(seqNo) = allScenes{i}.status.active;
                    noActs = size(allScenes{i}.actions,1);
                    tSchedules.action(seqNo) = append(allScenes{i}.actions(1).target.rtype,' x ', num2str(noActs), ':');

                    for a = 1:noActs
                        for l = 1:noLights
                            if  allScenes{i}.actions(a).target.rid == allLights{l}.id
                                tSchedules.action(seqNo) = append (tSchedules.action(seqNo), ' (', num2str(a), ')', allLights{l}.metadata.name);
                                break;
                            end
                        end
                    end

                    if allScenes{i}.actions(1).action.on.on
                        tSchedules.description(seqNo) = append('ON - Brigntness = ', ...
                            num2str(allScenes{i}.actions(1).action.dimming.brightness));
                    end
                    if allScenes{i}.group.rtype == "room"
                        for j = 1:noGroups
                            if allGroups{j}.id == allScenes{i}.group.rid
                                tSchedules.location(seqNo) = allGroups{j}.metadata.name;
                                break
                            end
                        end
                    end
                end


                app.RulesTable.Data = app.RulesTable.Data([],:); % clear the table
                app.RulesTable.Data = tSchedules(:,1:12);
                app.RulesLabel_2.Text = "Schedules & Scenes";
                app.ONSchedules.Value = noActiveSchedules;
                % app.RulesLabel_2.FontSize = 12;
                app.inTimesTriggeredOrderLabel.Visible = "off";
                app.NumberofRulesNOTtriggeredsincethelastBridgefirmwareupdateLabel.Visible = "off";
                app.NeverTriggered.Visible = "off";
                app.NeverTriggered.Value = 0;

            end
            app.Pulse.setSpeed(10);
            app.Response.Value = toc;
        end

        %%%
        %%% Toggle Lights on/off - if On then switch Off & vice versa
        %%%

        function onOff = toggleLights(app, lightID, index, singleLight)
            if app.vAPI == 1
                onOff = toggleLightsV1(app, lightID, index, singleLight);
            else % API v2
                %% need to set to lightID to app.tLights.uniqueid(x)
                for i = 1:numel(app.LightsTable.Data)
                    if app.LightsTable.Data{i,3} == lightID
                        lightUUID = app.LightsTable.Data{i,10};
                        onOff = toggleLightsV2(app, lightUUID, index, singleLight);
                        app.LightsTable.Data{i,7} = onOff;
                        break;
                    end
                end
            end
        end

        function onOff = toggleLightsV1(app, numLight, index, singleLight)
            options = weboptions('MediaType', 'application/json', 'RequestMethod', 'put');
            wrURL = sprintf('http://%s/api/%s/lights/%d/state', app.hueIPAddressV2,app.appID,numLight);
            if app.LightsTable.Data{index,2} == "Room" || singleLight
                if app.LightsTable.Data{index,7} == 1
                    state = struct('on', false);
                    app.lightsOn = app.lightsOn - 1;
                else
                    state = struct('on',true);
                    app.lightsOn = app.lightsOn + 1;
                end
                webwrite(wrURL, state, options);
                app.ONLights.Value = app.lightsOn;
                if app.lightsOn == 1
                    app.LightsOnLabel.Text = 'Light';
                else
                    app.LightsOnLabel.Text = 'Lights';
                end
                blinkLightsOn(app);
            end
            if app.LightsTable.Data{index,7} == 1
                app.LightsTable.Data{index,7} = 0;
                onOff = 0;
            else
                app.LightsTable.Data{index,7} = 1;
                onOff = 1;
            end
        end


        function onOff = toggleLightsV2(app, lightUUID, index, singleLight)

            if contains(app.LightsTable.Data{index,2}, "Room","IgnoreCase",true ) || singleLight
                isOn = app.LightsTable.Data{index,7} == 1;
                if isOn
                    body = struct('on', struct('on', false));
                    app.lightsOn = app.lightsOn - 1;
                else
                    body = struct('on', struct('on', true));
                    app.lightsOn = app.lightsOn + 1;
                end

                path = "/clip/v2/resource/light/" + lightUUID;
                method = 'PUT';
                hueRequest(app, app.hueIPAddressPro, app.appKey, method, path, body);

                app.ONLights.Value = app.lightsOn;
                if app.lightsOn == 1
                    app.LightsOnLabel.Text = 'Light';
                else
                    app.LightsOnLabel.Text = 'Lights';
                end
                blinkLightsOn(app);
            end
            if app.LightsTable.Data{index,7} == 1
                app.LightsTable.Data{index,7} = 0;
                onOff = 0;
            else
                app.LightsTable.Data{index,7} = 1;
                onOff = 1;
            end
        end


        %%%
        %%% Apply change to individual Light Brightness
        %%%

        function updBrightness(app, numLight)
            if app.vAPI == 1
                updBrightnessv1(app, numLight)
            else % API v2
                %% need to set to lightID to app.tLights.uniqueid(x)
                for i = 1:numel(app.LightsTable.Data)
                    if app.LightsTable.Data{i,3} == numLight
                        lightUUID = app.LightsTable.Data{i,10};
                        updBrightnessv2(app, lightUUID)
                        break;
                    end
                end
            end
        end

        function updBrightnessv1(app, numLight)
            options = weboptions('MediaType', 'application/json', 'RequestMethod', 'put');
            wrURL = sprintf('http://%s/api/%s/lights/%d/state', app.hueIPAddressV2,app.appID,numLight);
            nbri = max(round(app.newBri * 2.54),1);
            state = struct('bri', nbri);
            webwrite(wrURL, state, options);

        end

        function updBrightnessv2(app, lightUUID)
            % Convert v1 bri (0–254) → v2 brightness (0–100)
            briPct = round(app.newBri,0);

            % API v2 brightness payload
            body = struct( ...
                'dimming', struct('brightness', briPct) ...
                );

            path = "/clip/v2/resource/light/" + lightUUID;
            method = 'PUT';
            hueRequest(app, app.hueIPAddressPro, app.appKey, method, path, body);
        end

        function highlightOnLights(app)
            noLights = size(app.LightsTable.Data);
            for i = 1:noLights(1,1)
                if app.LightsTable.Data{i,7} == 1
                    s1 = uistyle('BackgroundColor','white');
                else
                    s1 = uistyle('BackgroundColor','black');
                end
                addStyle(app.LightsTable,s1,'cell', [i 7]);
            end
        end

        %%%
        %%% Process Brightness change for Groups of Lights
        %%%

        function groupBrightness(app, noLights)
            selGrp = app.LightsTable.Data{app.cellSelIndices(1,1),1};
            for i = 1:noLights(1,1)
                if app.LightsTable.Data{i,1} == selGrp
                    numLight = app.LightsTable.Data{i,3};
                    for j = 1:noLights(1,1)
                        if app.LightsTable.Data{j,3} == numLight ...
                                && app.LightsTable.Data{j,8} ~= app.newBri
                            updBrightness (app, numLight);
                            for k = 1:noLights(1,1) % update all brightness table entries for this light
                                if app.LightsTable.Data{k,3} == numLight
                                    app.LightsTable.Data{k,8} = app.newBri;
                                end
                            end
                            break
                        end
                    end
                end
            end
        end

        %%%
        %%%  Process changes to Light Brightness
        %%%

        function results = newBrightness(app, event)
            indices = event.Indices;
            try
                if event.NewData == event.PreviousData
                    results = "Brightness edited but not changed.";
                    return;
                end
                newData = event.NewData;
                if newData > 100
                    newData = 100;
                elseif newData < 1
                    newData = 1;
                end
            catch EM
                results = "Brightness selected.";
                return;
            end
            app.newBri = newData;
            noLights = size(app.LightsTable.Data);
            if app.cellSelIndices(1,2) == 1 % Group selection previously processed
                groupBrightness (app, noLights);
                results = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),1}, " lights brightness changed.");
            elseif indices(1,2) == 8 % Single Brightness Changed
                numLight = app.LightsTable.Data{indices(1,1),3};
                updBrightness (app, numLight);
                for j = 1:noLights(1,1)
                    if app.LightsTable.Data{j,3} == numLight
                        app.LightsTable.Data{j,8} = newData;
                    end
                end
                results = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),4}, " light brightness changed.");
            end
            app.cellSelIndices = event.Indices;
        end

        %%%
        %%% Update Light Colour
        %%%

        function updColour(app, numLight)
            if app.vAPI == 1
                updColourv1(app, numLight)
            else % API v2
                for i = 1:numel(app.LightsTable.Data)
                    if app.LightsTable.Data{i,3} == numLight
                        lightUUID = app.LightsTable.Data{i,10};
                        updColourv2(app, lightUUID, numLight)
                        break;
                    end
                end
            end
        end

        function updColourv1(app, numLight) % API v1
            options = weboptions('MediaType', 'application/json', 'RequestMethod', 'put');
            wrURL = sprintf('http://%s/api/%s/lights/%d/state', app.hueIPAddressV2,app.appID,numLight);
            state = struct('xy', app.xyColour);
            webwrite(wrURL, state, options);
            fmt = '%02d';
            lightsTableSize = size(app.LightsTable.Data);
            x = app.xyColour(1,1);
            y = app.xyColour(1,2);
            app.colBri = max(round(app.colBri * 2.54),1);
            rgb = hueXYtoRGB(app,x,y,app.colBri);
            colourName = {nearestCssColourName(app, rgb)};
            s1 = uistyle('BackgroundColor',rgb);
            s2 = uistyle;
            for i=1:lightsTableSize(1,1)
                if app.LightsTable.Data{i,3} == numLight
                    app.LightsTable.Data{i,9} = append(colourName, ' [', num2str(round(x * 100,0),fmt), ',', num2str(round(y * 100,0),fmt),']');
                    addStyle(app.LightsTable,s1,'cell',[i,9]);
                    if sum(rgb) == 0
                        s2.FontColor = 'white';
                    else
                        s2.FontColor = 'black';
                    end
                    addStyle(app.LightsTable,s2,'cell',[i 9]);
                end
            end
        end


        function updColourv2(app, lightUUID, numLight) % API v2
            x = app.xyColour(1,1);
            y = app.xyColour(1,2);

            % v1 bri (0–254) → v2 brightness (0–100)
            briPct = round(app.colBri,0);
            % fprintf('\nBrightness: %d\n', briPct);

            body = struct( ...
                'color', struct('xy', struct('x', x, 'y', y)), ...
                'dimming', struct('brightness', briPct) ...
                );

            path = "/clip/v2/resource/light/" + lightUUID;
            method = 'PUT';
            hueRequest(app, app.hueIPAddressPro, app.appKey, method, path, body);

            lightsTableSize = size(app.LightsTable.Data);

            rgb = hueXYtoRGB(app, x, y, briPct);
            colourName = {nearestCssColourName(app, rgb)};
            s1 = uistyle('BackgroundColor', rgb);
            s2 = uistyle;
            fmt = '%02d';
            for i = 1:lightsTableSize(1)
                if app.LightsTable.Data{i,3} == numLight
                    app.LightsTable.Data{i,9} = append(colourName, ' [', num2str(round(x * 100,0),fmt), ',', num2str(round(y * 100,0),fmt),']');
                    addStyle(app.LightsTable, s1, 'cell', [i,9]);

                    if sum(rgb) == 0
                        s2.FontColor = 'white';
                    else
                        s2.FontColor = 'black';
                    end

                    addStyle(app.LightsTable, s2, 'cell', [i,9]);
                end
            end
        end


        function coloursChanged = groupColour (app, noLights)
            selGrp = app.LightsTable.Data{app.cellSelIndices(1,1),1};
            coloursChanged = 0;
            for i = 1:noLights(1,1)
                if app.LightsTable.Data{i,1} == selGrp
                    if contains(app.LightsTable.Data{i,6},'c','IgnoreCase',true) || ...
                            contains(app.LightsTable.Data{i,5},'col','IgnoreCase',true)
                        coloursChanged = coloursChanged + 1;
                        numLight = app.LightsTable.Data{i,3};
                        updColour(app, numLight);
                    end
                end
            end
        end

        function results = newColour(app, event)
            indices = event.Indices;
            noLights = size(app.LightsTable.Data);
            if app.cellSelIndices(1,2) == 1 % Group selection previously processed
                coloursChanged = groupColour(app, noLights);
                if coloursChanged > 0
                    results = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),1}, " lights colour set to ", app.newColr,".");
                else
                    app.LightsTable.Data{event.Indices(1,1),9} = event.PreviousData; % restore previous colour name
                    results = "No colour lights found in selected Group.  Change ignored,";
                end
            elseif indices(1,2) == 9 % Single Colour Changed
                if contains(app.LightsTable.Data{indices(1,1),6},'c','IgnoreCase',true) || ...
                        contains(app.LightsTable.Data{indices(1,1),5},'col','IgnoreCase',true)
                    numLight = app.LightsTable.Data{indices(1,1),3};
                    updColour(app, numLight);
                    results = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),4}, " light colour set to ", app.newColr, ".");
                else
                    app.LightsTable.Data{event.Indices(1,1),9} = event.PreviousData; % restore previous colour name
                    results = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),4}, " is not a colour light.  Change ignored.");
                end
            end
            app.cellSelIndices = event.Indices;
        end

        %%%
        %%% Connect to Hue Bridge
        %%%

        function [errCode, hueIPAddress, appID, allDevices, allLights,   ...
                allRules, allScripts, allSensors, allSensorTemps, allSensorMotions, allSwitches, allPowerStates, ...
                allGroups, allSchedules, allCapabilities, allConnectivity,...
                allResourceLinks, allScenes, config] = ...
                connectToBridge(app)

            % Initialise all returned data items

            appID = '';
            allLights = [];
            allRules = [];
            allScripts = [];
            allSensors = [];
            allGroups = [];
            allSchedules = [];
            allCapabilities = [];
            allResourceLinks = [];
            allScenes = [];
            config = [];
            allDevices = [];
            allSensorTemps = [];
            allSensorMotions = [];
            allSwitches = [];
            allPowerStates = [];
            allConnectivity = [];

            % fprintf('Looking up IP address...');
            [errCode, hueIPAddress] = getBridgeIP(app);

            if errCode ~= 0
                return
            end

            % whos hueIPAddress
            % fprintf('; Hue IP = %s\n',hueIPAddress);

            if ispc
                app.fName = [app.dataRepository '\userNameHue.mat'];
            else
                app.fName = [app.dataRepository '/userNameHue.mat'];
            end

            if app.vAPI == 1

                % fprintf('\nChecking if already registered to the Hue Bridge...')
                hueUser = [];
                try
                    load(app.fName);  % userNameHue
                    appID     = hueUser;
                    url        = sprintf('http://%s/api/%s/', hueIPAddress,appID);
                    deviceInfo = webread(url); % check if authorised
                    notInBridgeWhitelist = isfield(deviceInfo,'error');
                catch MSG
                    % Cater for lack of internet..
                    if contains(MSG.message,'not connect to server')
                        errCode = 999;
                        warning('No internet connection.');
                        return;
                    end
                    notInBridgeWhitelist = true;
                end

                if notInBridgeWhitelist
                    fprintf('Not registered with Bridge. Attempting connection.\n')
                    appID = registerWithBridgeV2(app, hueIPAddress, app.fName);
                    if ~isempty(appID)
                        fprintf('Connection with Hue Bridge completed. Added to Whitelist. \n')
                    else
                        fprintf('Connection process terminated. \n')
                        errCode = 888;
                        return;
                    end
                else
                    % fprintf('Connection with Hue Bridge established; using registered user-name (appID)\n')
                end


                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%% Gather Bridge data to return for further processing..
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%


                app.DiagnosticsMsgTextArea.Value = 'Gathering Bridge v2 data.';
                if app.noRoomsLightsOn.Text ~= "99" % first time in startup
                    drawnow;
                end

                url        = sprintf('http://%s/api/%s/lights', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    allLights = response;
                else % warning for first read failure to aid diagnosis - error for any subsequent failures
                    warning ('** Unable to access Lights details **');
                    save([app.dataRepository 'diagnostics.mat'],"response");
                    disp(url);
                end

                url = sprintf('http://%s/api/%s/rules', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    allRules = response;
                else
                    error ('** Unable to access Rules details **');
                end

                url = sprintf('http://%s/api/%s/sensors', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    allSensors = response;
                else
                    error ('** Unable to access Sensors details **');
                end

                url = sprintf('http://%s/api/%s/groups', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    allGroups = response;
                else
                    error ('** Unable to access Groups details **');
                end

                url = sprintf('http://%s/api/%s/schedules', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    allSchedules = response;
                else
                    error ('** Unable to access Schedules details **');
                end

                url = sprintf('http://%s/api/%s/capabilities', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    allCapabilities = response;
                else
                    error ('** Unable to access Capabilities details **');
                end


                url = sprintf('http://%s/api/%s/config', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    config = response;
                else
                    error ('** Unable to access Config details **');
                end

                url = sprintf('http://%s/api/%s/resourcelinks', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    allResourceLinks = response;
                else
                    error ('** Unable to access Resourcelinks details **');
                end

                url = sprintf('http://%s/api/%s/scenes', hueIPAddress,appID);
                response = webread(url);
                if ~isfield(response,'error')
                    allScenes = response;
                else
                    error ('** Unable to access Scenes details **');
                end

            else % API v2 (Bridge Pro)

                fprintf('\nChecking if already registered to the Hue Bridge Pro (API v2)...\n');

                if ispc
                    app.fName = [app.dataRepository '\userNameHuePro.mat'];
                else
                    app.fName = [app.dataRepository '/userNameHuePro.mat'];
                end

                try
                    % Load stored application key
                    load(app.fName);   % loads Bridge Pro appKey
                    appID = hueAppKey;
                    registeredV2 = validateProKey(app,hueIPAddress, appID);
                catch
                    registeredV2 = false;
                end

                if ~ registeredV2
                    fprintf('Not registered with Bridge Pro. Attempting connection.\n')
                    appID = registerWithBridgePro(app, hueIPAddress, app.fName);
                    if ~isempty(appID)
                        fprintf('Connection with Hue Bridge Pro completed. Added to Whitelist. \n')
                    else
                        fprintf('Connection process terminated. \n')
                        errCode = 888;
                        return;
                    end
                else
                    fprintf('Connection with Hue Bridge established; using registered user-name (appID)\n')
                end


                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                %%% Gather Bridge Pro data
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%

                if app.bridgeProMode
                    app.DiagnosticsMsgTextArea.Value = 'Gathering Bridge Pro data.';
                else
                    app.DiagnosticsMsgTextArea.Value = 'Gathering Bridge v2 data via API v2 - simulating Bridge Pro processing..';
                end
                drawnow;

                app.appKey = appID;

                loadAllResourcesPro(app,hueIPAddress, app.appKey);
                config = app.bridgev2;

                % allRooms        = app.roomsv2;
                geolocation = app.geolocationv2.';
                allDevices = app.devicesv2.'; % transpose AxB to BxA
                allLights = app.lightsv2.';
                allGroups = app.groupsv2.';
                allScenes = app.scenesv2.';
                allScripts = app.scriptsv2.';
                allSchedules = app.schedulesv2.';
                allCapabilities = app.capabilitiesv2.';
                allResourceLinks = app.resourcelinksv2.';
                allSensors = app.sensorsv2.';
                allSensorTemps = app.sensorsTempv2.';
                allSensorMotions = app.sensorsMotionv2.';
                allSwitches = app.switchesv2.';
                allPowerStates = app.devicePowerv2.';
                allConnectivity = app.connectivityv2.';
                % Only save extracted Bridge data at startup
                if app.startup
                    app.startup = false;
                    saveAllData (app, allDevices, allLights, [], allScripts, allSensors, ...
                        allSensorTemps, allSensorMotions, allSwitches, allGroups, ...
                        allSchedules, allCapabilities, allConnectivity, allResourceLinks, allScenes, ...
                        allPowerStates, config)
                    if ispc
                        save([app.dataRepository '\geolocation.mat'],"geolocation");
                    else
                        save([app.dataRepository '/geolocation.mat'],"geolocation");
                    end
                else
                    dt = datetime('now', 'Format', 'dd-MMM-yyyy HH:mm:ss');
                    strDt = string(dt);
                    app.LastBridgeDataRefreshTextArea.Value = strDt;
                end

                app.sunset = geolocation{1}.sun_today(1).sunset_time;

            end
        end

        %%%
        %%% New Bridge registration - process for adding new user to
        %%% WhiteList for Bridge V2
        %%%

        function appID = registerWithBridgeV2(app, hueIPAddress, fNameSAVE)
            appID = [];
            app.DiagnosticsMsgTextArea.Value = 'Attempting to pair with the Bridge..';
            lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
            save (app.diagFileName,"lastDiagMsg");
            fprintf('\nAttempting to pair with the Bridge..');
            url  = sprintf('http://%s/api', hueIPAddress);
            options = weboptions('MediaType', 'application/json');
            deviceType = struct('devicetype', 'SPF_Matlab_API');
            response = webwrite (url, deviceType, options);

            if isfield(response,'success')
                finished = true;
            else
                finished = false;
            end

            if ~finished

                Msgbox = uifigure;
                message = "Need to obtain a valid Username to connect to Bridge v2; Proceed with Pairing? (Will need to press the link button on the bridge.)";
                selection1 = uiconfirm(Msgbox,message,"Bridge Registration Process", ...
                    "Options",["Yes","No (Cancel)"],...
                    "DefaultOption",1,"CancelOption",2,....
                    "Icon","question");
                switch selection1
                    case 'Yes'
                        message = "Press the Link Button on the Hue Bridge v2 within 60 seconds.";
                        selection2 = uiconfirm(Msgbox,message,"Bridge v2 Registration Process", ...
                            "Options",["Continue","Cancel"],...
                            "DefaultOption",1,"CancelOption",2,....
                            "Icon","info");
                        switch selection2
                            case 'Continue'
                                uialert(Msgbox,"Press the Link button on the Bridge v2","OK");
                                app.DiagnosticsMsgTextArea.Value = 'Waiting for Link Button press..';
                                lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                                save (app.diagFileName,"lastDiagMsg");
                                drawnow
                            case 'Cancel'
                                app.DiagnosticsMsgTextArea.Value = 'Cancelled.  Not paired.';
                                lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                                save (app.diagFileName,"lastDiagMsg");
                                drawnow
                                pause(3)
                                delete(Msgbox);
                                return
                        end
                    case 'No (Cancel)'
                        app.DiagnosticsMsgTextArea.Value = 'Cancelled.  Not paired.';
                        lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                        save (app.diagFileName,"lastDiagMsg");
                        drawnow
                        pause(3)
                        delete(Msgbox);
                        return
                end
            end

            tic
            while ~finished && toc < 60
                try
                    response = webwrite (url, deviceType, options);
                    if isfield(response,'success')
                        finished = true;
                    end
                catch % wait for link button being pressed, unless it's a real error
                    if contains(response.error.description, 'link button not pressed')
                        pause(5)
                    else
                        fprintf('\n* Unable to communicate with the Bridge v2 *\n');
                        app.DiagnosticsMsgTextArea.Value = 'Unable to communicate with the Bridge v2.  Not paired.';
                        lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                        save (app.diagFileName,"lastDiagMsg","response");
                        pause(3)
                        delete(Msgbox);
                        return
                    end
                end
            end

            if toc >= 60
                fprintf('\n* Link button on Bridge v2 not pressed within 60 seconds.  Timed out. *\n');
                app.DiagnosticsMsgTextArea.Value = 'Link button on Bridge v2 not pressed within 60 seconds.  Timed out.';
                lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                save (app.diagFileName,"lastDiagMsg");
                drawnow
                pause(3)
                delete(Msgbox);
                return
            end

            appID = response.success.username;
            hueUser = appID;
            save(fNameSAVE,'hueUser');
            app.DiagnosticsMsgTextArea.Value = 'Bridge v2 registration successful (added to Whitelist).  Restarting the App in 5 seconds...';
            lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
            save (app.diagFileName,"lastDiagMsg");
            pause(5)
            delete(Msgbox);
            restart(app);
        end

        %%%
        %%% Obtain API V2 application key for Bridge Bridge Pro
        %%%

        function appKey = registerWithBridgePro(app, hueIPAddress, fName)

            import matlab.net.http.*
            import matlab.net.http.field.*

            % ------------------------------------------------------------
            % Correct endpoint for pairing on ALL Hue bridges (v1 + v2)
            % ------------------------------------------------------------
            url = "https://" + hueIPAddress + "/api";
            % disp(url);

            % Correct pairing body for Hue API v2
            body = struct('devicetype', 'MATLAB_App');

            % No application key header during pairing
            req = RequestMessage('post', [], body);

            opts = HTTPOptions( ...
                'VerifyServerName', false, ...
                'CertificateFilename', '' ...   % ignore CA validation
                );

            fprintf('Press the link button on the Bridge Pro...\n');
            app.DiagnosticsMsgTextArea.Value = ...
                'Press the link button on the Bridge Pro within 30 seconds...';
            drawnow
            pause(30);

            try
                resp = req.send(url, opts);
                fprintf('Bridge registration response code: %s\n', resp.StatusCode);

                if resp.StatusCode == matlab.net.http.StatusCode.OK

                    % Response is a cell array containing a struct
                    data = resp.Body.Data;
                    if iscell(data)
                        data = data{1};
                    end

                    if isfield(data, "success") && isfield(data.success, "username")
                        appKey = data.success.username;
                        hueAppKey = appKey;
                        save(fName, "hueAppKey");

                        app.DiagnosticsMsgTextArea.Value = ...
                            'Bridge Pro registration successful. Restarting the App...';
                        drawnow
                        pause(5);
                        restart(app);
                        return
                    end
                end

                % If we reach here, pairing failed
                appKey = [];
                disp(resp.Body.Data)

            catch ME
                % ------------------------------------------------------------
                % Handle pairing errors (button not pressed, timeout, etc.)
                % ------------------------------------------------------------
                appKey = [];

                try
                    err = resp.Body.Data(1).error.description;

                    if contains(err, 'link button not pressed', 'IgnoreCase', true)
                        fprintf('\n* Link button not pressed within 30 seconds. *\n');
                        app.DiagnosticsMsgTextArea.Value = ...
                            'Link button not pressed within 30 seconds. Timed out.';
                    else
                        fprintf('\n* Undetermined error during registration. *\n');
                        app.DiagnosticsMsgTextArea.Value = ...
                            'Undetermined error during Bridge Pro registration.';
                    end

                catch
                    fprintf('\n* Unable to communicate with the Bridge Pro *\n');
                    app.DiagnosticsMsgTextArea.Value = ...
                        'Unable to communicate with the Bridge Pro. Not paired.';
                end

                drawnow
                pause(10)

                lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                save(app.diagFileName, "lastDiagMsg");
            end
        end


        %%%
        %%% Get the Bridge IP address - if not available or no longer valid then obtain a new one..
        %%%

        function [errCode, hueIPAddress] = getBridgeIP(app)

            % First try to use previously stored IP address to save unnecessary external webreads
            errCode = 0;

            if app.bridgeProMode % API v2 for Bridge Pro
                if ispc
                    IPFile = [app.dataRepository '\hueIPAddressPro.mat'];
                else
                    IPFile = [app.dataRepository '/hueIPAddressPro.mat'];
                end
                app.fName = IPFile;
                try
                    load(IPFile,"pro");
                    hueIPAddress = pro;
                    fprintf('Retrieved previously stored IP address %s from %s.',hueIPAddress,IPFile);
                    % API v2
                    if app.isValidHueIP(hueIPAddress)
                        return; % still valid
                    end
                catch MSG
                    disp(MSG)
                    hueIPAddress = 'IP file not found';
                end
                % Previous IP no longer valid
                % Try to get fresh details from the web
                fprintf('\nBridge Pro IP address changed.  Obtaining new IP address..');
                bridgeModel = 'BSB003';
                [pro, errCode] = discoverBridges(app, bridgeModel);
                if errCode ~= 0
                    fprintf('\nBridge Pro IP address not returned - errCode = %d',errCode);
                    return;
                end
                hueIPAddress = pro;
                save(IPFile,"pro");
                fprintf('\nNew Bridge Pro IP address = %s',hueIPAddress);
            else % either API v1 or API v2 for Bridge v2
                if ispc
                    IPFile = [app.dataRepository '\hueIPAddress.mat'];
                    userFile = [app.dataRepository '\userNameHue.mat'];
                else
                    IPFile = [app.dataRepository '/hueIPAddress.mat'];
                    userFile = [app.dataRepository '/userNameHue.mat'];
                end
                app.fName = IPFile;
                try
                    load(IPFile,"v2");
                    hueIPAddress = v2(1).internalipaddress;
                    load(userFile,"hueUser");
                    app.appID = hueUser;
                    % fprintf('Using previously stored IP address from %s.',IPFile);
                    if app.vAPI == 1
                        try
                            url = sprintf('http://%s/api/%s/config', hueIPAddress,app.appID);
                            % check that the details still work..
                            webread(url);
                            return; % still valid
                        catch MSG
                            disp(MSG.message)
                        end
                    else % API v2
                        if app.isValidHueIP(hueIPAddress)
                            return; % still valid
                        end
                    end
                catch MSG
                    disp(MSG)
                    hueIPAddress = 'IP file not found';
                end

                % Previous IP no longer valid
                % Try to get fresh details from the web
                fprintf('\nBridge v2 IP address changed.  Obtaining new IP address..');
                bridgeModel = 'BSB002';
                [v2, errCode] = discoverBridges(app, bridgeModel);
                if errCode ~= 0
                    return;
                end
                hueIPAddress = v2;
                save(IPFile,"v2");
                fprintf('\nNew Bridge v2 IP address = %s',hueIPAddress);
            end
        end

        %%%
        %%% Saves all Bridge data for external analysis
        %%%

        function saveAllData(app, allDevices, allLights, allRules, allScripts, allSensors, sensorsTemp, ...
                sensorsMotion, allSwitches, allGroups, ...
                allSchedules, allCapabilities, allConnectivity, allResourceLinks, allScenes, ...
                allPowerStates, config)
            if ispc
                % Save all data for subsequent analysis
                save([app.dataRepository '\allDevicesData.mat'],"allDevices");
                save([app.dataRepository '\allLightsData.mat'],"allLights");
                save([app.dataRepository '\allRulesData.mat'],"allRules");
                save([app.dataRepository '\allScriptsData.mat'],"allScripts");
                save([app.dataRepository '\allSensorsData.mat'],"allSensors");
                save([app.dataRepository '\temperaturesData.mat'],"sensorsTemp");
                save([app.dataRepository '\motionsData.mat'],"sensorsMotion");
                save([app.dataRepository '\allSwitchesData.mat'],"allSwitches");
                save([app.dataRepository '\allGroupsData.mat'],"allGroups");
                save([app.dataRepository '\allSchedulesData.mat'],"allSchedules");
                save([app.dataRepository '\allCapabilitiesData.mat'],"allCapabilities");
                save([app.dataRepository '\allConnectivityData.mat'],"allConnectivity");
                save([app.dataRepository '\allScenesData.mat'],"allScenes");
                save([app.dataRepository '\allResourceLinksData.mat'],"allResourceLinks");
                save([app.dataRepository '\allPowerStatesData.mat'],"allPowerStates");
                save([app.dataRepository '\config.mat'],"config");
            else
                save([app.dataRepository '/allDevicesData.mat'],"allDevices");
                save([app.dataRepository '/allLightsData.mat'],"allLights");
                save([app.dataRepository '/allRulesData.mat'],"allRules");
                save([app.dataRepository '/allScriptsData.mat'],"allScripts");
                save([app.dataRepository '/allSensorsData.mat'],"allSensors");
                save([app.dataRepository '/temperaturesData.mat'],"sensorsTemp");
                save([app.dataRepository '/motionsData.mat'],"sensorsMotion");
                save([app.dataRepository '/allSwitchesData.mat'],"allSwitches");
                save([app.dataRepository '/allGroupsData.mat'],"allGroups");
                save([app.dataRepository '/allSchedulesData.mat'],"allSchedules");
                save([app.dataRepository '/allCapabilitiesData.mat'],"allCapabilities");
                save([app.dataRepository '/allConnectivityData.mat'],"allConnectivity");
                save([app.dataRepository '/allScenesData.mat'],"allScenes");
                save([app.dataRepository '/allResourceLinksData.mat'],"allResourceLinks");
                save([app.dataRepository '/allPowerStatesData.mat'],"allPowerStates");
                save([app.dataRepository '/config.mat'],"config");
            end

            listing = dir("**");
            tbl = struct2table(listing);
            tbl.date = datetime(tbl.datenum,ConvertFrom="datenum");
            tbl = removevars(tbl,"datenum");
            dets = tbl(tbl.name == "allCapabilitiesData.mat",3);
            app.LastBridgeDataRefreshTextArea.Value = string(dets.date(1,1));
        end

        %%%
        %%% Load all saved Bridge data
        %%%

        function [allDevices, allLights, allRules, allScripts, allSensors, ...
                sensorsTemp, sensorsMotion, allSwitches, allGroups, ...
                allSchedules, allCapabilities, allConnectivity, allResourceLinks, allScenes, ...
                allPowerStates, config] ...
                = loadAllData(app)


            if ispc
                % Load all saved data for processing
                load([app.dataRepository '\allDevicesData.mat'],"allDevices");
                load([app.dataRepository '\allLightsData.mat'],"allLights");
                load([app.dataRepository '\allRulesData.mat'],"allRules");
                load([app.dataRepository '\allScriptsData.mat'],"allScripts");
                load([app.dataRepository '\allSensorsData.mat'],"allSensors");
                load([app.dataRepository '\temperaturesData.mat'],"sensorsTemp");
                load([app.dataRepository '\motionsData.mat'],"sensorsMotion");
                load([app.dataRepository '\allSwitchesData.mat'],"allSwitches");
                load([app.dataRepository '\allGroupsData.mat'],"allGroups");
                load([app.dataRepository '\allSchedulesData.mat'],"allSchedules");
                load([app.dataRepository '\allCapabilitiesData.mat'],"allCapabilities");
                load([app.dataRepository '\allConnectivityData.mat'],"allConnectivity");
                load([app.dataRepository '\allScenesData.mat'],"allScenes");
                load([app.dataRepository '\allResourceLinksData.mat'],"allResourceLinks");
                load([app.dataRepository '\allPowerStatesData.mat'],"allPowerStates");
                load([app.dataRepository '\config.mat'],"config");
            else
                load([app.dataRepository '/allDevicesData.mat'],"allDevices");
                load([app.dataRepository '/allLightsData.mat'],"allLights");
                load([app.dataRepository '/allRulesData.mat'],"allRules");
                load([app.dataRepository '/allScriptsData.mat'],"allScripts");
                load([app.dataRepository '/allSensorsData.mat'],"allSensors");
                load([app.dataRepository '/temperaturesData.mat'],"sensorsTemp");
                load([app.dataRepository '/motionsData.mat'],"sensorsMotion");
                load([app.dataRepository '/allSwitchesData.mat'],"allSwitches");
                load([app.dataRepository '/allGroupsData.mat'],"allGroups");
                load([app.dataRepository '/allSchedulesData.mat'],"allSchedules");
                load([app.dataRepository '/allCapabilitiesData.mat'],"allCapabilities");
                load([app.dataRepository '/allConnectivityData.mat'],"allConnectivity");
                load([app.dataRepository '/allScenesData.mat'],"allScenes");
                load([app.dataRepository '/allResourceLinksData.mat'],"allResourceLinks");
                load([app.dataRepository '/allPowerStatesData.mat'],"allPowerStates");
                load([app.dataRepository '/config.mat'],"config");
            end

            listing = dir("**");
            tbl = struct2table(listing);
            tbl.date = datetime(tbl.datenum,ConvertFrom="datenum");
            tbl = removevars(tbl,"datenum");
            dets = tbl(tbl.name == "allCapabilitiesData.mat",3);
            app.LastBridgeDataRefreshTextArea.Value = string(dets.date(1,1));
        end

        function [returnFile] = load1File(app, loadFile)
            if ispc
                disp(whos("-file",append(app.dataRepository, '\', loadFile, '.mat')));
                returnFile = load(append(app.dataRepository, '\', loadFile, '.mat'));
            else
                returnFile = load(append(app.dataRepository, '/', loadFile, '.mat'));
            end
        end

        %%%
        %%% Restart the app after whitelist addition or IP address refresh
        %%%

        function restart(app)
            try
                stop(app.UpdateTimer);
                delete(app.UpdateTimer);
            catch
            end
            app.restoreWindowPos = false;
            startupFcn(app);              % Launch a new instance of the app
        end

        %%%
        %%% Blink the number of lights on value
        %%%

        function blinkLightsOn(app)

            % Fast-ish blink (3 seconds per cycle)
            app.HTMLLightsOn.Data = struct( ...
                'message', num2str(app.lightsOn), ...
                'color', 'white', ...
                'fontsize', '36px', ...
                'fontweight', 'bold', ...
                'lineheight', '0', ...
                'width', '55px', ...
                'height', '45px', ...
                'speed', '3s');
        end

        % Capture new sequence of Lights details when sorted via UI
        function LightsTableDisplayDataChanged(app, event)
            % Get the displayed (sorted/filtered) data
            newData = event.DisplayData;
            % Update the underlying Data property
            app.LightsTable.Data = newData;
        end

        %%%
        %%% Determine how many Groups and Rooms have at least one Light lit after Toggling
        %%%

        function updateONGroupsRooms(app)
            app.OnGroups.Value = 0;
            app.roomLightsOn = 0;
            noLights = size(app.LightsTable.Data,1);
            sz = [app.AVGroups.Value 3];
            varTypes = ["string", "string", "logical"];
            varNames = [ "groupname", "type","on"];
            lGroups = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            lGroups.groupname(:) = "";
            for i = 1:noLights
                if app.LightsTable.Data{i,7} == 1
                    for j = 1:app.AVGroups.Value
                        if app.LightsTable.Data{i,1} == lGroups.groupname(j)
                            break;
                        end
                        if lGroups.groupname(j) == ""
                            lGroups.groupname(j) = app.LightsTable.Data{i,1};
                            lGroups.type(j) = app.LightsTable.Data{i,2};
                            lGroups.on(j)= true;
                            break;
                        end
                    end
                end
            end
            for i = 1:app.AVGroups.Value
                if lGroups.on(i)
                    app.OnGroups.Value = app.OnGroups.Value + 1;
                    if contains(lGroups.type(i),"Room","Ignorecase",true)
                        app.roomLightsOn = app.roomLightsOn + 1;
                    end
                end
            end
            app.noRoomsLightsOn.Text = num2str(app.roomLightsOn);
            if app.roomLightsOn == 1
                app.RoomsLabel.Text = 'Room';
            else
                app.RoomsLabel.Text = 'Rooms';
            end
        end


        %%%
        %%% Full close-down and restart
        %%%

        function restartApp(app)
            if app.offlineMode
                return
            end

            cd (app.appFolder)
            warning('on', 'all');
            saveWindowState(app);
            appName = mfilename;

            try
                stop(app.UpdateTimer);
                delete(app.UpdateTimer);
            catch
            end

            delete(app);
            feval(appName);

        end

        %%%
        %%% Convert Philips Hue xy + brightness to MATLAB RGB (0–1)
        %%%

        function rgb = hueXYtoRGB(~,x, y, bri)
            % Convert Philips Hue xy + brightness to App-style RGB (0–255)

            if y == 0
                rgb = [0 0 0];
                return;
            end

            % Step 1: xy + brightness → XYZ
            Y = bri / 254;          % Normalised luminance
            X = (Y / y) * x;
            Z = (Y / y) * (1 - x - y);
            XYZ = [X; Y; Z];

            % Step 2: XYZ → linear RGB (sRGB matrix)
            M = [ 3.2406 -1.5372 -0.4986;
                -0.9689  1.8758  0.0415;
                0.0557 -0.2040  1.0570 ];

            rgb_lin = M * XYZ;

            % Step 3: Clip negative values
            rgb_lin = max(rgb_lin, 0);

            % Step 4: sRGB gamma correction
            gammaCorrect = @(c) (c <= 0.0031308) .* (12.92 * c) + ...
                (c > 0.0031308)  .* (1.055 * c .^ (1/2.4) - 0.055);

            rgb = gammaCorrect(rgb_lin);

            % Step 5: Scale to 0–255
            rgb = uint8(round(255 * rgb / max(rgb)));
        end

        %%%
        %%% Validate XY (xx,yy) format Lights colour input and return X,Y
        %%%

        function [isValid, x, y] = validateXYcolour(~, str)
            %   Accepts "NN,NN" or "0.34,0.21" and normalises to Hue xy.
            %
            %   Strict format "12,34" becomes x=0.12, y=0.34.
            %   Flexible format "0.12,0.34" is used as-is.
            %
            %   mode = "strict"   → strict NN,NN detected and normalised
            %   mode = "flexible" → real Hue xy detected
            %   mode = "none"     → invalid

            arguments
                ~
                str {mustBeTextScalar}
            end

            x = NaN;
            y = NaN;
            mode = "none";

            % Strict: exactly two digits, comma, two digits
            strictTok = regexp(str, '^(\d{2}),(\d{2})$', 'tokens', 'once');

            if ~isempty(strictTok)
                xs = str2double(strictTok{1});
                ys = str2double(strictTok{2});

                % Normalise to 0–1 Hue xy
                x = xs / 100;
                y = ys / 100;

                mode = "strict";
            else
                % Flexible: decimals, variable digits, whitespace
                flexTok = regexp(str, '^\s*([0-9]*\.?[0-9]+)\s*,\s*([0-9]*\.?[0-9]+)\s*$', ...
                    'tokens', 'once');

                if ~isempty(flexTok)
                    x = str2double(flexTok{1});
                    y = str2double(flexTok{2});
                    mode = "flexible";
                end
            end

            if mode == "none"
                isValid = false;
                return;
            end

            % Hue xy range check
            isValid = x>=0 && x<=1 && y>=0 && y<=1;
        end

        %%%
        %%% Save and restore UIFigure placement
        %%%

        function saveWindowState(app)
            appName = class(app);
            fig = app.getAppFigure;
            setpref(appName, "WindowPosition", fig.Position);
            setpref(appName, "WindowState", fig.WindowState);
        end

        function restoreWindowState(app)
            appName = class(app);
            fig = app.getAppFigure;

            % Restore window state
            if ispref(appName, "WindowState")
                fig.WindowState = getpref(appName, "WindowState");
            end

            % Restore position only if not maximized
            if ispref(appName, "WindowPosition") && fig.WindowState == "normal"
                pos = getpref(appName, "WindowPosition");

                % Get true minimum size
                [minW, minH] = app.getMinimumAppSize;

                % Enforce minimum size
                pos(3) = max(pos(3), minW);
                pos(4) = max(pos(4), minH);

                % Avoid off-screen placement
                screens = get(0, "MonitorPositions");
                if any(pos(1) >= screens(:,1) & pos(1) <= screens(:,1)+screens(:,3))
                    fig.Position = pos;
                end
            end
        end

        function fig = getAppFigure(app)
            % Find the UIFigure property dynamically
            props = properties(app);

            for i = 1:numel(props)
                val = app.(props{i});
                if isa(val, 'matlab.ui.Figure')
                    fig = val;
                    return
                end
            end
            error('No UIFigure found in app.');
        end

        function [minW, minH] = getMinimumAppSize(app)
            fig = app.getAppFigure;

            % Temporarily shrink the window to its smallest allowed size
            originalPos = fig.Position;

            % Try shrinking width
            fig.Position(3) = 1;
            drawnow limitrate
            minW = fig.Position(3);

            % Try shrinking height
            fig.Position(4) = 1;
            drawnow limitrate
            minH = fig.Position(4);

            % Restore original size
            fig.Position = originalPos;
        end

        %%%
        %%% Convert RGB to nearest matching colour (basic)
        %%%

        function name = nearestColourName(~, rgb)

            % Ensure double
            rgb = double(rgb);

            % rgb can be 0–1 or 0–255
            if max(rgb) > 1
                rgb = rgb / 255;
            end

            % Basic CSS-style colour list
            names = { ...
                'black','white','red','green','blue','yellow','cyan','magenta', ...
                'gray','orange','pink','purple','brown','lime','navy','teal'};

            values = [ ...
                0   0   0;      % black
                1   1   1;      % white
                1   0   0;      % red
                0   1   0;      % green
                0   0   1;      % blue
                1   1   0;      % yellow
                0   1   1;      % cyan
                1   0   1;      % magenta
                0.5 0.5 0.5;    % gray
                1   0.5 0;      % orange
                1   0.75 0.8;   % pink
                0.5 0   0.5;    % purple
                0.6 0.3 0;      % brown
                0.75 1 0;       % lime
                0   0   0.5;    % navy
                0   0.5 0.5];   % teal

            % Compute Euclidean distance
            rgb = reshape(rgb, 1, 3);   % ensure 1×3
            diffs = values - rgb(ones(size(values,1),1), :);
            dist = sqrt(sum(diffs.^2,2));

            [~, idx] = min(dist);
            name = names{idx};
        end

        %%%
        %%% Convert RGB to nearest matching CSS colour (detailed)
        %%%

        function name = nearestCssColourName(~, rgb)
            % Return nearest CSS colour name to an RGB triplet.
            % name = nearestCssColorName([R G B])
            % Accepts 0–255 or 0–1 RGB input.

            % Ensure double
            rgb = double(rgb);

            % Normalise if needed
            if max(rgb) > 1
                rgb = rgb / 255;
            end

            % CSS Level 4 colour names and RGB values
            data = {
                'aliceblue',            [240 248 255]
                'antiquewhite',         [250 235 215]
                'aqua',                 [  0 255 255]
                'aquamarine',           [127 255 212]
                'azure',                [240 255 255]
                'beige',                [245 245 220]
                'bisque',               [255 228 196]
                'black',                [  0   0   0]
                'blanchedalmond',       [255 235 205]
                'blue',                 [  0   0 255]
                'blueviolet',           [138  43 226]
                'brown',                [165  42  42]
                'burlywood',            [222 184 135]
                'cadetblue',            [ 95 158 160]
                'chartreuse',           [127 255   0]
                'chocolate',            [210 105  30]
                'coral',                [255 127  80]
                'cornflowerblue',       [100 149 237]
                'cornsilk',             [255 248 220]
                'crimson',              [220  20  60]
                'cyan',                 [  0 255 255]
                'darkblue',             [  0   0 139]
                'darkcyan',             [  0 139 139]
                'darkgoldenrod',        [184 134  11]
                'darkgray',             [169 169 169]
                'darkgreen',            [  0 100   0]
                'darkgrey',             [169 169 169]
                'darkkhaki',            [189 183 107]
                'darkmagenta',          [139   0 139]
                'darkolivegreen',       [ 85 107  47]
                'darkorange',           [255 140   0]
                'darkorchid',           [153  50 204]
                'darkred',              [139   0   0]
                'darksalmon',           [233 150 122]
                'darkseagreen',         [143 188 143]
                'darkslateblue',        [ 72  61 139]
                'darkslategray',        [ 47  79  79]
                'darkslategrey',        [ 47  79  79]
                'darkturquoise',        [  0 206 209]
                'darkviolet',           [148   0 211]
                'deeppink',             [255  20 147]
                'deepskyblue',          [  0 191 255]
                'dimgray',              [105 105 105]
                'dimgrey',              [105 105 105]
                'dodgerblue',           [ 30 144 255]
                'firebrick',            [178  34  34]
                'floralwhite',          [255 250 240]
                'forestgreen',          [ 34 139  34]
                'fuchsia',              [255   0 255]
                'gainsboro',            [220 220 220]
                'ghostwhite',           [248 248 255]
                'gold',                 [255 215   0]
                'goldenrod',            [218 165  32]
                'gray',                 [128 128 128]
                'green',                [  0 128   0]
                'greenyellow',          [173 255  47]
                'grey',                 [128 128 128]
                'honeydew',             [240 255 240]
                'hotpink',              [255 105 180]
                'indianred',            [205  92  92]
                'indigo',               [ 75   0 130]
                'ivory',                [255 255 240]
                'khaki',                [240 230 140]
                'lavender',             [230 230 250]
                'lavenderblush',        [255 240 245]
                'lawngreen',            [124 252   0]
                'lemonchiffon',         [255 250 205]
                'lightblue',            [173 216 230]
                'lightcoral',           [240 128 128]
                'lightcyan',            [224 255 255]
                'lightgoldenrodyellow', [250 250 210]
                'lightgray',            [211 211 211]
                'lightgreen',           [144 238 144]
                'lightgrey',            [211 211 211]
                'lightpink',            [255 182 193]
                'lightsalmon',          [255 160 122]
                'lightseagreen',        [ 32 178 170]
                'lightskyblue',         [135 206 250]
                'lightslategray',       [119 136 153]
                'lightslategrey',       [119 136 153]
                'lightsteelblue',       [176 196 222]
                'lightyellow',          [255 255 224]
                'lime',                 [  0 255   0]
                'limegreen',            [ 50 205  50]
                'linen',                [250 240 230]
                'magenta',              [255   0 255]
                'maroon',               [128   0   0]
                'mediumaquamarine',     [102 205 170]
                'mediumblue',           [  0   0 205]
                'mediumorchid',         [186  85 211]
                'mediumpurple',         [147 112 219]
                'mediumseagreen',       [ 60 179 113]
                'mediumslateblue',      [123 104 238]
                'mediumspringgreen',    [  0 250 154]
                'mediumturquoise',      [ 72 209 204]
                'mediumvioletred',      [199  21 133]
                'midnightblue',         [ 25  25 112]
                'mintcream',            [245 255 250]
                'mistyrose',            [255 228 225]
                'moccasin',             [255 228 181]
                'navajowhite',          [255 222 173]
                'navy',                 [  0   0 128]
                'oldlace',              [253 245 230]
                'olive',                [128 128   0]
                'olivedrab',            [107 142  35]
                'orange',               [255 165   0]
                'orangered',            [255  69   0]
                'orchid',               [218 112 214]
                'palegoldenrod',        [238 232 170]
                'palegreen',            [152 251 152]
                'paleturquoise',        [175 238 238]
                'palevioletred',        [219 112 147]
                'papayawhip',           [255 239 213]
                'peachpuff',            [255 218 185]
                'peru',                 [205 133  63]
                'pink',                 [255 192 203]
                'plum',                 [221 160 221]
                'powderblue',           [176 224 230]
                'purple',               [128   0 128]
                'rebeccapurple',        [102  51 153]
                'red',                  [255   0   0]
                'rosybrown',            [188 143 143]
                'royalblue',            [ 65 105 225]
                'saddlebrown',          [139  69  19]
                'salmon',               [250 128 114]
                'sandybrown',           [244 164  96]
                'seagreen',             [ 46 139  87]
                'seashell',             [255 245 238]
                'sienna',               [160  82  45]
                'silver',               [192 192 192]
                'skyblue',              [135 206 235]
                'slateblue',            [106  90 205]
                'slategray',            [112 128 144]
                'slategrey',            [112 128 144]
                'snow',                 [255 250 250]
                'springgreen',          [  0 255 127]
                'steelblue',            [ 70 130 180]
                'tan',                  [210 180 140]
                'teal',                 [  0 128 128]
                'thistle',              [216 191 216]
                'tomato',               [255  99  71]
                'turquoise',            [ 64 224 208]
                'violet',               [238 130 238]
                'wheat',                [245 222 179]
                'white',                [255 255 255]
                'whitesmoke',           [245 245 245]
                'yellow',               [255 255   0]
                'yellowgreen',          [154 205  50]
                };

            names  = data(:,1);
            values = double(cell2mat(data(:,2))) / 255;

            % Compute Euclidean distance
            rgb = reshape(rgb, 1, 3);   % ensure 1×3

            diffs = values - rgb(ones(size(values,1),1), :);

            dist  = sqrt(sum(diffs.^2,2));

            [~, idx] = min(dist);
            name = names{idx};
        end

        %%%
        %%% Check if any motions detected since last Refresh.  If so, get fresh Bridge data.
        %%%

        function onTimerTick(app)
            try
                if ~ app.autoRefresh
                    return
                end
                % disp('Timer')
                url = sprintf('http://%s/api/%s/sensors', app.hueIPAddressV2,app.appID);
                response = webread(url);
                if ~isfield(response,'error')
                    allSensors = response;
                else
                    error ('** Unable to access Sensors details 2 **');
                end
                tableSensors = struct2table(allSensors);
                noSensors = size(tableSensors);
                fieldX = fieldnames(allSensors(1,1));
                newEvent = false;
                for i = 1:noSensors(1,2)
                    try
                        lastEvent = tableSensors.(fieldX{i,1}).state.lastupdated;
                        productName = tableSensors.(fieldX{i,1}).productname;
                        sensorType = tableSensors.(fieldX{i,1}).type;
                        sensorName = tableSensors.(fieldX{i,1}).name;
                        if contains(productName,'motion') && contains(sensorType,"Presence")
                            if lastEvent > app.lastRefresh && app.sensorNameLastMovement ~= sensorName
                                app.sensorNameLastMovement = sensorName;
                                newEvent = true;
                                break;
                            end
                        end
                    catch
                    end
                end
                if newEvent
                    refreshDetails(app);
                    app.autoRefreshCount = app.autoRefreshCount + 1;
                    app.lastRefresh = datetime("now");
                    app.DiagnosticsMsgTextArea.Value = append('Auto-refresh ',num2str(app.autoRefreshCount),' triggered by ',sensorName, ' movement detection.');
                end
            catch
            end
        end

        %%%
        %%% Refresh Bridge Details
        %%%

        function refreshDetails(app)
            % clc

            stopTimer(app);
            delete(app.UpdateTimer);
            startTimer(app)
            % Change speed (faster pulse)
            app.Pulse.setSpeed(0.5);

            if app.responseLimit == 0
                app.responseLimit = round(app.Response.Value * 1.25);
            end
            app.RefreshButton.Visible ="off";
            app.DiagnosticsMsgTextArea.Value = '';
            app.LastBridgeDataRefreshTextArea.Value = 'Updating..';
            prevColour = app.LastBridgeDataRefreshTextArea.FontColor;
            app.LastBridgeDataRefreshTextArea.FontColor = "white";
            if app.ResponseLimitSwitch.Value == "On" && ~ app.offlineMode
                app.DiagnosticsMsgTextArea.Value = append('Extracting details from the Bridge..(Response Limit set to ',num2str(app.responseLimit), ' seconds.)');
            else
                if app.offlineMode
                    app.DiagnosticsMsgTextArea.Value = 'Loading previously saved details..';
                else
                    app.DiagnosticsMsgTextArea.Value = 'Extracting details from the Bridge..';
                end
            end
            drawnow;
            errCode = getStats (app, 'refresh');
            if app.ResponseLimitSwitch.Value == "On"
                if toc > app.responseLimit
                    fprintf('** Restarting to renew resources after exceeding %d seconds response limit by %d second(s). **\n', app.responseLimit,round(toc-app.responseLimit));
                    restartDetails= append('Restarted to renew resources after exceeding previous ',num2str(app.responseLimit), 's response limit.');
                    if ispc
                        save([app.dataRepository '\hueAppDiagnostics'],"restartDetails");
                    else
                        save([app.dataRepository '/hueAppDiagnostics'],"restartDetails");
                    end
                    restartApp(app);
                    return
                end
            end
            % clc
            app.LastBridgeDataRefreshTextArea.FontColor = prevColour;
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            if errCode == 0
                if app.offlineMode
                    app.DiagnosticsMsgTextArea.Value = 'Retrieved previously saved Bridge data.';
                else
                    app.DiagnosticsMsgTextArea.Value = 'Fresh data retrieved from the Bridge.';
                end
            elseif ~ app.offlineMode
                app.DiagnosticsMsgTextArea.Value = [app.DiagnosticsMsgTextArea.Value '  Refresh failed.'];
                lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                save (app.diagFileName,"lastDiagMsg");
                app.successfulStart = false;
            end
            if ~ app.offlineMode
                app.RefreshButton.Visible = "on";
                app.RefreshButton.Tooltip = '';
                app.lastRefresh = datetime("now");
            end
        end

        %%%
        %%% Timer functions
        %%%

        function startTimer(app)
            app.UpdateTimer = timer( ...
                'ExecutionMode', 'fixedRate', ...
                'Period', 15.0, ...
                'TimerFcn', @(~,~) app.onTimerTick() );
            start(app.UpdateTimer)
        end
        function stopTimer(app)
            try
                if ~isempty(app.UpdateTimer) && isvalid(app.UpdateTimer)
                    app.UpdateTimer.TimerFcn = '';   % prevents new callbacks
                    stop(app.UpdateTimer)
                    delete(app.UpdateTimer)
                end
            catch
            end
        end

        %%% >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>><<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<
        %%% >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>><<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<
        %%% >>  API v2 and Bridge Pro processing follows
        %%% >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>><<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<
        %%% >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>><<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<

        %%%
        %%% Determine which API version to use depending on Bridge
        %%%

        function apiVersion = detectHueApiVersion(app) % NOT USED
            fprintf('detectHueApiVersion\n');

            import matlab.net.http.*
            import matlab.net.http.field.*

            % Try API v2 endpoint (HTTPS)
            urlV2 = "https://" + app.hueIPAddressV2 + "/clip/v2/resource/bridge";

            try
                req = RequestMessage('get', HeaderField("hue-application-key", app.appID));
                opts = HTTPOptions( ...
                    'VerifyServerName', false, ...
                    'CertificateFilename', '' ...   % <‑‑ THIS disables CA validation
                    );

                resp = req.send(urlV2, opts);

                if resp.StatusCode == matlab.net.http.StatusCode.OK
                    apiVersion = 2;
                    return
                end
            catch
                % ignore, fall through to v1
            end

            % Try API v1 endpoint (HTTP)
            urlV1 = "http://" + app.hueIPAddressV2 + "/api/" + app.appID + "/config";

            try
                cfg = webread(urlV1);
                if isfield(cfg, 'bridgeid')
                    apiVersion = 1;
                    return
                end
            catch
                % ignore
            end

            error("Unable to detect Hue API version");

        end


        %%%
        %%%
        %%%

        function tf = deviceHasSensorServices(~,device)
            fprintf('deviceHasSensorServices\n');
            sensorTypes = ["motion","light_level","temperature","device_power","button","contact"];
            rtypes = string({device.services.rtype});
            tf = any(ismember(rtypes, sensorTypes));
        end

        %%%
        %%% Test for valid IP Addres using API v2
        %%%

        function tf = isValidHueIP(app, ip)
            % disp(class(app))
            fprintf('\nValidating IP: %s',ip);

            % 1) Try description.xml (works on all bridges)
            try
                url = "https://" + ip + "/description.xml";
                resp = app.sendRequest(url, "GET");

                if resp.StatusCode == matlab.net.http.StatusCode.OK
                    tf = true;
                    fprintf(' - OK.\n');
                    return
                end
            catch EM
                disp(EM)
                % ignore and try next
            end

            % 2) Try /api/config (works on all bridges)
            try
                url = "https://" + ip + "/api/config";
                resp = app.sendRequest(url, "GET");

                if resp.StatusCode == matlab.net.http.StatusCode.OK
                    tf = true;
                    fprintf(' - OK.\n');
                    return
                end
            catch EM
                disp(EM)
                % ignore
            end
            tf = false;
            fprintf(' - FAILED.\n');
        end


        %%%
        %%% Check which Bridges are connected
        %%%

        function [ip, errCode] = discoverBridges(~, bridgeModel)
            fprintf('discoverBridges for bridgeModel: %s\n', bridgeModel);

            % Returns IP address for requested Bridge Model
            % Uses the Hue discovery service to find all bridges on the LAN

            errCode = 0;
            ip = "";

            % --- Query Hue discovery service (still safe to use webread here) ---
            try
                allBridges = webread('https://discovery.meethue.com');
            catch
                warning('Unable to reach Hue discovery service.');
                errCode = 1;
                return;
            end

            disp(allBridges);

            % --- Prepare HTTP options for local bridge calls ---
            import matlab.net.*
            import matlab.net.http.*

            opts = HTTPOptions( ...
                'VerifyServerName', false, ...   % bypass hostname mismatch
                'CertificateFilename', '' );     % bypass CA validation

            req = RequestMessage;

            % --- Loop through discovered bridges ---
            for k = 1:numel(allBridges)
                ip = allBridges(k).internalipaddress;
                disp(ip);

                % ================================================================
                % 1) Try description.xml (v2 square bridge)
                % ================================================================
                try
                    uri = URI(['https://' ip '/description.xml']);
                    resp = req.send(uri, opts);

                    if resp.StatusCode == StatusCode.OK
                        desc = char(resp.Body.Data);
                        disp(desc);

                        if contains(desc, bridgeModel)
                            return
                        end
                    end
                catch
                    % Ignore — could be Bridge Pro or just unreachable
                end

                % ================================================================
                % 2) Try /api/config (works for all bridges)
                % ================================================================
                try
                    uri = URI(['https://' ip '/api/config']);
                    resp = req.send(uri, opts);

                    if resp.StatusCode ~= StatusCode.OK
                        continue
                    end

                    cfg = resp.Body.Data;
                    disp(cfg);

                    if cfg.modelid == bridgeModel
                        return
                    end

                catch
                    continue
                end
            end
            % No matching bridge was found
            errCode = 2;
        end

        %%%
        %%% Resource mapping for API v2 data
        %%%

        function s = getService(~, device, rtype, resourceMap)
            fprintf('getService: rtype=%s\n', rtype);
            s = [];

            % Unwrap device if it's a cell
            if iscell(device)
                if isempty(device)
                    return
                end
                device = device{1};
            end

            % If services field missing or empty, nothing to do
            if ~isfield(device, "services") || isempty(device.services)
                return
            end

            % Normalise services to a cell array
            if isstruct(device.services)
                svcList = num2cell(device.services);
            elseif iscell(device.services)
                svcList = device.services;
            else
                return
            end

            % Iterate safely
            for i = 1:numel(svcList)
                svc = svcList{i};

                if isfield(svc, "rtype") && svc.rtype == rtype
                    rid = svc.rid;

                    if isKey(resourceMap, rid)
                        s = resourceMap(rid);
                    end
                    return
                end
            end
        end

        %%%
        %%% api v2 to api v1 converters
        %%%

        % --------------------------------------------------------------
        % Load all v2 resources and split into categories
        % --------------------------------------------------------------
        function loadAllResourcesPro(app, hueIPAddress, appKey)
            % fprintf('loadAllResourcesv2\n');

            fetchAllResourcesPro(app, hueIPAddress, appKey);
            app.resourceMap = buildResourceMap(app, app.allResources);

            % data = app.rawResources;
            % save([app.dataRepository '/allSelectedBridgeProData.mat'],"data");

            % T = flattenHueResources(app, app.rawResources(:));
            % for v = 1:width(T)
            %     col = T.(v);
            %
            %     % Detect cell columns containing vectors
            %     if iscell(col) && ~isempty(col) && any(cellfun(@iscell, col))
            %         T.(v) = cellfun( ...
            %             @(c) strjoin(string(c), ','), ...
            %             col, ...
            %             'UniformOutput', false);
            %     end
            % end
            %
            % try
            %     writetable(T, 'hue_resources.xlsx');
            % catch EM
            %     disp(EM)
            %     disp(T)
            % end

            keys(app.resourceMap);

            % Split resources by type
            app.geolocationv2   = filterByType(app,"geolocation");
            app.roomsv2         = filterByType(app,"room");
            app.zonesv2         = filterByType(app,"zone");
            app.lightsv2        = filterByType(app,"light");
            app.bridgev2        = filterByType(app,"bridge");
            app.devicesv2       = filterByType(app,"device");
            app.sensorsv2 = filterByType(app,["temperature","motion"]);
            app.switchesv2 = filterByType(app, "button");
            app.sensorsTempv2 = filterByType(app,"temperature");
            app.sensorsMotionv2 = filterByType(app,"motion");
            app.powerStatesv2 = filterByType(app,"power");
            app.groupsv2        = filterByType(app,["zone" "room"]);
            app.scenesv2        = filterByType(app,"scene");
            app.scriptsv2         = filterByType(app,"behavior_script");
            app.schedulesv2     = filterByType(app,"behavior_instance");
            app.capabilitiesv2  = filterByType(app,"entertainment_configuration");
            app.resourcelinksv2 = filterByType(app,"resource_link");
            app.softwareUpdatesv2 = filterByType(app,"device_software_update");
            app.devicePowerv2 = filterByType(app,"device_power");
            app.connectivityv2 = filterByType(app,"zigbee_connectivity");

        end

        % --------------------------------------------------------------
        % Build RID → resource struct map
        % --------------------------------------------------------------
        function map = buildResourceMap(~, data)
            % fprintf('buildResourceMap\n');
            map = containers.Map;
            for i = 1:numel(data)
                r = data{i};
                map(r.id) = r;
            end
        end

        % --------------------------------------------------------------
        % Filter resources by type
        % --------------------------------------------------------------
        function out = filterByType(app, types)
            % fprintf('filterByType: %s\n', types);
            out = {};   % cell array avoids struct concatenation issues
            idx = 1;

            for i = 1:numel(app.allResources)
                r = app.allResources{i};
                if any(r.type == types)
                    out{idx} = r;
                    idx = idx + 1;
                end
            end
            % fprintf('filterByType: matched  %d\n', idx-1);
        end

        % --------------------------------------------------------------
        % Public v1-style API
        % --------------------------------------------------------------
        function out = lights(app)
            out = convertV2LightsToV1(app, app.devices, app.resourceMap);
        end

        function out = groups(app)
            out = convertV2GroupsToV1(app, app.groups, app.resourceMap);
        end

        function out = scenes(app)
            out = convertV2ScenesToV1(app, app.scenes, app.resourceMap);
        end

        function out = sensors(app)
            out = convertV2SensorsToV1(app, app.devices, app.resourceMap);
        end

        function out = rules(app)
            out = convertV2RulesToV1(app, app.rules, app.resourceMap);
        end

        function out = schedules(app)
            out = convertV2SchedulesToV1(app, app.schedules, app.resourceMap);
        end

        function out = capabilities(app)
            out = convertV2CapabilitiesToV1(app, app.capabilities);
        end

        function out = resourcelinks(app)
            out = convertV2ResourceLinksToV1(app, app.resourcelinks);
        end

        %%%
        %%% API v2 to v1 converters
        %%%

        function v1 = convertV2LightsToV1(app, devices, resourceMap)
            fprintf('convertV2LightsToV1\n');

            v1 = struct([]);
            idx = 1;

            for d = 1:numel(devices)

                device = normalizeResource(app, devices{d});   % <-- critical fix

                % Must have a light service
                lightSvc = getService(app,device, "light", resourceMap);
                disp(lightSvc);
                if isempty(lightSvc)
                    continue
                end
                v1(idx).name = device.metadata.name;
                v1(idx).uniqueid = device.id;
                v1(idx).type = "Extended color light";

                % ON/OFF
                onSvc = getService(app,device, "on", resourceMap);
                if ~isempty(onSvc)
                    v1(idx).state.on = onSvc.on.on;
                else
                    v1(idx).state.on = false;
                end

                % Brightness
                dimSvc = getService(app, device, "dimming", resourceMap);
                if ~isempty(dimSvc)
                    v1(idx).state.bri = round(dimSvc.dimming.brightness * 2.54);
                else
                    v1(idx).state.bri = 0;
                end

                % Color Temperature
                ctSvc = getService(app, device, "color_temperature", resourceMap);
                if ~isempty(ctSvc)
                    v1(idx).state.ct = ctSvc.color_temperature.mirek;
                end

                % XY Color
                colSvc = getService(app, device, "color", resourceMap);
                if ~isempty(colSvc)
                    v1(idx).state.xy = colSvc.color.xy;
                end

                % Reachable
                zig = getService(app, device, "zigbee_connectivity", resourceMap);
                if ~isempty(zig)
                    v1(idx).state.reachable = (zig.status == "connected");
                else
                    v1(idx).state.reachable = true;
                end

                idx = idx + 1;
            end
        end

        function v1 = convertV2GroupsToV1(app, groups, ~)
            fprintf('convertV2GroupsToV1\n');
            v1 = struct([]);
            idx = 1;

            for g = 1:numel(groups)
                % grp = groups(g);

                grp = normalizeResource(app, groups{g});   % <-- critical fix
                v1(idx).name = grp.metadata.name;
                v1(idx).type = grp.type;
                v1(idx).uniqueid = grp.id;

                v1(idx).lights = string([]);

                for i = 1:numel(grp.children)
                    % child = grp.children(i);
                    child = normalizeResource(app,grp.children{i});

                    if child.rtype == "device"
                        v1(idx).lights(end+1) = child.rid;
                    end
                end

                idx = idx + 1;
            end
        end

        function v1 = convertV2ScenesToV1(app, scenes, ~)
            fprintf('convertV2ScenesToV1\n');
            v1 = struct([]);
            idx = 1;

            for s = 1:numel(scenes)
                % sc = scenes(s);
                sc = normalizeResource(app, scenes{s});
                v1(idx).name = sc.metadata.name;
                v1(idx).type = "LightScene";
                v1(idx).uniqueid = sc.id;

                v1(idx).lights = string([]);
                v1(idx).lightstates = struct();

                actions = normalizeActions(app, sc.actions);

                for a = 1:numel(actions)
                    act = actions{a};

                    rid = act.target.rid;

                    % Resolve the v2 resource
                    light = app.resourceMap(rid);

                    % Extract v1 light ID (e.g. "/lights/12")
                    v1id = extractAfter(light.id_v1, "/lights/");

                    % Append to v1 light list
                    v1(idx).lights(end+1) = str2double(v1id);

                    % Store the action under a valid field name
                    fieldName = "L" + v1id;
                    v1(idx).lightstates.(fieldName) = act.action;
                end

                idx = idx + 1;
            end
        end

        function v1 = convertV2SensorsToV1(app, devices, resourceMap)
            fprintf('convertV2SensorsToV1\n');
            v1 = struct([]);
            idx = 1;

            for d = 1:numel(devices)
                device = normalizeResource(app, devices{d});
                state  = getSensorState(app, device, resourceMap);

                baseName = device.metadata.name;
                uid      = device.id;

                % -------------------------------------------------------------
                % MOTION → ZLLPresence
                % -------------------------------------------------------------
                if ~isempty(state.motion)
                    v1(idx).type = "ZLLPresence";
                    v1(idx).name = baseName + " motion";
                    v1(idx).state.presence    = logical(state.motion);
                    v1(idx).state.lastupdated = state.lastupdated;
                    v1(idx).uniqueid = uid;
                    idx = idx + 1;
                end

                % -------------------------------------------------------------
                % TEMPERATURE → ZLLTemperature
                % -------------------------------------------------------------
                if ~isnan(state.temperature)
                    v1(idx).type = "ZLLTemperature";
                    v1(idx).name = baseName + " temperature";
                    v1(idx).state.temperature = state.temperature;
                    v1(idx).state.lastupdated = state.lastupdated;
                    v1(idx).uniqueid = uid;
                    idx = idx + 1;
                end

                % -------------------------------------------------------------
                % LIGHT LEVEL → ZLLLightLevel
                % -------------------------------------------------------------
                if ~isnan(state.lightlevel)
                    v1(idx).type = "ZLLLightLevel";
                    v1(idx).name = baseName + " light level";
                    v1(idx).state.lightlevel  = state.lightlevel;
                    v1(idx).state.lastupdated = state.lastupdated;
                    v1(idx).uniqueid = uid;
                    idx = idx + 1;
                end

                % -------------------------------------------------------------
                % BATTERY → ZLLPower
                % -------------------------------------------------------------
                if ~isnan(state.battery)
                    v1(idx).type = "ZLLPower";
                    v1(idx).name = baseName + " battery";
                    v1(idx).state.battery     = state.battery;
                    v1(idx).state.lastupdated = state.lastupdated;
                    v1(idx).uniqueid = uid;
                    idx = idx + 1;
                end

                % -------------------------------------------------------------
                % CONTACT → ZLLContact
                % -------------------------------------------------------------
                if ~isempty(state.contact)
                    v1(idx).type = "ZLLContact";
                    v1(idx).name = baseName + " contact";
                    v1(idx).state.open        = logical(state.contact);
                    v1(idx).state.lastupdated = state.lastupdated;
                    v1(idx).uniqueid = uid;
                    idx = idx + 1;
                end

                % -------------------------------------------------------------
                % TAMPER → ZLLTamper
                % -------------------------------------------------------------
                if ~isempty(state.tamper)
                    v1(idx).type = "ZLLTamper";
                    v1(idx).name = baseName + " tamper";
                    v1(idx).state.tamper      = logical(state.tamper);
                    v1(idx).state.lastupdated = state.lastupdated;
                    v1(idx).uniqueid = uid;
                    idx = idx + 1;
                end

                % -------------------------------------------------------------
                % BUTTON → ZLLSwitch
                % -------------------------------------------------------------
                if ~isempty(state.button)
                    v1(idx).type = "ZLLSwitch";
                    v1(idx).name = baseName + " button";
                    v1(idx).state.button      = state.button;
                    v1(idx).state.lastupdated = state.lastupdated;
                    v1(idx).uniqueid = uid;
                    idx = idx + 1;
                end

                % -------------------------------------------------------------
                % ROTARY → ZLLRelativeRotary
                % -------------------------------------------------------------
                if ~isempty(state.rotary)
                    v1(idx).type = "ZLLRelativeRotary";
                    v1(idx).name = baseName + " rotary";
                    v1(idx).state.rotary      = state.rotary;
                    v1(idx).state.lastupdated = state.lastupdated;
                    v1(idx).uniqueid = uid;
                    idx = idx + 1;
                end
            end
        end

        % metadata.name
        % id
        % configuration.trigger
        % configuration.action

        function v1 = convertV2RulesToV1(app, rules, ~)
            fprintf('convertV2RulesToV1\n');
            v1 = struct([]);
            idx = 1;

            for r = 1:numel(rules)
                scr = normalizeGeneric(app,rules(r));

                v1(idx).name     = scr.metadata.name;
                v1(idx).uniqueid = scr.id;
                v1(idx).status   = "enabled";

                % Conditions
                if isfield(scr.configuration, "trigger")
                    v1(idx).conditions = scr.configuration.trigger;
                else
                    v1(idx).conditions = [];
                end

                % Actions
                if isfield(scr.configuration, "action")
                    v1(idx).actions = scr.configuration.action;
                else
                    v1(idx).actions = [];
                end

                idx = idx + 1;
            end
        end

        % metadata.name
        % id
        % configuration.schedule_time
        % configuration.action

        function v1 = convertV2SchedulesToV1(app, schedules, ~)
            fprintf('convertV2SchedulesToV1\n');
            v1 = struct([]);
            idx = 1;

            for s = 1:numel(schedules)
                inst = normalizeGeneric(app, schedules(s));

                v1(idx).name     = inst.metadata.name;
                v1(idx).uniqueid = inst.id;

                % Time
                if isfield(inst.configuration, "schedule_time")
                    v1(idx).time = inst.configuration.schedule_time;
                else
                    v1(idx).time = "";
                end

                % Command
                if isfield(inst.configuration, "action")
                    v1(idx).command = inst.configuration.action;
                else
                    v1(idx).command = [];
                end

                idx = idx + 1;
            end
        end

        function v1 = convertV2CapabilitiesToV1(app, capabilities)
            fprintf('convertV2CapabilitiesToV1\n');
            v1 = struct([]);
            idx = 1;

            for c = 1:numel(capabilities)
                cap = normalizeGeneric(app, capabilities(c));

                v1(idx).name         = cap.metadata.name;
                v1(idx).uniqueid     = cap.id;
                v1(idx).capabilities = cap;

                idx = idx + 1;
            end
        end

        % metadata.name
        % id
        % children

        function v1 = convertV2ResourceLinksToV1(app, links)
            fprintf('convertV2ResourceLinksToV1\n');
            v1 = struct([]);
            idx = 1;

            for l = 1:numel(links)
                link = normalizeGeneric(app, links(l));

                % Normalize children
                if ~isfield(link, "children") || isempty(link.children)
                    kids = {};
                elseif isstruct(link.children)
                    kids = num2cell(link.children);
                elseif iscell(link.children)
                    kids = link.children;
                else
                    kids = {};
                end

                v1(idx).name     = link.metadata.name;
                v1(idx).uniqueid = link.id;
                v1(idx).links    = kids;

                idx = idx + 1;
            end
        end

        %%%
        %%% VCheck for valid API v2 application key
        %%%


        function ok = validateProKey(~, ip, key)
            % fprintf('\nvalidateProKey\n');
            import matlab.net.http.*

            url = "https://" + ip + "/clip/v2/resource";

            % IMPORTANT: use comma-separated row vector
            headers = [ ...
                HeaderField('hue-application-key', key), ...
                HeaderField('Accept', '*/*') ...
                ];

            req = RequestMessage('get', headers);

            opts = HTTPOptions( ...
                'VerifyServerName', false, ...
                'CertificateFilename', '' ...
                );

            try
                resp = req.send(url, opts);

                % Valid key → 200 OK
                ok = resp.StatusCode == matlab.net.http.StatusCode.OK;

            catch ME
                disp(ME)
                ok = false;
            end
        end


        function appKey = performProPairing(app, ip)
            import matlab.net.http.*

            url = "https://" + ip + "/clip/v2/resource";

            body = struct( ...
                'type', 'application', ...
                'name', 'matlab-app' ...
                );

            headers = [ ...
                HeaderField('Accept','application/json'), ...
                HeaderField('Content-Type','application/json'), ...
                HeaderField('hue-application-key','dummy') ...  % <-- critical fix
                ];

            req = RequestMessage( ...
                'post', ...
                headers, ...
                MessageBody(jsonencode(body)) ...
                );

            opts = HTTPOptions( ...
                'VerifyServerName', false, ...
                'CertificateFilename', '', ...
                'UseProxy', false ...
                );

            fprintf('Press the link button on the Bridge Pro...\n');
            app.DiagnosticsMsgTextArea.Value = 'Press the link button on the Bridge Pro within 30 seconds..';
            drawnow
            pause(30);

            % try
            resp = req.send(url, opts);

            if resp.StatusCode == matlab.net.http.StatusCode.Created
                data = resp.Body.Data.data;
                appKey = data(1).username;
                hueAppKey = appKey;
                save(app.fName, "hueAppKey");
                app.DiagnosticsMsgTextArea.Value = 'Bridge Pro (API v2) registration successful (added to Whitelist).  Restarting the App in 5 seconds...';
                drawnow
                pause(5);
                restart(app);
            end
            % catch
            appKey = [];
            try
                if contains(resp.error.description, 'link button not pressed')
                    fprintf('\n* Link button on Bridge Pro not pressed within 30 seconds.  Timed out. *\n');
                    app.DiagnosticsMsgTextArea.Value = 'Link button on Bridge Pro not pressed within 30 seconds.  Timed out.';
                else
                    fprintf('\n* Undetermined error during Bridge Pro (API v2) registration. *\n');
                    app.DiagnosticsMsgTextArea.Value = 'Undetermined error during Bridge Pro (API v2) registration.';
                end
                drawnow;
                pause(10)
            catch
                fprintf('\n* Unable to communicate with the Bridge Pro *\n');
                app.DiagnosticsMsgTextArea.Value = 'Unable to communicate with the Bridge Pro.  Not paired.';
                drawnow;
                pause(10);
            end
            lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
            save (app.diagFileName,"lastDiagMsg");
            % end
        end

        %%%
        %%% Hue API v2 returned data normaliser
        %%%

        function r = normalizeResource(~, r)
            fprintf('normalizeResource\n');
            % Unwrap cell → struct
            if iscell(r)
                if isempty(r)
                    r = struct();
                    return
                end
                r = r{1};
            end

            % Ensure metadata exists
            if ~isfield(r, "metadata") || isempty(r.metadata)
                r.metadata = struct();
            end

            % Ensure id exists
            if ~isfield(r, "id")
                r.id = "";
            end

            % Ensure type exists
            if ~isfield(r, "type")
                r.type = "";
            end

            % Normalise services
            if ~isfield(r, "services") || isempty(r.services)
                r.services = {};
            elseif isstruct(r.services)
                r.services = num2cell(r.services);
            elseif ~iscell(r.services)
                r.services = {};
            end

            % Normalise children
            if ~isfield(r, "children") || isempty(r.children)
                r.children = {};
            elseif isstruct(r.children)
                r.children = num2cell(r.children);
            elseif ~iscell(r.children)
                r.children = {};
            end

            % Normalise owner
            if ~isfield(r, "owner") || isempty(r.owner)
                r.owner = struct();
            end

            % Normalise product_data
            if ~isfield(r, "product_data") || isempty(r.product_data)
                r.product_data = struct();
            end
        end

        function actions = normalizeActions(~, actions)
            fprintf('normalizeActions\n');
            % Empty → return empty cell
            if isempty(actions)
                actions = {};
                return
            end

            % Case A: struct array → convert to cell array
            if isstruct(actions) && numel(actions) > 1
                actions = num2cell(actions);
                return
            end

            % Case B: struct with UUID fieldnames
            if isstruct(actions) && isscalar(actions)
                f = fieldnames(actions);
                if all(contains(f, "-"))   % UUID detection
                    tmp = cell(1, numel(f));
                    for i = 1:numel(f)
                        tmp{i} = actions.(f{i});
                    end
                    actions = tmp;
                    return
                end
            end

            % Case C: already a cell array
            if iscell(actions)
                return
            end

            % Case D: single struct → wrap in cell
            if isstruct(actions)
                actions = {actions};
                return
            end

            % Anything else → empty
            actions = {};
        end

        function r = normalizeGeneric(~, r)
            fprintf('normalizeGeneric\n');
            % Unwrap cell
            if iscell(r)
                if isempty(r)
                    r = struct();
                    return
                end
                r = r{1};
            end

            % Ensure metadata
            if ~isfield(r, "metadata") || isempty(r.metadata)
                r.metadata = struct();
            end
            if ~isfield(r.metadata, "name")
                r.metadata.name = "";
            end

            % Ensure id
            if ~isfield(r, "id")
                r.id = "";
            end

            % Ensure configuration
            if ~isfield(r, "configuration") || isempty(r.configuration)
                r.configuration = struct();
            end
        end

        %%%
        %%% Get the v2 Sensor details
        %%%

        function state = getSensorState(~, device, resourceMap)
            fprintf('getSensorState\n');
            % Initialise output with safe defaults
            state.motion      = [];
            state.temperature = NaN;
            state.lightlevel  = NaN;
            state.battery     = NaN;
            state.contact     = [];
            state.tamper      = [];
            state.button      = [];
            state.rotary      = [];
            state.lastupdated = "";

            % Collect timestamps from all services
            timestamps = {};

            % Normalise services list
            services = device.services;
            if isstruct(services)
                services = num2cell(services);
            elseif ~iscell(services)
                services = {};
            end

            % Loop through service references
            for i = 1:numel(services)
                svcRef = services{i};

                % Must have rid
                if ~isfield(svcRef, "rid")
                    continue
                end

                % Resolve full service object
                if ~isKey(resourceMap, svcRef.rid)
                    continue
                end

                svc = resourceMap(svcRef.rid);

                % Must have .type (Hue v2 service objects use "type")
                if ~isfield(svc, "type")
                    continue
                end

                switch string(svc.type)

                    % -------------------------------------------------------------
                    % MOTION
                    % -------------------------------------------------------------
                    case "motion"
                        if isfield(svc, "motion")
                            state.motion = svc.motion.motion;
                        end
                        %
                        % exploreStruct(app, svc); % structure debug
                        % fprintf('svc.motion.motion_report.changed: %s',svc.motion.motion_report.changed);

                        % New schema: timestamp in motion_report.changed
                        if isfield(svc.motion, "motion_report") && ...
                                isfield(svc.motion.motion_report, "changed")
                            timestamps{end+1} = svc.motion.motion_report.changed;
                        end

                        % -------------------------------------------------------------
                        % TEMPERATURE
                        % -------------------------------------------------------------
                    case "temperature"
                        if isfield(svc, "temperature")
                            state.temperature = svc.temperature.temperature;
                        end
                        % New schema: timestamp in temperature_report.changed
                        if isfield(svc, "temperature_report") && ...
                                isfield(svc.temperature_report, "changed")

                            timestamps{end+1} = svc.temperature_report.changed;
                        end

                        % -------------------------------------------------------------
                        % LIGHT LEVEL
                        % -------------------------------------------------------------
                    case "light_level"
                        if isfield(svc, "light")
                            state.lightlevel = svc.light.light_level;
                        end

                        % New schema: timestamp in light_report.changed
                        if isfield(svc, "light_report") && ...
                                isfield(svc.light_report, "changed")

                            timestamps{end+1} = svc.light_report.changed;
                        end

                        % -------------------------------------------------------------
                        % BATTERY
                        % -------------------------------------------------------------
                    case "device_power"
                        if isfield(svc, "power_state") && ...
                                isfield(svc.power_state, "battery_level")

                            state.battery = svc.power_state.battery_level;
                        end

                        % -------------------------------------------------------------
                        % CONTACT SENSOR
                        % -------------------------------------------------------------
                    case "contact"
                        if isfield(svc, "contact")
                            state.contact = svc.contact.contact;

                            if isfield(svc.contact, "last_updated")
                                timestamps{end+1} = svc.contact.last_updated;
                            end
                        end

                        % -------------------------------------------------------------
                        % TAMPER
                        % -------------------------------------------------------------
                    case "tamper"
                        if isfield(svc, "tamper")
                            state.tamper = svc.tamper.tamper;

                            if isfield(svc.tamper, "last_updated")
                                timestamps{end+1} = svc.tamper.last_updated;
                            end
                        end

                        % -------------------------------------------------------------
                        % BUTTON (old + new schemas)
                        % -------------------------------------------------------------
                    case "button"

                        % Old schema
                        if isfield(svc, "button")
                            if isfield(svc.button, "last_event")
                                state.button = svc.button.last_event;
                            end
                            if isfield(svc.button, "last_updated")
                                timestamps{end+1} = svc.button.last_updated;
                            end
                        end

                        % New schema
                        if isfield(svc, "button_event")
                            if isfield(svc.button_event, "last_event")
                                state.button = svc.button_event.last_event;
                            elseif isfield(svc.button_event, "event")
                                state.button = svc.button_event.event;
                            end

                            if isfield(svc.button_event, "last_updated")
                                timestamps{end+1} = svc.button_event.last_updated;
                            end
                        end

                        % -------------------------------------------------------------
                        % ROTARY
                        % -------------------------------------------------------------
                    case "relative_rotary"
                        if isfield(svc, "relative_rotary")
                            state.rotary = svc.relative_rotary.last_event;

                            if isfield(svc.relative_rotary, "last_updated")
                                timestamps{end+1} = svc.relative_rotary.last_updated;
                            end
                        end
                end
            end

            % -------------------------------------------------------------
            % MERGE TIMESTAMPS
            % -------------------------------------------------------------
            if ~isempty(timestamps)
                try
                    dt = datetime(timestamps, "InputFormat", "yyyy-MM-dd'T'HH:mm:ssXXX");
                    state.lastupdated = char(max(dt));
                catch
                    % Fallback: use raw string if datetime parsing fails
                    state.lastupdated = timestamps{end};
                end
            end
        end

        function config = getBridgeConfigPro(app, hueIPAddress)
            % fprintf('getBridgeConfigPro\n');
            % Retrieve bridge metadata using Hue API v2.
            %
            % Returns a struct with:
            %   bridge_id, bridge_name, timezone, api_version, sw_version,
            %   product_name, manufacturer, model_id, serial_number

            import matlab.net.http.*
            import matlab.net.http.field.*

            url = "https://" + hueIPAddress + "/clip/v2/resource";

            header = HeaderField("hue-application-key", app.appKey);
            req = RequestMessage('get', header);

            opts = HTTPOptions( ...
                'VerifyServerName', false, ...
                'CertificateFilename', '' ...
                );

            resp = req.send(url, opts);

            if resp.StatusCode ~= matlab.net.http.StatusCode.OK
                error("Hue Bridge returned HTTP %s", string(resp.StatusCode));
            end

            raw = resp.Body.Data;

            % --- Validate presence of "data" field ---
            if ~isfield(raw, "data") || isempty(raw.data)
                error("Hue Bridge returned no resource data. Check app key or permissions.");
            end

            data = raw.data;

            % --- Normalise: extract only valid struct entries ---

            if iscell(data)
                flat = {};
                for i = 1:numel(data)
                    item = data{i};
                    if isstruct(item)
                        flat{end+1} = item; %#ok<AGROW>
                    elseif iscell(item)
                        % nested cell: extract any structs inside it
                        for j = 1:numel(item)
                            if isstruct(item{j})
                                flat{end+1} = item{j}; %#ok<AGROW>
                            end
                        end
                    end
                end

                if isempty(flat)
                    error("No valid struct entries found in resource list. Unexpected bridge response.");
                end

                % --- Unify field sets across all structs ---
                % Collect all field names that appear in any struct
                allFields = {};
                for i = 1:numel(flat)
                    allFields = union(allFields, fieldnames(flat{i}));
                end

                % Add missing fields to each struct
                for i = 1:numel(flat)
                    f = fieldnames(flat{i});
                    missing = setdiff(allFields, f);
                    for m = 1:numel(missing)
                        flat{i}.(missing{m}) = [];   % fill missing fields
                    end
                end

                % Now safe to concatenate
                data = [flat{:}];
            end

            % --- Validate struct array ---
            if ~isstruct(data)
                error("Unexpected resource format: expected struct array, got %s", class(data));
            end

            % --- Extract entries by type ---
            types = {data.type};

            bridgeEntry = data(strcmp(types, "bridge"));
            deviceEntry = data(strcmp(types, "device"));
            zigbeeEntry = data(strcmp(types, "zigbee_connectivity"));
            % --- Build output struct ---
            config = struct();

            % Zigbee details
            if ~isempty(zigbeeEntry)
                for zz=1:numel(zigbeeEntry)
                    z = zigbeeEntry(zz);
                    config.zigbee_channel  = safeField(app, z, ["channel", "value"]);
                    if contains(config.zigbee_channel,'channel','IgnoreCase',true)
                        config.zigbee_channel = extractAfter(config.zigbee_channel,'_');
                        break;
                    end
                end
            end

            % Bridge-level info
            if ~isempty(bridgeEntry)
                b = bridgeEntry(1);  % Should only be one
                config.bridge_id    = safeField(app,b, "bridge_id");
                config.timezone     = safeField(app,b, ["time_zone","time_zone"]);
            end

            % Device-level info (manufacturer, model, serial)
            if ~isempty(deviceEntry)
                for dd=1:numel(deviceEntry)
                    d = deviceEntry(dd);
                    config.product_name = safeField(app,d, ["product_data","product_name"]);
                    config.manufacturer = safeField(app,d, ["product_data","manufacturer_name"]);
                    config.model_id = safeField(app,d, ["product_data","model_id"]);
                    config.serial_number = safeField(app,d, ["product_data","serial_number"]);
                    config.sw_version = safeField(app,d, ["product_data","software_version"]);
                    config.model_id = safeField(app,d, ["product_data","model_id"]);
                    config.bridge_name = safeField(app,d, ["metadata","name"]);
                    if contains(config.product_name,'bridge','IgnoreCase',true)
                        break;
                    end
                end
            end
        end


        % -------------------------------------------------------------------------
        % SAFE NESTED FIELD ACCESSOR
        % -------------------------------------------------------------------------
        function out = safeField(~,s, path)
            % fprintf('safeField\n');
            %SAFEFIELD  Safely extract nested fields without throwing errors.
            % Example: safeField(entry, ["metadata","name"])

            try
                tmp = s;
                for p = path
                    if isfield(tmp, p)
                        tmp = tmp.(p);
                    else
                        out = "";
                        return
                    end
                end
                out = char(tmp);
            catch
                out = "";
            end
        end


        function C = ensureCell(~, data)
            % fprintf('ensureCell\n');

            % Case 1: empty → return empty cell
            if isempty(data)
                C = {};
                return
            end

            % Case 2: already a cell array
            if iscell(data)
                C = data;
                return
            end

            % Case 3: struct or struct array → convert to cell array
            if isstruct(data)
                C = num2cell(data);
                return
            end

            % Case 4: string "[]" or similar → treat as empty
            if isstring(data) || ischar(data)
                s = string(data);
                if strlength(s) == 0 || s == "[]"
                    C = {};
                    return
                end
            end

            % Case 5: numeric or logical → treat as empty
            if isnumeric(data) || islogical(data)
                C = {};
                return
            end

            % Anything else is unexpected
            error("Unexpected resource format: %s", class(data));
        end

        function fetchAllResourcesPro(app, hueIPAddress, appKey)
            % fprintf('fetchAllResources\n');

            path = "/clip/v2/resource/clip";
            method = 'GET';
            resp = hueRequest(app, hueIPAddress, appKey, method, path);
            % Save all resource types
            clip = resp.data;
            if app.startup % only at startup
                if ispc
                    save([app.dataRepository '\clip.mat'],"clip");
                else
                    save([app.dataRepository '/clip.mat'],"clip");
                end
            end

            types = [
                "light"
                "room"
                "device"
                "bridge"
                "zigbee_connectivity"
                "device_power"
                "product_data"
                "motion"
                "temperature"
                "temperature_report"
                "light_level"
                "light_report"
                "behavior_script"
                "behavior_instance"
                "power"
                "scene"
                "contact"
                "tamper"
                "button"
                "button_event"
                "relative_rotary"
                "resource_link"
                "device_software_update"
                "geolocation"
                ];

            app.allResources = {};

            path = "/clip/v2/resource";
            method = 'GET';
            resp = hueRequest(app, hueIPAddress, appKey, method, path);

            if app.startup % only at startup
                % Save all bridge pro data
                bridge = resp.data;
                if ispc
                    save([app.dataRepository '\allBridgeProData.mat'],"bridge");
                else
                    save([app.dataRepository '/allBridgeProData.mat'],"bridge");
                end
            end

            app.rawResources = resp.data;

            for t = types'

                path = "/clip/v2/resource/" + t;
                method = 'GET';
                resp = hueRequest(app, hueIPAddress, appKey, method, path);

                % Check if the response has a data field
                if ~isfield(resp, "data")
                    % fprintf("  -> No 'data' field returned (skipped)\n");
                    continue
                end

                raw = resp.data;

                % Normalize
                chunk = ensureCell(app, raw);

                % Append to master list
                app.allResources = [app.allResources; chunk(:)];
            end

        end

        function T = flattenHueResources(app, C)

            % Flatten each resource into a struct
            rows = cell(numel(C),1);
            for i = 1:numel(C)
                rows{i} = flattenStruct(app, C{i});
            end

            % Collect all unique fieldnames
            allFields = {};
            for i = 1:numel(rows)
                allFields = union(allFields, fieldnames(rows{i})');
            end

            % Build rows as structs (NOT tables)
            S = repmat(cell2struct(cell(1,numel(allFields)), allFields, 2), numel(rows), 1);

            for i = 1:numel(rows)
                s = rows{i};
                for f = allFields
                    fn = f{1};
                    if isfield(s, fn)
                        nv = normaliseForExcel(app, s.(fn));
                    else
                        nv = [];
                    end

                    % ensure plain text, numeric, logical, datetime, or 1×1 cell
                    if iscell(nv)
                        S(i).(fn) = nv{1};
                    else
                        S(i).(fn) = nv;
                    end
                end
            end

            % Convert struct array → table in one step
            T = struct2table(S);

        end


        function out = flattenStruct(app, s, prefix)
            if nargin < 3
                prefix = "";
            end

            out = struct();
            fns = fieldnames(s);

            for k = 1:numel(fns)
                name = fns{k};
                value = s.(name);
                if isempty(value)
                    continue
                end
                full = prefix + name;

                % Struct
                if isstruct(value)
                    if numel(value) == 1
                        sub = flattenStruct(app, value, full + "_");
                        out = mergeStructs(app, out, sub);
                    else
                        for idx = 1:numel(value)
                            sub = flattenStruct(app, value(idx), full + "_" + idx + "_");
                            out = mergeStructs(app, out, sub);
                        end
                    end

                    % Numeric array → expand into separate columns
                elseif isnumeric(value) && numel(value) > 1
                    for idx = 1:numel(value)
                        out.(full + "_" + idx) = value(idx);
                    end

                    % Cell array
                elseif iscell(value)
                    out.(full) = {value};

                    % Scalar
                else
                    out.(full) = value;
                end
            end
        end


        function s = mergeStructs(~, a, b)
            s = a;
            f = fieldnames(b);
            for i = 1:numel(f)
                s.(f{i}) = b.(f{i});
            end
        end

        function T = cellStructsToTable(~, C)
            C = C(:);  % flatten AxB to Nx1

            % Collect all fields
            allFields = unique(vertcat(fieldnames([C{:}])));

            % Normalise each struct
            for i = 1:numel(C)
                for f = 1:numel(allFields)
                    fld = allFields{f};
                    if ~isfield(C{i}, fld)
                        C{i}.(fld) = [];
                    end
                end
            end

            % Convert to table
            T = struct2table([C{:}]);
        end


        function v = normaliseForExcel(~, v)

            % Empty
            if isempty(v)
                v = {[]};
                return
            end

            % Strings → plain text
            if isstring(v) || ischar(v)
                txt = string(v);

                % Detect ISO‑8601 Hue timestamps
                if matches(txt, "\d{4}-\d{2}-\d{2}T\d{2}:\d{2}:\d{2}.*")
                    try
                        v = datetime(txt, ...
                            'InputFormat','yyyy-MM-dd''T''HH:mm:ssXXX', ...
                            'TimeZone','UTC');
                        return
                    catch
                        v = txt;
                        return
                    end
                end

                v = txt;
                return
            end

            % Numeric or logical scalar
            if (isnumeric(v) || islogical(v)) && isscalar(v)
                return
            end

            % Numeric or logical vector → comma-separated string
            if (isnumeric(v) || islogical(v)) && ~isscalar(v)
                v = strjoin(string(v), ",");
                return
            end

            % Datetime
            if isdatetime(v)
                return
            end

            % Cell array
            if iscell(v)
                % If the cell contains a vector, flatten it
                if numel(v) > 1
                    v = strjoin(string(v), ",");
                    return
                end

                % If the cell contains a single element, normalise that element
                inner = v{1};

                % If inner is itself a vector or struct, flatten it
                if iscell(inner)
                    v = strjoin(string(inner), ",");
                    return
                elseif isnumeric(inner) && ~isscalar(inner)
                    v = strjoin(string(inner), ",");
                    return
                elseif isstruct(inner)
                    % Flatten struct fields into key=value pairs
                    f = fieldnames(inner);
                    parts = strings(1, numel(f));
                    for i = 1:numel(f)
                        parts(i) = f{i} + "=" + string(inner.(f{i}));
                    end
                    v = strjoin(parts, ";");
                    return
                else
                    % Scalar inside cell → unwrap
                    v = inner;
                    return
                end
            end

            % Struct (should not happen after flattening)
            if isstruct(v)
                f = fieldnames(v);
                parts = strings(1, numel(f));
                for i = 1:numel(f)
                    parts(i) = f{i} + "=" + string(v.(f{i}));
                end
                v = strjoin(parts, ";");
                return
            end

            % Fallback
            v = string(v);
        end


        %%%
        %%% Generic API v2 request function
        %%%

        function out = hueRequest(~, ip, appKey, method, path, body)
            %H  Send a request to a Philips Hue v2 bridge.
            %
            %   out = hueRequest(ip, appKey, method, path)
            %   out = hueRequest(ip, appKey, method, path, body)
            %
            %   ip     - Bridge IP address (string)
            %   appKey - Hue v2 application key (string)
            %   method - 'GET', 'PUT', 'POST', 'DELETE'
            %   path   - API path, e.g. '/clip/v2/resource/bridge'
            %   body   - (optional) MATLAB struct to send as JSON

            import matlab.net.*
            import matlab.net.http.*

            % Build URI

            uri = matlab.net.URI("https://" + ip + path);

            opts = matlab.net.http.HTTPOptions( ...
                'VerifyServerName', false, ...
                'CertificateFilename', '' ...
                );

            headers = matlab.net.http.HeaderField("hue-application-key", appKey);


            % Select HTTP method
            switch upper(method)
                case 'GET'
                    verb = RequestMethod.GET;
                    reqBody = [];
                case 'DELETE'
                    verb = RequestMethod.DELETE;
                    reqBody = [];
                case 'PUT'
                    verb = RequestMethod.PUT;
                    reqBody = jsonencode(body);
                case 'POST'
                    verb = RequestMethod.POST;
                    reqBody = jsonencode(body);
                otherwise
                    error('HueRequest:InvalidMethod', 'Unsupported method: %s', method);
            end

            % Build request message
            if isempty(reqBody)
                req = matlab.net.http.RequestMessage(verb, headers);
            else
                req = matlab.net.http.RequestMessage(verb, headers, reqBody);
            end
            resp = req.send(uri, opts);

            % Error handling
            if resp.StatusCode ~= matlab.net.http.StatusCode.OK && ...
                    resp.StatusCode ~= matlab.net.http.StatusCode.Created

                body = resp.Body.Data;

                % Convert body to readable text for the error message
                if isa(body, 'uint8')
                    msg = char(body);
                elseif ischar(body)
                    msg = body;
                elseif isstring(body)
                    msg = char(body);
                elseif isstruct(body)
                    % Struct → JSON string for readability
                    msg = jsonencode(body);
                else
                    msg = "Unrecognised response body type";
                end
                if contains(msg, 'not found','IgnoreCase',true)
                    out = "Light Not Found.";
                    return
                end
                if contains(msg, 'communication issue','IgnoreCase',true)
                    out = "Light Communication Issues.";
                    return
                end

                error('HueRequest:HTTPError', ...
                    'Bridge returned %s\n%s', ...
                    string(resp.StatusCode), msg);
            end

            % Decode JSON
            body = resp.Body.Data;

            if isa(body, 'uint8')
                out = jsondecode(char(body));
            elseif ischar(body)
                out = jsondecode(body);
            elseif isstring(body)
                out = jsondecode(char(body));
            elseif isstruct(body)
                out = body;   % already decoded
            else
                error("HueRequest:UnexpectedBodyType", ...
                    "Unexpected response body type: %s", class(body));
            end
        end


        %%%%%
        %%%%% Unified API v2 HTTP request Processing methods
        %%%%%

        %%%
        %%% Generate preconfigured API v2 HTTP Client (1)
        %%%

        function [req, opts] = makeRequestObjects(~)
            import matlab.net.*
            import matlab.net.http.*

            req = RequestMessage;

            opts = HTTPOptions( ...
                'VerifyServerName', false, ...   % ignore hostname mismatch
                'CertificateFilename', '' );     % ignore CA trust chain
        end

        %%%
        %%% Wrapper for sending requests (2)
        %%%
        function resp = sendRequest(app, url, method, body)
            import matlab.net.*
            import matlab.net.http.*

            if nargin < 4
                body = [];
            end

            [req, opts] = app.makeRequestObjects();

            if nargin >= 3 && ~isempty(method)
                req.Method = method;
            end

            if ~isempty(body)
                req.Body = Body(body);
            end

            uri = URI(url);
            resp = req.send(uri, opts);
        end
        %%%%%
        %%%%% Unified API v2 HTTP request Processing methods (end)
        %%%%%

        %%%%
        %%%% Structure decomposition - for diagnostics
        %%%%

        function exploreStruct(app, s, indent)
            if nargin < 3
                indent = "";
            end

            if ~isstruct(s)
                fprintf("%s%s (%s)\n", indent, mat2str(s), class(s));
                return
            end

            f = fieldnames(s);
            for i = 1:numel(f)
                val = s.(f{i});
                if isstruct(val)
                    fprintf("%s%s: struct (%d fields)\n", indent, f{i}, numel(fieldnames(val)));
                    exploreStruct(app, val, indent + "    ");
                elseif iscell(val)
                    fprintf("%s%s: cell (%d elements)\n", indent, f{i}, numel(val));
                else
                    fprintf('%s%s: %s %s\n', ...
                        char(indent), ...
                        char(f{i}), ...
                        char(class(val)), ...
                        char(mat2str(size(val))));
                end
            end
        end

        %%% >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>
        %%% >>>> NOT IMPLEMENTED Start
        %%% >>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>>

        % ✔ Full v1/v2 auto‑switching for:
        % lights
        % groups/rooms
        % scenes
        % sensors
        % automations
        % ✔ Automatic ID translation (v1 ↔ v2)
        % ✔ Unified API layer
        % ✔ UI stays unchanged
        % ✔ Bridge‑agnostic control
        % ✔ Future‑proof for migration scripts
        %%% <<<

        %%%
        %%% Builds BOTH v1→v2 and v2→v1 maps for all Hue resource types.
        %%% Works on Bridge Pro (API v2). Returns a struct with:
        %%%  map.v1_to_v2.<type>(v1ID) = v2UUID
        %%%  map.v2_to_v1.<type>(v2UUID) = v1ID
        %%%

        function map = buildHueIdMaps(app, hueIPAddress)
            % fprintf('buildHueIdMaps\n');
            % To implement for use by the code below:
            % ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
            % app.idMap = buildHueIdMaps(app, app.lastHueIP);
            % ~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~~
            % Usage:
            % Convert  v1 lightno → v2 UUID
            % uuid = map.v1_to_v2.lights('/lights/12');
            %
            % Convert v2 UUID → v1 lightno
            % v1ightno = map.v2_to_v1.lights(uuid);

            import matlab.net.http.*
            import matlab.net.http.field.*

            url = "https://" + hueIPAddress + "/clip/v2/resource";

            header = HeaderField("hue-application-key", app.appID);
            req = RequestMessage('get', header);
            opts = HTTPOptions( ...
                'VerifyServerName', false, ...
                'CertificateFilename', '' ...   % <‑‑ THIS disables CA validation
                );


            resp = req.send(url, opts);

            if resp.StatusCode ~= matlab.net.http.StatusCode.OK
                error("Failed to query Bridge Pro resource list");
            end

            data = resp.Body.Data.data;

            % Initialise mapping containers
            types = ["lights","groups","scenes","sensors","all"];
            for t = types
                map.v1_to_v2.(t) = containers.Map('KeyType','char','ValueType','char');
                map.v2_to_v1.(t) = containers.Map('KeyType','char','ValueType','char');
            end

            % Process each resource entry
            for i = 1:numel(data)
                entry = data(i);

                if ~isfield(entry, 'id_v1')
                    continue
                end

                v1 = char(entry.id_v1);   % e.g. "/lights/12"
                v2 = char(entry.id);      % UUID

                % Store in global maps
                map.v1_to_v2.all(v1) = v2;
                map.v2_to_v1.all(v2) = v1;

                % Categorise by resource type
                switch entry.type
                    case "light"
                        map.v1_to_v2.lights(v1) = v2;
                        map.v2_to_v1.lights(v2) = v1;

                    case {"room","zone"}
                        map.v1_to_v2.groups(v1) = v2;
                        map.v2_to_v1.groups(v2) = v1;

                    case {"motion","temperature","light_level","device"}
                        map.v1_to_v2.sensors(v1) = v2;
                        map.v2_to_v1.sensors(v2) = v1;

                    case "scene"
                        map.v1_to_v2.scenes(v1) = v2;
                        map.v2_to_v1.scenes(v2) = v1;
                end
            end
        end


        function setGroupState(app, id, state)
            fprintf('setGroupState\n');

            if app.vAPI == 1
                setGroupStateV1(app, id, state);
            else
                key = sprintf('/groups/%d', id);
                uuid = app.idMap.v1_to_v2.groups(key);
                setGroupStateV2(app, uuid, state);
            end
        end

        function setGroupStateV1(app, groupID, state)
            fprintf('setGroupStateV1\n');
            url = sprintf('http://%s/api/%s/groups/%d/action', ...
                app.hueIPAddressV2, app.appID, groupID);
            webwrite(url, state, weboptions('MediaType','application/json','RequestMethod','put'));
        end

        function setGroupStateV2(app, uuid, state)
            fprintf('setGroupStateV2\n');

            import matlab.net.http.*
            import matlab.net.http.field.*

            url = "https://" + app.hueIPAddressV2 + "/clip/v2/resource/room/" + uuid;

            % Convert v1-style state to v2-style
            body = convertStateV1toV2(app,state);

            req = RequestMessage('patch', HeaderField("hue-application-key", app.appID), body);
            opts = HTTPOptions( ...
                'VerifyServerName', false, ...
                'CertificateFilename', '' ...
                );

            req.send(url, opts);
        end

        function activateScene(app, id)
            fprintf('activateScene\n');

            if app.vAPI == 1
                activateSceneV1(app, id);
            else
                key = sprintf('/scenes/%s', id);
                uuid = app.idMap.v1_to_v2.scenes(key);
                activateSceneV2(app, uuid);
            end
        end

        function activateSceneV1(app, sceneID)
            fprintf('activateSceneV1\n');
            url = sprintf('http://%s/api/%s/groups/0/action', app.hueIPAddressV2, app.appID);
            body = struct('scene', sceneID);
            webwrite(url, body, weboptions('MediaType','application/json','RequestMethod','put'));
        end

        function activateSceneV2(app, uuid)
            fprintf('activateSceneV2\n');

            import matlab.net.http.*
            import matlab.net.http.field.*

            url = "https://" + app.hueIPAddressV2 + "/clip/v2/resource/scene/" + uuid;

            body = struct('recall', struct('action', 'active'));

            req = RequestMessage('put', HeaderField("hue-application-key", app.appID), body);
            opts = HTTPOptions( ...
                'VerifyServerName', false, ...
                'CertificateFilename', '' ...
                );

            req.send(url, opts);
        end

        function data = readSensor(app, id)
            fprintf('readSensor\n');

            if app.vAPI == 1
                data = readSensorV1(app, id);
            else
                key = sprintf('/sensors/%d', id);
                uuid = app.idMap.v1_to_v2.sensors(key);
                data = readSensorV2(app, uuid);
            end
        end

        function data = readSensorV1(app, sensorID)
            fprintf('readSensorV1\n');
            url = sprintf('http://%s/api/%s/sensors/%d', app.hueIPAddressV2, app.appID, sensorID);
            data = webread(url);
        end

        function data = readSensorV2(app, uuid)
            fprintf('readSensorV2\n');

            import matlab.net.http.*
            import matlab.net.http.field.*

            url = "https://" + app.hueIPAddressV2 + "/clip/v2/resource/sensor/" + uuid;

            req = RequestMessage('get', HeaderField("hue-application-key", app.appID));
            opts = HTTPOptions( ...
                'VerifyServerName', false, ...
                'CertificateFilename', '' ...
                );

            resp = req.send(url, opts);

            data = resp.Body.Data.data;
        end

        function runAutomation(app, id)
            fprintf('runAutomation\n');

            if app.vAPI == 1
                error("Automations do not exist on API v1");
            else
                uuid = id; % automations are v2-only
                runAutomationV2(app, uuid);
            end
        end

        function runAutomationV2(app, uuid)
            fprintf('runAutomationV2\n');

            import matlab.net.http.*
            import matlab.net.http.field.*

            url = "https://" + app.hueIPAddressV2 + "/clip/v2/resource/automation/" + uuid;

            body = struct('enabled', true);

            req = RequestMessage('patch', HeaderField("hue-application-key", app.appID), body);
            opts = HTTPOptions('VerifyServerName', false);
            req.send(url, opts);
        end

        function out = convertStateV1toV2(~,state)
            fprintf('convertStateV1toV2\n');

            out = struct;

            if isfield(state, 'on')
                out.on = struct('on', state.on);
            end

            if isfield(state, 'bri')
                out.dimming = struct('brightness', state.bri / 2.54);
            end

            if isfield(state, 'ct')
                out.color_temperature = struct('mirek', state.ct);
            end

            if isfield(state, 'xy')
                out.color = struct('xy', struct('x', state.xy(1), 'y', state.xy(2)));
            end
        end

        %%% <<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<
        %%% <<<< NOT IMPLEMENTED End
        %%% <<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<<


        %%%
        %%%
        %%%

    end %%% Methods (Access = private) - Main processing

    %%%
    %%%
    %%%


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % clc
            app.startup = true;
            if app.vAPI == 2
                app.bridgeProMode = true;
                app.RulesTable.ColumnName = {'No'; 'Area'; 'Schedule/Scene Name'; 'Created'; 'Last Trigger'; 'Trigs'; 'Status'; 'Components'; 'On Timeout'; 'Mins'; 'Startup State'; 'Time Slots'};
                w = app.RulesTable.ColumnWidth;
                w{4} = 0;  % hide columns 4-6
                w{5} = 0;
                w{6} = 0;
                app.RulesTable.ColumnWidth = w;
            else
                app.RulesTable.ColumnName = {'No'; 'Area'; 'Rule Name'; 'Created'; 'Last Trigger'; 'Trigs'; 'Status'; 'Action'; 'Target'; 'Tgt No'; 'Target Name'; 'Resource Link'};
                w = app.RulesTable.ColumnWidth;
                w{4} = 'auto'; % unhide columns 4-6
                w{5} = 'auto';
                w{6} = 'auto';
                app.RulesTable.ColumnWidth = w;
            end

            if app.restoreWindowPos
                restoreWindowState(app);
            end
            app.lastRefresh = datetime("now");

            % Hide Colour column (9) in LightsTable
            % w = app.LightsTable.ColumnWidth;
            % w{9} = 0;
            % app.LightsTable.ColumnWidth = w;
            % Show Colour column in LightsTable
            % w = app.LightsTable.ColumnWidth;
            % w{9} = 'auto';            % or a numeric width, e.g. 120
            % app.LightsTable.ColumnWidth = w;

            %
            % Determine folder from which the app was launched
            %
            if isempty (string(app.appFolder))
                fullPath = mfilename('fullpath');
                app.appFolder = fileparts(which(fullPath));
                fprintf('app.appFolder = %s.\n', app.appFolder);
            end
            if ispc
                htmlFolder = [app.appFolder '\HTML'];
            else
                htmlFolder = [app.appFolder '/HTML'];
            end
            if ~exist(htmlFolder, 'dir')
                error ('HTML folder <%s> is missing.\n', htmlFolder);
            else
                fprintf('HTML folder <%s> found.\n', htmlFolder);
            end

            % Make connections to external HTML sources
            % HTML is saved in the HTML folder..
            if ispc
                addpath (genpath(strcat(app.appFolder, '\HTML')));
                addpath (genpath(strcat(app.appFolder, '\Classes')));
            else
                addpath (genpath(strcat(app.appFolder, '/HTML')));
                addpath (genpath(strcat(app.appFolder, '/Classes')));
            end

            app.HTMLTempTrend.HTMLSource =  fullfile(app.appFolder, 'HTML', 'blinkTextFlexible.html');
            app.HTMLLowBatMsg.HTMLSource = fullfile(app.appFolder, 'HTML','pulseTextHue.html');
            app.HTMLLightsOn.HTMLSource = fullfile(app.appFolder, 'HTML','blinkTextFlexible.html');
            app.HTMLLastMotion.HTMLSource = fullfile(app.appFolder, 'HTML','blinkTextFlexible.html');
            app.HTMLLowBatDevs.HTMLSource = fullfile(app.appFolder, 'HTML','blinkTextFlexible.html');
            app.HTMLUnreachableLights.HTMLSource = fullfile(app.appFolder, 'HTML','blinkTextFlexible.html');

            % Pulse image
            app.Pulse = pulsingImage(app.HueLogo, 'huelogo.png');
            app.Pulse.HtmlComp.Data.size = struct('width', 145, 'height', 123);
            app.Pulse.HtmlComp.Position = [1 1 145 123];
            app.Pulse.setSpeed(10);
            app.Pulse.setOn(true);

            wID = 'MATLAB:ui:uihtml:ErrorOccuredInHTMLSource';
            warning('off',wID);

            %%% [msgStr,warnId] = lastwarn;
            % if contains(msgStr, 'HTMLSource')
            %     fprintf('\n*** Disabling spurious HTMLSource warnings:');
            %     warning('off',wID);
            %     fprintf('\n*** Warning ID:  %s DISABLED. ***', warnId);
            %     fprintf('\n*** Warning Msg (for info): %s ***\n',msgStr);
            % end
            %
            %%% Optional Pulse image settings
            % Pulse off:
            % app.Pulse.setOn(false);
            % Pulse on:
            % app.Pulse.setOn(true);
            % Pulse speed:
            % app.Pulse.setSpeed(10);
            % New icon:
            % app.Pulse.setImage('newicon.png');

            app.dataRepository = app.appFolder; % CURRENT directory (* NOT necessarily the directory from which the App was launched *)

            switch app.vAPI
                case 1
                    bridgeVersion = 'BridgeV2';
                case 2
                    bridgeVersion = 'BridgePro';
                otherwise
                    bridgeVersion = 'AllBridges';
            end
            if ispc
                repositoryName = ['\HueData' bridgeVersion];
            else
                repositoryName = ['/HueData' bridgeVersion];
            end
            fprintf('\n%s starting Up in Directory: %s\nLooking for existing Hue Data Repository %s...', mfilename, app.appFolder, repositoryName);
            %%%
            %%% First check whether the current directory is already the data repository..
            %%%
            if ~contains(app.dataRepository,repositoryName)
                app.dataRepository = [app.dataRepository repositoryName];
                % Now check if there is a data repository sub-directory...
                if ~exist(app.dataRepository, 'dir')
                    mkdir(app.dataRepository);
                    fprintf('Not found.  Created new Hue Data Repository: %s\n', app.dataRepository);
                else
                    fprintf('Found.\n');
                end
            else
                fprintf('Found.\n');
            end
            fileName = 'userNameHue.mat';
            fprintf('Looking for file: %s in %s...', fileName, app.dataRepository);
            usernameFile = fullfile(app.dataRepository, fileName);
            % Check if file exists
            if ~exist(usernameFile, 'file')
                fprintf('not found.  Attempting to copy from main Hue directory..');
                if ispc
                    sourceDir = 'C:\Users\spfol\OneDrive\Matlab2025\Current_r2025\Hue';
                    sourceFile = fullfile(sourceDir, fileName);
                else
                    sourceDir = '/Users/stephenfolmer/Library/CloudStorage/OneDrive-Personal/Matlab2025/Current_r2025/Hue'; % directorty containing master of userNameHue.mat
                    sourceFile = fullfile(sourceDir, fileName);
                end
                targetFile = fullfile(app.dataRepository, fileName);
                % Check if file exists
                if exist(sourceFile, 'file')
                    % Copy the file
                    copyfile(sourceFile, targetFile);
                    fprintf('Source File copied to: %s\n', targetFile);
                else
                    fprintf('\n** Master Source File not found: %s **\n', sourceFile);
                end
            else
                fprintf('Found.\n');
            end

            errCode = getStats (app, 'startup');
            if errCode == 0
                errCode = getStats (app, 'refresh');
            else
                fprintf('\nerrCode: %d returned from getStats',errCode);
            end
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            if app.bridgeSwitch
                app.restartMsg = 'Bridge switched and defaulted to On-line Mode. ';
                app.bridgeSwitch = false;
            end
            if errCode == 0
                if ~ app.offlineMode
                    app.DiagnosticsMsgTextArea.Value = append(app.restartMsg, ' Bridge data loaded.');
                else
                    app.DiagnosticsMsgTextArea.Value = append('Previously saved Bridge data loaded. ',app.restartMsg);
                end
                app.successfulStart = true;
                startTimer(app);
            elseif ~ app.offlineMode
                app.DiagnosticsMsgTextArea.Value = [app.DiagnosticsMsgTextArea.Value '  Start-up failed.'];
                lastDiagMsg = app.DiagnosticsMsgTextArea.Value;
                save (app.diagFileName,"lastDiagMsg");
                app.successfulStart = false;
            end


        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            restartDetails = append('Successfully terminated on ',strDt, '.');
            if ispc
                save([app.dataRepository '\hueAppDiagnostics'],"restartDetails");
            else
                save([app.dataRepository '/hueAppDiagnostics'],"restartDetails");
            end
            warning('on', 'all');
            saveWindowState(app);
            stopTimer(app);
            closereq;
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            % If not yet successfully started (e.g. lack of internet) re-initiate startup processing..
            if ~app.successfulStart
                startupFcn(app);
            else
                clc % only clear command windo when explicitly refreshed (retains any necessary diagnostics)
                refreshDetails(app);
            end
        end

        % Cell selection callback: LightsTable
        function LightsTableCellSelection(app, event)
            pause(0.01);  % 10ms delay allows CellEditCallback to finish
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            app.cellSelIndices = event.Indices;
            noLights = size(app.LightsTable.Data);
            try % cater for selection outside of lights grid boundary
                switch app.cellSelIndices(1,2)
                    case 8 % brightness
                        app.DiagnosticsMsgTextArea.Value = newBrightness(app, event);
                        return;
                    case 9 % colour
                        app.DiagnosticsMsgTextArea.Value = "Colour selected.";
                    otherwise % toggle
                        drawnow;
                        if app.cellSelIndices(1,2) == 1 % Group selected
                            if app.prevEventName == "KeyPress" && app.editGroup == app.LightsTable.Data{app.cellSelIndices(1,1),1}
                                % brightness changed - only update brightness; don't toggle group lights
                                if app.xyColour(1,1) == 0
                                    groupBrightness(app, noLights);
                                    app.DiagnosticsMsgTextArea.Value = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),1}, " brightness changed.");
                                else
                                    coloursChanged = groupColour(app, noLights);
                                    if coloursChanged > 0
                                        app.DiagnosticsMsgTextArea.Value = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),1}, " colour set to ", app.newColr,".");
                                    else
                                        app.DiagnosticsMsgTextArea.Value = append("No colour lights found in ",app.LightsTable.Data{app.cellSelIndices(1,1),1}, ".  Change ignored.");
                                    end
                                end
                                app.editGroup = 'none'; % reset so that the same group will toggle if selected next
                            else
                                selGrp = app.LightsTable.Data{app.cellSelIndices(1,1),1};
                                grpType = app.LightsTable.Data{app.cellSelIndices(1,1),2};
                                for i = 1:noLights(1,1)
                                    if app.LightsTable.Data{i,1} == selGrp ...
                                            && app.LightsTable.Data{i,2} == grpType
                                        numLight = app.LightsTable.Data{i,3};
                                        onOff = toggleLights(app, numLight, i, true);
                                        for j = 1:noLights(1,1)
                                            if app.LightsTable.Data{j,3} == numLight
                                                app.LightsTable.Data{j,7} = onOff;
                                            end
                                        end
                                    end
                                end
                                app.DiagnosticsMsgTextArea.Value = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),1}, " lights toggled.");
                            end
                        else
                            if app.prevEventName ~= "KeyPress" % ignore toggle if brightness was just updated
                                numLight = app.LightsTable.Data{app.cellSelIndices(1,1),3};
                                onOff = toggleLights(app, numLight, app.cellSelIndices(1,1), true);
                                for j = 1:noLights(1,1)
                                    if app.LightsTable.Data{j,3} == numLight
                                        app.LightsTable.Data{j,7} = onOff;
                                    end
                                end
                                app.DiagnosticsMsgTextArea.Value = strcat(app.LightsTable.Data{app.cellSelIndices(1,1),4}, " light toggled.");
                            end
                        end
                        highlightOnLights(app);
                        app.prevEventName = event.EventName;
                        app.xyColour = [0,0];
                        updateONGroupsRooms(app);
                end
            catch
            end

        end

        % Button pushed function: ToggleALLLightsButton
        function ToggleALLLightsButtonPushed(app, event)
            app.DiagnosticsMsgTextArea.Value = 'Toggling ALL Lights..';
            drawnow;
            noLights = size(app.LightsTable.Data);
            for i = 1:noLights(1,1)
                numLight = app.LightsTable.Data{i,3};
                toggleLights(app, numLight, i, false);
            end
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            app.DiagnosticsMsgTextArea.Value = 'ALL lights toggled.';
            highlightOnLights(app);
            app.prevEventName = event.EventName;
            updateONGroupsRooms(app);
        end

        % Button pushed function: AllLightsOn
        function AllLightsOnButtonPushed(app, event)
            app.DiagnosticsMsgTextArea.Value = 'Switching ALL Lights ON..';
            drawnow;
            noLights = size(app.LightsTable.Data);
            if app.vAPI == 1
                options = weboptions('MediaType', 'application/json', 'RequestMethod', 'put');
                state = struct('on',true);
                for i = 1:noLights(1,1)
                    if app.LightsTable.Data{i,7} == 0
                        app.LightsTable.Data{i,7} = 1;
                        if contains(app.LightsTable.Data{i,2},"Room","IgnoreCase",true)
                            numLight = app.LightsTable.Data{i,3};
                            wrURL = sprintf('http://%s/api/%s/lights/%d/state', app.hueIPAddressV2,app.appID,numLight);
                            webwrite(wrURL, state, options);
                            app.lightsOn = app.lightsOn + 1;
                        end
                    end
                end
            else
                for i = 1:noLights(1,1)
                    if app.LightsTable.Data{i,7} == 0
                        app.LightsTable.Data{i,7} = 1;
                        if contains(app.LightsTable.Data{i,2},"Room","IgnoreCase",true)
                            lightUUID = app.LightsTable.Data{i,10};
                            disp(lightUUID);
                            body = struct('on', struct('on', true));
                            path = "/clip/v2/resource/light/" + lightUUID;
                            method = 'PUT';
                            hueRequest(app, app.hueIPAddressPro, app.appKey, method, path, body);
                            app.lightsOn = app.lightsOn + 1;
                        end
                    end
                end
            end
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            app.DiagnosticsMsgTextArea.Value = 'ALL lights ON.';
            highlightOnLights(app);
            app.prevEventName = event.EventName;
            app.ONLights.Value = app.lightsOn;
            if app.lightsOn == 1
                app.LightsOnLabel.Text = 'Light';
            else
                app.LightsOnLabel.Text = 'Lights';
            end
            updateONGroupsRooms(app);
            blinkLightsOn(app);
            % fprintf('\n AllLightson function: app.lightsOn %d', app.lightsOn);
        end

        % Button pushed function: AllLightsOff
        function AllLightsOffButtonPushed(app, event)
            app.DiagnosticsMsgTextArea.Value = 'Switching ALL Lights OFF..';
            drawnow;
            noLights = size(app.LightsTable.Data);
            if app.vAPI == 1
                options = weboptions('MediaType', 'application/json', 'RequestMethod', 'put');
                state = struct('on',false);
                for i = 1:noLights(1,1)
                    if app.LightsTable.Data{i,7} == 1
                        app.LightsTable.Data{i,7} = 0;
                        if contains(app.LightsTable.Data{i,2},"Room","IgnoreCase",true)
                            numLight = app.LightsTable.Data{i,3};
                            wrURL = sprintf('http://%s/api/%s/lights/%d/state', app.hueIPAddressV2,app.appID,numLight);
                            webwrite(wrURL, state, options);
                        end
                    end
                end
            else
                for i = 1:noLights(1,1)
                    if app.LightsTable.Data{i,7} == 1
                        app.LightsTable.Data{i,7} = 0;
                        if contains(app.LightsTable.Data{i,2},"Room","IgnoreCase",true)
                            lightUUID = app.LightsTable.Data{i,10};
                            disp(lightUUID)
                            body = struct('on', struct('on', false));
                            app.lightsOn = app.lightsOn - 1;
                            path = "/clip/v2/resource/light/" + lightUUID;
                            method = 'PUT';
                            hueRequest(app, app.hueIPAddressPro, app.appKey, method, path, body);
                        end
                    end
                end
            end
            if app.lightsOn == 1
                app.LightsOnLabel.Text = 'Light';
            else
                app.LightsOnLabel.Text = 'Lights';
            end
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            app.DiagnosticsMsgTextArea.Value = 'ALL lights OFF.';
            highlightOnLights(app);
            app.prevEventName = event.EventName;
            app.ONLights.Value = 0;
            updateONGroupsRooms(app);
            app.lightsOn = 0;
            blinkLightsOn(app);
        end

        % Cell edit callback: LightsTable
        function LightsTableCellEdit(app, event)

            drawnow;
            app.cellSelIndices = event.Indices;
            app.editGroup = app.LightsTable.Data{event.Indices(1,1),1};
            dt = datetime('now', 'Format', 'dd/MM/yy HH:mm:ss');
            strDt = string(dt);
            app.DiagnosticsTimeTextArea.Value = strDt;
            app.xyColour = [0,0];
            app.newColr = '';
            if app.cellSelIndices(1,2) == 9 % Colour edited
                app.colBri = app.LightsTable.Data{event.Indices(1,1),8};
                switch upper(event.EditData)
                    case 'B'
                        app.newColr = 'blue';
                        app.xyColour= [0.15, 0.06];
                    case 'C'
                        app.newColr = 'cyan';
                        app.xyColour= [0.17, 0.34];
                    case 'G'
                        app.newColr = 'green';
                        app.xyColour= [0.17, 0.7];
                    case 'Y'
                        app.newColr = 'yellow';
                        app.xyColour= [0.44, 0.51];
                    case 'O'
                        app.newColr = 'orange';
                        app.xyColour= [0.56, 0.42];
                    case 'R'
                        app.newColr = 'red';
                        app.xyColour= [0.7, 0.3];
                    case 'P'
                        app.newColr = 'purple';
                        app.xyColour= [0.25, 0.1];
                    case 'M'
                        app.newColr = 'magenta';
                        app.xyColour= [0.38, 0.17];
                    case 'W'
                        app.newColr = 'white';
                        app.xyColour= [0.31, 0.33];
                    otherwise
                        % Not a simple shorthand colour, so check whether  valid XY values..
                        %%%
                        str = event.EditData;
                        [valid, x, y] = validateXYcolour(app, str);
                        if ~ valid
                            app.LightsTable.Data{event.Indices(1,1),9} = event.PreviousData; % restore previous colour name
                            app.DiagnosticsMsgTextArea.Value = 'Invalid colour.  Must be: XY colour values (in xx,yy format) OR b, c, g, y, o, r, p, m, w.';
                            return;
                        end
                        app.currentX = x;
                        app.currentY = y;
                        app.xyColour= [x, y];
                        rgb = hueXYtoRGB(app,x,y,app.LightsTable.Data{event.Indices(1,1),8});
                        app.newColr = {nearestCssColourName(app, rgb)};
                        %%%
                end
                app.DiagnosticsMsgTextArea.Value = newColour (app, event);
            else % Brightness edited
                if isnan(event.NewData)
                    app.LightsTable.Data{event.Indices(1,1),8} = event.PreviousData; % restore previous numerical value
                    app.DiagnosticsMsgTextArea.Value = 'Brightness must be a numerical value from 1 to 100.';
                    return;
                else
                    app.DiagnosticsMsgTextArea.Value = newBrightness (app, event);
                end
            end
        end

        % Selection changed function: LightsTable
        function LightsTableSelectionChanged(app, event)
            app.prevCellSel = event.PreviousSelection;
            if isempty(app.prevCellSel)
                app.prevCellSel = [0 0];
            end
        end

        % Key press function: LightsTable
        function LightsTableKeyPressed(app, event)
            app.lastKey = event.Key;
            switch app.lastKey
                case "return"
                    app.prevEventName = 'none';
                    app.editGroup = '';
                case "tab"
                    app.prevEventName = 'none';
                    app.editGroup = '';
                otherwise
                    app.prevEventName = event.EventName;
            end
        end

        % Key press function: HueDashboardv57UIFigure
        function HueDashboardv57UIFigureKeyPress(app, event)
            app.lastKey = event.Key;
        end

        % Value changed function: lightsOnRoomsOnlyCheckbox
        function lightOnRoomsOnly(app, event)

            if app.lightsOnRoomsOnlyCheckbox.Value == 1
                app.lightsOnRoomsOnly = true;
            else
                app.lightsOnRoomsOnly = false;
            end
            RefreshButtonPushed(app);
        end

        % Close request function: HueDashboardv57UIFigure
        function HueDashboardv57UIFigureCloseRequest(app, event)

            stopTimer(app);

            delete(app)

        end

        % Button pushed function: AutoRefreshButton
        function AutoButtonPushed(app, event)

            if app.autoRefresh % Was ON
                app.AutoRefreshButton.BackgroundColor = 'black';
                app.AutoRefreshButton.FontColor = 'yellow';
                app.AutoRefreshButton.Text = 'Auto OFF';
                app.AutoRefreshButton.Tooltip = 'Details will NOT be automatically refreshed when a Motion is detected.';
                app.autoRefresh = false;
            else % Was OFF
                app.AutoRefreshButton.BackgroundColor = app.RefreshButton.BackgroundColor;
                app.AutoRefreshButton.FontColor = app.RefreshButton.FontColor;
                app.AutoRefreshButton.Text = 'Auto ON';
                app.AutoRefreshButton.Tooltip = 'Details will be automatically refreshed when a Motion is detected.';
                app.autoRefresh = true;
            end
        end

        % Value changed function: BridgeDropDown
        function BridgeVersionChanged(app, event)
            app.vAPI = app.BridgeDropDown.Value;
            if app.vAPI == 1
                app.bridgeProMode = false;
            else
                app.bridgeProMode = true;
            end
            % revert to on-line mode when switching bridges
            app.OfflineMode.Value = 0;
            app.offlineMode = false;
            app.autoRefresh = true;
            app.AutoRefreshButton.Visible ="on";
            app.ResponseLimitSwitch.Visible ="on";
            app.RefreshButton.Visible = "on";
            app.bridgeSwitch = true;
            restart(app);
        end

        % Value changed function: OfflineMode
        function offlineModeChanged(app, event)
            if app.OfflineMode.Value == 1
                app.offlineMode = true;
                app.DiagnosticsMsgTextArea.Value = 'Switched to Off-line Mode.';
                app.autoRefresh = false;
                app.AutoRefreshButton.Visible ="off";
                app.ResponseLimitSwitch.Visible ="off";
                app.RefreshButton.Visible = "off";
            else
                app.offlineMode = false;
                app.DiagnosticsMsgTextArea.Value = 'Switched back to On-line Mode.';
                app.autoRefresh = true;
                app.AutoRefreshButton.Visible ="on";
                app.ResponseLimitSwitch.Visible ="on";
                app.RefreshButton.Visible = "on";
            end
            restartDetails= append('Restarted: ', app.DiagnosticsMsgTextArea.Value);
            if ispc
                save([app.dataRepository '\hueAppDiagnostics'],"restartDetails");
            else
                save([app.dataRepository '/hueAppDiagnostics'],"restartDetails");
            end
            drawnow;
            pause(2);
            restart(app);
        end

        % Value changed function: ResponseLimitSwitch
        function ResponseLimitSwitchFlipped(app, event)
            if app.ResponseLimitSwitch.Value == "On"
                app.ResponseLimitSwitch.Tooltip = 'Response Limit ON.';
            else
                app.ResponseLimitSwitch.Tooltip = 'Response Limit OFF.';
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.HueDashboardv57UIFigure = uifigure('Visible', 'off');
            app.HueDashboardv57UIFigure.Name = 'HueDashboard (v5.7)';
            app.HueDashboardv57UIFigure.Icon = fullfile(pathToMLAPP, 'huelogo.png');
            app.HueDashboardv57UIFigure.CloseRequestFcn = createCallbackFcn(app, @HueDashboardv57UIFigureCloseRequest, true);
            app.HueDashboardv57UIFigure.KeyPressFcn = createCallbackFcn(app, @HueDashboardv57UIFigureKeyPress, true);
            app.ExitButton = uibutton(app.HueDashboardv57UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.PhilipsHueDashboardLabel = uilabel(app.HueDashboardv57UIFigure);
            app.PhilipsHueDashboardLabel.Text = 'Philips Hue Dashboard';
            app.RefreshButton = uibutton(app.HueDashboardv57UIFigure, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Tooltip = {'Refresh the display with latest Bridge data.'};
            app.RefreshButton.Text = 'Refresh';
            app.IPAddressLabel_3 = uilabel(app.HueDashboardv57UIFigure);
            app.IPAddressLabel_3.Text = 'API v.';
            app.IPAddressLabel = uilabel(app.HueDashboardv57UIFigure);
            app.IPAddressLabel.Text = 'IP Address';
            app.ZigbeeChannelLabel = uilabel(app.HueDashboardv57UIFigure);
            app.ZigbeeChannelLabel.Text = 'Zigbee Chan';
            app.LastFirmwareUpdateSoftwareVersionTextAreaLabel = uilabel(app.HueDashboardv57UIFigure);
            app.LastFirmwareUpdateSoftwareVersionTextAreaLabel.Text = 'Last  Firmware Update / Software Version';
            app.IPAddressLabel_4 = uilabel(app.HueDashboardv57UIFigure);
            app.IPAddressLabel_4.Text = 'Auto Update';
            app.PreviousStatusRefreshLabel = uilabel(app.HueDashboardv57UIFigure);
            app.PreviousStatusRefreshLabel.Text = {'Last Bridge'; 'Data Refresh'};
            app.ZigbeeChannelLabel_2 = uilabel(app.HueDashboardv57UIFigure);
            app.ZigbeeChannelLabel_2.Text = {'Refresh'; 'Response'};
            app.APIversion = uitextarea(app.HueDashboardv57UIFigure);
            app.APIversion.Editable = 'off';
            app.APIversion.Tooltip = {'Current Bridge API version.'};
            app.IPAddress = uitextarea(app.HueDashboardv57UIFigure);
            app.IPAddress.Editable = 'off';
            app.IPAddress.Tooltip = {'Current Bridge IP Address.'};
            app.ZigbeeChannel = uieditfield(app.HueDashboardv57UIFigure, 'numeric');
            app.ZigbeeChannel.Editable = 'off';
            app.ZigbeeChannel.Tooltip = {'The Zigbee Channel selected in the Bridge settings'};
            app.LastFirmwareUpdate = uitextarea(app.HueDashboardv57UIFigure);
            app.LastFirmwareUpdate.Editable = 'off';
            app.LastFirmwareUpdate.Tooltip = {'Time and version of the last Bridge Software update.'};
            app.AutoSWUpdate = uitextarea(app.HueDashboardv57UIFigure);
            app.AutoSWUpdate.Editable = 'off';
            app.AutoSWUpdate.Tooltip = {'Indicates whether the Bridge settings have Software Auto-Update enabled.'};
            app.LastBridgeDataRefreshTextArea = uitextarea(app.HueDashboardv57UIFigure);
            app.LastBridgeDataRefreshTextArea.Editable = 'off';
            app.LastBridgeDataRefreshTextArea.Tooltip = {'Timestamp of last Bridge Data Refresh.'};
            app.Response = uieditfield(app.HueDashboardv57UIFigure, 'numeric');
            app.Response.ValueDisplayFormat = '%.0f';
            app.Response.Editable = 'off';
            app.Response.Tooltip = {'Response time (seconds) for last Refresh.  When this exceeds the response time limit the app will restart to improve efficiency.'};
            app.IPAddressLabel_2 = uilabel(app.HueDashboardv57UIFigure);
            app.IPAddressLabel_2.Text = 'Bridge ID';
            app.BridgeID = uitextarea(app.HueDashboardv57UIFigure);
            app.BridgeID.Editable = 'off';
            app.BridgeID.Tooltip = {'The Bridge ID specified in the configuration. '};
            app.SWversion = uitextarea(app.HueDashboardv57UIFigure);
            app.SWversion.Editable = 'off';
            app.Label = uilabel(app.HueDashboardv57UIFigure);
            app.Label.Text = '/';
            app.SWUpDateAvail = uitextarea(app.HueDashboardv57UIFigure);
            app.SWUpDateAvail.Editable = 'off';
            app.SWUpDateAvail.Tooltip = {'Indicates whether the Bridge has pending Software Updates.'};
            app.SensorTemperaturesPanel = uipanel(app.HueDashboardv57UIFigure);
            app.SensorTemperaturesPanel.Title = 'Sensor Temperatures (max/min)';
            app.CMSensorsLabel = uilabel(app.SensorTemperaturesPanel);
            app.CMSensorsLabel.Text = 'Comfort      18-21';
            app.CMSensors = uieditfield(app.SensorTemperaturesPanel, 'numeric');
            app.CMSensors.Editable = 'off';
            app.CLSensors = uieditfield(app.SensorTemperaturesPanel, 'numeric');
            app.CLSensors.Editable = 'off';
            app.COSensors = uieditfield(app.SensorTemperaturesPanel, 'numeric');
            app.COSensors.Editable = 'off';
            app.WASensors = uieditfield(app.SensorTemperaturesPanel, 'numeric');
            app.WASensors.Editable = 'off';
            app.HOSensors = uieditfield(app.SensorTemperaturesPanel, 'numeric');
            app.HOSensors.Editable = 'off';
            app.HotLabel = uilabel(app.SensorTemperaturesPanel);
            app.HotLabel.Text = 'Hot      > 30';
            app.WarmLabel = uilabel(app.SensorTemperaturesPanel);
            app.WarmLabel.Text = 'Warm   > 21';
            app.CoolLabel = uilabel(app.SensorTemperaturesPanel);
            app.CoolLabel.Text = 'Cool     < 18';
            app.ColdLable = uilabel(app.SensorTemperaturesPanel);
            app.ColdLable.Text = 'Cold     < 10';
            app.FreezingLable = uilabel(app.SensorTemperaturesPanel);
            app.FreezingLable.Text = 'Freezing < 0';
            app.FRSensors = uieditfield(app.SensorTemperaturesPanel, 'numeric');
            app.FRSensors.Editable = 'off';
            app.SensorsandSwitchesLabel = uilabel(app.HueDashboardv57UIFigure);
            app.SensorsandSwitchesLabel.Tooltip = {'Temperatures in Celcius and Battery percentage remaining.'; 'Last Events pertain to triggered detections or programmed automations for Sensors and manual interactions with Switches/dimmers.'};
            app.SensorsandSwitchesLabel.Interpreter = 'html';
            app.SensorsandSwitchesLabel.Text = 'Sensors and Switches';
            app.TempsTable = uitable(app.HueDashboardv57UIFigure);
            app.TempsTable.ColumnName = {'Temp'; 'Batt'; 'Name (model)'; 'Last Event'};
            app.TempsTable.RowName = {};
            app.TempsTable.ColumnSortable = true;
            app.LightsOnLabel = uilabel(app.HueDashboardv57UIFigure);
            app.LightsOnLabel.Tooltip = {'TOGGLE switches lights between ON and OFF state.'; '*'; 'Click on a Group cell (Column 1) to Toggle a lights Group OR Toggle individual lights by clicking light-specific cells in Cols 2-7.  '; '*'; 'To apply brightness or colour changes to light Groups:'; '1) Type brightness percentage or colour character in Col 8 (Bri) or Col 9 (Colour), respectively - do NOT hit Enter or Tab.'; '2) Click on a Group cell in the SAME row as the above changes were made. '; '*'; 'To apply brightness or colour changes to ONE light:'; '1) Type brightness percentage or colour character in Col 8 (Bri) or Col 9 (Colour), respectively.'; '2) Hit Enter or Tab.'; '*'; 'Valid Colour values are:  r(ed), g(reen), b(lue) , y(ellow), w(hite), p(urple), o(range), c(yan), m(agenta).'};
            app.LightsOnLabel.Text = 'Lights';
            app.ToggleALLLightsButton = uibutton(app.HueDashboardv57UIFigure, 'push');
            app.ToggleALLLightsButton.ButtonPushedFcn = createCallbackFcn(app, @ToggleALLLightsButtonPushed, true);
            app.ToggleALLLightsButton.Text = {'Toggle ALL'; 'Lights'};
            app.AllLightsOff = uibutton(app.HueDashboardv57UIFigure, 'push');
            app.AllLightsOff.ButtonPushedFcn = createCallbackFcn(app, @AllLightsOffButtonPushed, true);
            app.AllLightsOff.Text = 'ALL OFF';
            app.AllLightsOn = uibutton(app.HueDashboardv57UIFigure, 'push');
            app.AllLightsOn.ButtonPushedFcn = createCallbackFcn(app, @AllLightsOnButtonPushed, true);
            app.AllLightsOn.Text = 'ALL ON';
            app.LightsTable = uitable(app.HueDashboardv57UIFigure);
            app.LightsTable.ColumnName = {'Group'; 'Type'; 'No'; 'Name'; 'Product Name'; 'Model'; 'On'; 'Bri'; 'Colour'; 'ID'};
            app.LightsTable.RowName = {};
            app.LightsTable.ColumnSortable = [true false false false false false false false false false];
            app.LightsTable.ColumnEditable = [false false false false false false false true true false];
            app.LightsTable.CellEditCallback = createCallbackFcn(app, @LightsTableCellEdit, true);
            app.LightsTable.CellSelectionCallback = createCallbackFcn(app, @LightsTableCellSelection, true);
            app.LightsTable.SelectionChangedFcn = createCallbackFcn(app, @LightsTableSelectionChanged, true);
            app.LightsTable.Multiselect = 'off';
            app.LightsTable.Tooltip = {''};
            app.LightsTable.KeyPressFcn = createCallbackFcn(app, @LightsTableKeyPressed, true);
            app.RulesLabel_2 = uilabel(app.HueDashboardv57UIFigure);
            app.RulesLabel_2.Tooltip = {'Columns can be rearranged by dragging the column heading.'};
            app.RulesLabel_2.Text = 'Rules';
            app.NumberofRulesNOTtriggeredsincethelastBridgefirmwareupdateLabel = uilabel(app.HueDashboardv57UIFigure);
            app.NumberofRulesNOTtriggeredsincethelastBridgefirmwareupdateLabel.Text = 'Number of Rules NOT triggered since the last Bridge firmware update:';
            app.NeverTriggered = uieditfield(app.HueDashboardv57UIFigure, 'numeric');
            app.NeverTriggered.Editable = 'off';
            app.NeverTriggered.Tooltip = {'The number of Rules ''never'' triggered is based on the ''timestriggered'' value held on the Bridge.  This is reset when the Bridge software is updated.  Also note that multiple Rules are created for every event or condition set for each device.'};
            app.RulesTable = uitable(app.HueDashboardv57UIFigure);
            app.RulesTable.ColumnName = {'No'; 'Area'; 'Rule Name'; 'Created'; 'Last Trigger'; 'Trigs'; 'Status'; 'Action'; 'Target'; 'Tgt No'; 'Target Name'; 'Resource Link'};
            app.RulesTable.ColumnRearrangeable = 'on';
            app.RulesTable.RowName = {};
            app.RulesTable.ColumnSortable = true;
            app.UnreachableLightsLabel = uilabel(app.HueDashboardv57UIFigure);
            app.UnreachableLightsLabel.Tooltip = {'Any unreachable lights will be listed here.'};
            app.UnreachableLightsLabel.Text = 'Unreachable Lights';
            app.TableLights = uitable(app.HueDashboardv57UIFigure);
            app.TableLights.ColumnName = {'ID'; 'Light'};
            app.TableLights.RowName = {};
            app.TableLights.ColumnSortable = true;
            app.TableSensors = uitable(app.HueDashboardv57UIFigure);
            app.TableSensors.ColumnName = {'%'; 'Model'; 'Name'};
            app.TableSensors.RowName = {};
            app.TableSensors.ColumnSortable = true;
            app.DiagnosticsTextAreaLabel = uilabel(app.HueDashboardv57UIFigure);
            app.DiagnosticsTextAreaLabel.Text = 'Diagnostics';
            app.DiagnosticsMsgTextArea = uitextarea(app.HueDashboardv57UIFigure);
            app.DiagnosticsMsgTextArea.Editable = 'off';
            app.DiagnosticsTimeTextArea = uitextarea(app.HueDashboardv57UIFigure);
            app.DiagnosticsTimeTextArea.Editable = 'off';
            app.BridgeConfigurationandUsagePanel = uipanel(app.HueDashboardv57UIFigure);
            app.BridgeConfigurationandUsagePanel.Title = 'Bridge Configuration and Usage';
            app.InstalledLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.InstalledLabel.Text = 'Installed';
            app.BridgeLimitLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.BridgeLimitLabel.Text = 'Bridge Limit';
            app.UsedLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.UsedLabel.Text = 'Used %';
            app.OnActiveLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.OnActiveLabel.Text = 'On / Active';
            app.LightsLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.LightsLabel.Text = 'Lights';
            app.AVLights = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVLights.Editable = 'off';
            app.BRLights = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRLights.Editable = 'off';
            app.PCLights = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCLights.ValueDisplayFormat = '%5.2f';
            app.PCLights.Editable = 'off';
            app.ONLights = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.ONLights.Editable = 'off';
            app.RulesLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.RulesLabel.Text = 'Rules';
            app.AVRules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVRules.Editable = 'off';
            app.BRRules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRRules.Editable = 'off';
            app.PCRules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCRules.ValueDisplayFormat = '%5.2f';
            app.PCRules.Editable = 'off';
            app.ONRules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.ONRules.Editable = 'off';
            app.GroupsLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.GroupsLabel.Text = 'Light Groups';
            app.AVGroups = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVGroups.Editable = 'off';
            app.BRGroups = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRGroups.Editable = 'off';
            app.PCGroups = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCGroups.ValueDisplayFormat = '%5.2f';
            app.PCGroups.Editable = 'off';
            app.OnGroups = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.OnGroups.Editable = 'off';
            app.ScenesLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.ScenesLabel.Text = 'Scenes';
            app.AVScenes = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVScenes.Editable = 'off';
            app.BRScenes = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRScenes.Editable = 'off';
            app.PCScenes = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCScenes.ValueDisplayFormat = '%5.2f';
            app.PCScenes.Editable = 'off';
            app.SchedulesLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.SchedulesLabel.Text = 'Schedules';
            app.AVSchedules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVSchedules.Editable = 'off';
            app.BRSchedules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRSchedules.Editable = 'off';
            app.PCSchedules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCSchedules.ValueDisplayFormat = '%5.2f';
            app.PCSchedules.Editable = 'off';
            app.ONSchedules = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.ONSchedules.Editable = 'off';
            app.ResourceLinksLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.ResourceLinksLabel.Text = 'Resource Links';
            app.AVResourceLinks = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVResourceLinks.Editable = 'off';
            app.BRResourceLinks = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRResourceLinks.Editable = 'off';
            app.PCResourceLinks = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCResourceLinks.ValueDisplayFormat = '%5.2f';
            app.PCResourceLinks.Editable = 'off';
            app.SensorsLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.SensorsLabel.Text = 'Sensors';
            app.AVSensors = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.AVSensors.Editable = 'off';
            app.BRSensors = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.BRSensors.Editable = 'off';
            app.PCSensors = uieditfield(app.BridgeConfigurationandUsagePanel, 'numeric');
            app.PCSensors.ValueDisplayFormat = '%5.2f';
            app.PCSensors.Editable = 'off';
            app.MotionSensors = uilabel(app.BridgeConfigurationandUsagePanel);
            app.MotionSensors.Text = '99';
            app.MotionSensorsLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.MotionSensorsLabel.Text = 'Motion Sensors ';
            app.DimmerSwitches = uilabel(app.BridgeConfigurationandUsagePanel);
            app.DimmerSwitches.Text = '99';
            app.SwitchesLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.SwitchesLabel.Text = 'Switches';
            app.devicesLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.devicesLabel.Text = 'devices:';
            app.TotalDevices = uilabel(app.BridgeConfigurationandUsagePanel);
            app.TotalDevices.Text = '88';
            app.InLabel = uilabel(app.BridgeConfigurationandUsagePanel);
            app.InLabel.Text = 'In';
            app.onLabel = uilabel(app.HueDashboardv57UIFigure);
            app.onLabel.Text = 'on';
            app.WarningsPanel = uipanel(app.HueDashboardv57UIFigure);
            app.WarningsPanel.Title = 'Warnings';
            app.UnreachableLightsHdr = uilabel(app.WarningsPanel);
            app.UnreachableLightsHdr.Tooltip = {'Number of unreachable Lights.  Often temporary.'};
            app.UnreachableLightsHdr.Text = 'Unreachable Lights';
            app.LowBatteryDevicesHdr = uilabel(app.WarningsPanel);
            app.LowBatteryDevicesHdr.Tooltip = {'Number of devices with a battery level below 30%.'};
            app.LowBatteryDevicesHdr.Text = 'Low Battery Devices';
            app.HTMLLowBatDevs = uihtml(app.WarningsPanel);
            app.HTMLLowBatDevs.Tooltip = {'Number of devices with a battery level below 30%.'};
            app.HTMLUnreachableLights = uihtml(app.WarningsPanel);
            app.HTMLUnreachableLights.Tooltip = {'Number of unreachable Lights.  Often temporary.'};
            app.HTMLLowBatMsg = uihtml(app.HueDashboardv57UIFigure);
            app.HTMLTempTrend = uihtml(app.HueDashboardv57UIFigure);
            app.HTMLLightsOn = uihtml(app.HueDashboardv57UIFigure);
            app.HTMLLastMotion = uihtml(app.HueDashboardv57UIFigure);
            app.ResponseLimitSwitch = uiswitch(app.HueDashboardv57UIFigure, 'rocker');
            app.ResponseLimitSwitch.ValueChangedFcn = createCallbackFcn(app, @ResponseLimitSwitchFlipped, true);
            app.ResponseLimitSwitch.Tooltip = {'Enable auto-restart if Response Limit is exceeded.  Response Limit is calculated as Startup time * 1.25.'};
            app.HueLogo = uipanel(app.HueDashboardv57UIFigure);
            app.inLabel = uilabel(app.HueDashboardv57UIFigure);
            app.inLabel.Text = 'in';
            app.RoomsLabel = uilabel(app.HueDashboardv57UIFigure);
            app.RoomsLabel.Text = 'Rooms';
            app.noRoomsLightsOn = uilabel(app.HueDashboardv57UIFigure);
            app.noRoomsLightsOn.Text = '99';
            app.lightsOnRoomsOnlyCheckbox = uicheckbox(app.HueDashboardv57UIFigure);
            app.lightsOnRoomsOnlyCheckbox.ValueChangedFcn = createCallbackFcn(app, @lightOnRoomsOnly, true);
            app.lightsOnRoomsOnlyCheckbox.Tooltip = {'Show Only Rooms with Lights ON in the Grid.'};
            app.lightsOnRoomsOnlyCheckbox.Text = '';
            app.AutoRefreshButton = uibutton(app.HueDashboardv57UIFigure, 'push');
            app.AutoRefreshButton.ButtonPushedFcn = createCallbackFcn(app, @AutoButtonPushed, true);
            app.AutoRefreshButton.Tooltip = {'Auto-refresh upon Motion detection.  Checks at 15 second intervals.  Ignores consecutive movements in the same room.'};
            app.AutoRefreshButton.Text = 'Auto ON';
            app.BridgeDropDownLabel = uilabel(app.HueDashboardv57UIFigure);
            app.BridgeDropDownLabel.Text = 'Bridge';
            app.BridgeDropDown = uidropdown(app.HueDashboardv57UIFigure);
            app.BridgeDropDown.Items = {'v2', 'Pro'};
            app.BridgeDropDown.ItemsData = [1 2];
            app.BridgeDropDown.ValueChangedFcn = createCallbackFcn(app, @BridgeVersionChanged, true);
            app.BridgeDropDown.Value = 1;
            app.OfflineMode = uicheckbox(app.HueDashboardv57UIFigure);
            app.OfflineMode.ValueChangedFcn = createCallbackFcn(app, @offlineModeChanged, true);
            app.OfflineMode.Tooltip = {'Switch to Offline Mode. (For testing and data analysis.)'};
            app.OfflineMode.Text = '';
            app.inTimesTriggeredOrderLabel = uilabel(app.HueDashboardv57UIFigure);
            app.inTimesTriggeredOrderLabel.Text = '(in Times Triggered Order)';
            app.Sunset = uilabel(app.HueDashboardv57UIFigure);
            app.Sunset.Text = 'Sunset';
            app.ContextMenu = uicontextmenu(app.HueDashboardv57UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            % Show the figure after all components are created
            app.HueDashboardv57UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HueDashboard

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HueDashboardv57UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HueDashboardv57UIFigure)
        end
    end
end